classdef ImportDataFeatureFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        ExcelPanel                      matlab.ui.container.Panel
        ChooseExcelButton               matlab.ui.control.Button
        ExcelFileLabel                  matlab.ui.control.Label
        DataTableDropDown               matlab.ui.control.DropDown
        FeatureDictionaryDropDown       matlab.ui.control.DropDown
        FeatureDictionaryDropDownLabel  matlab.ui.control.Label
        DataTableDropDownLabel          matlab.ui.control.Label
        CSVPanel                        matlab.ui.container.Panel
        ChooseDataCSVButton             matlab.ui.control.Button
        CSVdataLabel                    matlab.ui.control.Label
        ChooseFeatureCSVButton          matlab.ui.control.Button
        CSVfeatureLabel                 matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        TidyConvertApp
        isXLS
        xlFullPathfilename
        ProjectName
        DataName
        FeatureName
        DataTable 
        FeatureTable
        csvFullPathDatafilename
        csvFullPathFeaturefilename
        CurrentFolder
        Filename
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)


        function reset(app)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureDictionaryDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVfeatureLabel.Text = '';
            app.Filename = '';
            app.CurrentFolder = '';
            app.xlFullPathfilename = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Import Data Table & Feature Dictionary';
            app.CallingApp = mainapp;
            if ~isempty(app.CallingApp.CurrentFolder)
                cd(app.CallingApp.CurrentFolder);
            end
        end

        % Button pushed function: ChooseExcelButton
        function ChooseExcelButtonPushed(app, event)
            app.isXLS = true;
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureDictionaryDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVfeatureLabel.Text = '';
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.xlsx');
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';
           
            figure(app.UIFigure);
            %app.CallingApp.UIFigure.Visible = 'off';
            %app.CallingApp.UIFigure.Visible = 'on';
                       
            if isequal(file,0)
                 %uialert(app.UIFigure,'Failed to import data','Error','Icon','error');
                 return
            end
            app.Filename = file;
            app.CurrentFolder = path;
            cd(path);
            app.xlFullPathfilename = fullfile(path,file);
            xlSheetNames = sheetnames(app.xlFullPathfilename);
            app.ExcelFileLabel.Text = app.Filename;
            app.DataTableDropDown.Items = ['-';xlSheetNames];
            app.FeatureDictionaryDropDown.Items = ['-';xlSheetNames];
        end

        % Button pushed function: ChooseDataCSVButton
        function ChooseDataCSVButtonPushed(app, event)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureDictionaryDropDown.Items = {''};
            app.isXLS = false;

            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv');
            
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';
            figure(app.UIFigure);
            
                        
            if isequal(file,0)
                 uialert(app.UIFigure,'Failed to import csv file','Error','Icon','error');
                 return
            end
            app.Filename = file;
            app.CurrentFolder = path;
            cd(path);

            app.CSVdataLabel.Text = file;
            app.csvFullPathDatafilename = fullfile(path,file);         
            opts = detectImportOptions(app.csvFullPathDatafilename);
            if ~all(ismember({'SampleType','Batch','Order'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a DataTable','Warning','Icon','warning');
                app.CSVdataLabel.Text = '';
            end
                     
        end

        % Button pushed function: ChooseFeatureCSVButton
        function ChooseFeatureCSVButtonPushed(app, event)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureDictionaryDropDown.Items = {''};
            app.isXLS = false;
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv'); 
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';  
            figure(app.UIFigure);
            cd(path);

            % app.UIFigure.Visible = 'off';
            % app.UIFigure.Visible = 'on';            
            if isequal(file,0)
                 uialert(app.UIFigure,'Failed to import csv file','Error','Icon','error');
                 return
            end
            app.CSVfeatureLabel.Text = file;
            app.csvFullPathFeaturefilename = fullfile(path,file);
            opts = detectImportOptions(app.csvFullPathFeaturefilename);
            if ~all(ismember({'UID','Name'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a Feature Dictionary','Warning','Icon','warning');
                app.CSVfeatureLabel.Text = '';
            end                 
        end

        % Value changed function: DataTableDropDown
        function DataTableDropDownValueChanged(app, event)
%             set(app.UIFigure, 'Pointer','watch')
%             app.DataTableDropDown.Enable = 'off';
%             app.FeatureDictionaryDropDown.Enable = 'off';
%             app.ChooseExcelButton.Enable = 'off';
%             app.ImportButton.Enable = 'off';
%            drawnow();
            app.DataName = app.DataTableDropDown.Value;
%             opts = detectImportOptions(app.xlFullPathfilename,'Sheet',app.DataName);
%             if ~all(ismember({'SampleType','Batch','Order'},opts.VariableNames))
%                 pause(0.2);
%                 uialert(app.UIFigure,'Selection is not a DataTable','Warning','Icon','warning');
%                 app.DataTableDropDown.Value = '-';
%             end
%             set(app.UIFigure, 'Pointer','arrow')
%             app.DataTableDropDown.Enable = 'on';
%             app.FeatureDictionaryDropDown.Enable = 'on';
%             app.ChooseExcelButton.Enable = 'on';
%             app.ImportButton.Enable = 'on';
        end

        % Value changed function: FeatureDictionaryDropDown
        function FeatureDictionaryDropDownValueChanged(app, event)
%             set(app.UIFigure, 'Pointer','watch')
%             app.DataTableDropDown.Enable = 'off';
%             app.FeatureDictionaryDropDown.Enable = 'off';
%             app.ChooseExcelButton.Enable = 'off';
%             app.ImportButton.Enable = 'off';
%             drawnow();
            app.FeatureName = app.FeatureDictionaryDropDown.Value;
%             opts = detectImportOptions(app.xlFullPathfilename,'Sheet',app.FeatureName);
%             if ~all(ismember({'UID','Name'},opts.VariableNames))
%                 pause(0.2);
%                 uialert(app.UIFigure,'Selection is not a FeatureTable','Warning','Icon','warning');
%                 app.FeatureDictionaryDropDown.Value = '-';
%             end
%             set(app.UIFigure, 'Pointer','arrow')
%             app.DataTableDropDown.Enable = 'on';
%             app.FeatureDictionaryDropDown.Enable = 'on';
%             app.ChooseExcelButton.Enable = 'on';
%             app.ImportButton.Enable = 'on';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
                if ismember('-',app.DataTableDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Data Sheet','Failed to import data','Icon','error');
                    return;
                end
                if ismember('-',app.FeatureDictionaryDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Feature Sheet','Failed to import data','Icon','error');
                    return;
                end       
                try
                    if app.isXLS
                        [app.DataTable,app.FeatureTable] = readDataXLapp(app.xlFullPathfilename,DataSheet=app.DataName,FeatureSheet=app.FeatureName,fighandle=app.UIFigure);
                        app.ProjectName = app.xlFullPathfilename; 
                        FileType = 'xls';
                    else
                        [app.DataTable,app.FeatureTable] = readDataCSVapp(app.csvFullPathDatafilename,app.csvFullPathFeaturefilename,fighandle=app.UIFigure);
                        DataFileName = app.csvFullPathDatafilename;
                        FeatureFileName = app.csvFullPathFeaturefilename;
                        temp = split(DataFileName,'.');
                        app.DataName = temp{1};
                        temp = split(FeatureFileName,'.');
                        app.FeatureName = temp{1};
                        app.ProjectName = 'CSV'; 
                        FileType = 'csv';
                    end
                catch ME
                    uialert(app.UIFigure,ME.message,'Failed to import data','Icon','error');
                    reset(app);
                    return;
                end
                app.CallingApp.DataTable = app.DataTable;
                app.CallingApp.FeatureTable = app.FeatureTable;
                app.CallingApp.ExcelName = app.xlFullPathfilename;
                app.CallingApp.ProjectName = app.Filename;
                app.CallingApp.DataName = app.DataName;
                app.CallingApp.FeatureName = app.FeatureName;
                app.CallingApp.FileType = FileType;
                app.CallingApp.ImportSuccess = true;
                app.CallingApp.CurrentFolder = app.CurrentFolder;
%                 app.CallingApp.Restart();
            UIFigureCloseRequest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.TidyConvertApp)
            delete(app)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CSVPanel = uipanel(app.UIFigure);
            app.CSVPanel.Title = 'CSV:';
            app.CSVfeatureLabel = uilabel(app.CSVPanel);
            app.CSVfeatureLabel.Text = '';
            app.ChooseFeatureCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChooseFeatureCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFeatureCSVButtonPushed, true);
            app.ChooseFeatureCSVButton.Text = 'Select Feature Dictionary CSV';
            app.CSVdataLabel = uilabel(app.CSVPanel);
            app.CSVdataLabel.Text = '';
            app.ChooseDataCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChooseDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataCSVButtonPushed, true);
            app.ChooseDataCSVButton.Text = 'Select Data Table CSV';
            app.ExcelPanel = uipanel(app.UIFigure);
            app.ExcelPanel.Title = 'Excel:';
            app.DataTableDropDownLabel = uilabel(app.ExcelPanel);
            app.DataTableDropDownLabel.Text = 'Data Table:';
            app.FeatureDictionaryDropDownLabel = uilabel(app.ExcelPanel);
            app.FeatureDictionaryDropDownLabel.Text = 'Feature Dictionary:';
            app.FeatureDictionaryDropDown = uidropdown(app.ExcelPanel);
            app.FeatureDictionaryDropDown.Items = {};
            app.FeatureDictionaryDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDictionaryDropDownValueChanged, true);
            app.FeatureDictionaryDropDown.Value = {};
            app.DataTableDropDown = uidropdown(app.ExcelPanel);
            app.DataTableDropDown.Items = {};
            app.DataTableDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTableDropDownValueChanged, true);
            app.DataTableDropDown.Value = {};
            app.ExcelFileLabel = uilabel(app.ExcelPanel);
            app.ExcelFileLabel.Text = '';
            app.ChooseExcelButton = uibutton(app.ExcelPanel, 'push');
            app.ChooseExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelButtonPushed, true);
            app.ChooseExcelButton.Text = 'Select Excel File';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TidyMetqcx.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDataFeatureFile(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end