classdef InputDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ManualSplineCorrectionLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        gammavalueSpinner            matlab.ui.control.Spinner
        gammavalueLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        value % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, xloc, yloc, value)
            app.UIFigure.Name = 'Spline Correction';
            pos = app.UIFigure.Position;
            pos(1) = xloc;
            pos(2) = yloc;
            app.UIFigure.Position = pos;
            app.gammavalueSpinner.Value = value;           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: gammavalueSpinner
        function gammavalueSpinnerValueChanged(app, event)
            app.value = app.gammavalueSpinner.Value;
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.gammavalueLabel = uilabel(app.UIFigure);
            app.gammavalueLabel.Interpreter = 'latex';
            app.gammavalueLabel.Text = '\gamma value:';
            app.gammavalueSpinner = uispinner(app.UIFigure);
            app.gammavalueSpinner.Limits = [0 15];
            app.gammavalueSpinner.RoundFractionalValues = 'on';
            app.gammavalueSpinner.ValueChangedFcn = createCallbackFcn(app, @gammavalueSpinnerValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ManualSplineCorrectionLabel = uilabel(app.UIFigure);
            app.ManualSplineCorrectionLabel.Text = 'Manual Spline Correction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputDialogApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end