classdef QCRSCconfigExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BlankRatioPanel               matlab.ui.container.Panel
        RelativeLODEditField          matlab.ui.control.NumericEditField
        ThresholdLabel                matlab.ui.control.Label
        BlankRatioMethodDropDown      matlab.ui.control.DropDown
        MethodDropDownLabel_2         matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        StatisticsBeforeAfterPanel    matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        blankRatioLabel               matlab.ui.control.Label
        BlankRatioData                matlab.ui.control.Label
        StatsParametric               matlab.ui.control.Switch
        qcRSDLabel                    matlab.ui.control.Label
        QCRSDBeforeAfterData          matlab.ui.control.Label
        dRatioLabel                   matlab.ui.control.Label
        logDratioBeforeAfterData      matlab.ui.control.Label
        refRSDLabel                   matlab.ui.control.Label
        RefRSDBeforeAfterData         matlab.ui.control.Label
        OutlierDetectionPanel         matlab.ui.container.Panel
        OutlierPosthocDropDown        matlab.ui.control.DropDown
        PosthocDropDownLabel          matlab.ui.control.Label
        OutlierApplyButton            matlab.ui.control.Button
        OutlierCISwitch               matlab.ui.control.Switch
        MethodDropDownLabel           matlab.ui.control.Label
        OutlierMethodDropDown         matlab.ui.control.DropDown
        PeakPanel                     matlab.ui.container.Panel
        RESETButton                   matlab.ui.control.Button
        ViewPeakTableButton           matlab.ui.control.Button
        LOADConfigButton              matlab.ui.control.Button
        SAVEConfigButton              matlab.ui.control.Button
        PeakEditField                 matlab.ui.control.EditField
        PeakEditFieldLabel            matlab.ui.control.Label
        PeakDropDown                  matlab.ui.control.DropDown
        BatchDropDown                 matlab.ui.control.DropDown
        LogTransformCheckBox          matlab.ui.control.CheckBox
        IgnoreBlanksCheckBox          matlab.ui.control.CheckBox
        RemoveZerosCheckBox           matlab.ui.control.CheckBox
        BatchDropDownLabel            matlab.ui.control.Label
        PeakDropDownLabel             matlab.ui.control.Label
        RandomPeakButton              matlab.ui.control.Button
        Panel_Optimise                matlab.ui.container.Panel
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        CorrectionSwitch              matlab.ui.control.Switch
        CrossValidationDropDown       matlab.ui.control.DropDown
        CrossValidationDropDownLabel  matlab.ui.control.Label
        grangeEditField               matlab.ui.control.EditField
        gammarangeLabel               matlab.ui.control.Label
        OptimiseButton                matlab.ui.control.Button
        MCrepsLabel                   matlab.ui.control.Label
        MCrepsEditField               matlab.ui.control.NumericEditField
        Panel_CVplot                  matlab.ui.container.Panel
        gammavalueLabel               matlab.ui.control.Label
        gammaValueSlider              matlab.ui.control.Slider
        UIAxesMSE                     matlab.ui.control.UIAxes
        Panel_BeforeAfter             matlab.ui.container.Panel
        UIAxesBefore                  matlab.ui.control.UIAxes
        UIAxesAfter                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CurrentFolder   %
        DataTable       % Description
        PeakTable       % Peak Table - primarliy to access P.UID
        ProjectName     % Project Name
        ExcelName       % Full Path of Excel File.
        DataName        
        PeakName
        ImportSuccess
        FileType        % csv or xls
    end
    
    properties (Access = private)
        
        SpreadsheetApp  % popup app to view (edit) Data & Peak tables       
                
        peak            % Name of peak selected to view (e.g. CP1002) 
        peak_old        % Previous peak
        
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset.
        baseConfig      % Data Structure containing all the configuration options
        configFilename  % Config filename
        
        y               % raw metabolite peak data
        t               % injection order
        isQC            % y data that is a pooled QC
        isBlank         % y data that is a blank sample
        isReference     % y data that a reference (i.e. external test)
        
        yqc             % parsed QC-only metaoblite peak data
        tqc             % parsed QC-only injection order numbers
        toutlier        % timepoints in which yqc is considered an outlier
        
        gammaRange      % range of values used to search for the optimal spline fit to tqc vs yqc
        epsilon         % scaling factor to make the CPAPS algorithm tractable.
        gammaVal        % optimal smoothing value
        cvMse           % cross validation Mean Square Error for the spline fit
        minVal          % minimum value of cvMse
        
        yspline         % the optimal spline fit
        z               % the corrected y data z=f(y) where f = spline model.
        mpv             % median peak value to "reinflate" the corrected data
        
        h_cvMSE         % figure handle for the cvMSE plot
        h_Before        % figure handle for the "before" correction spline (yspline)
        h_Before1       % figure handle for the "before"correction data
        h_Before2       % figure handle for the "before"correction data
        h_Before3       % figure handle for the "before"correction data
        h_Before4       % figure handle for the "before"correction data
        h_Before5       % figure handle for the "before"correction data
        h_After1        % figure handle for the "after" correction data
        h_After2        % figure handle for the "after" correction data
        h_After3        % figure handle for the "after" correction data
        h_After4        % figure handle for the "after" correction data
        h_After5        % figure handle for the "after" correction data
        
        InsufficientDataFlag % flag to abort any atempt at correction when low number of QCs
    end
    
    methods (Access = private)
        
        % helper function to select the correct peak (or a random peak)
        
        function getPeak(app)
            app.peak_old = app.peak;
            app.peak = app.PeakDropDown.Value;
            if strcmp(app.peak,'Random')
                idx = randperm(length(app.PeakTable.UID)-1)+1;
                app.peak = app.PeakTable.UID{idx(1)};
            end
        end


        % helper function to call external OutlierFilter function.
        
        function change = OutlierDetect(app)
            
            old = length(app.toutlier);
            try
                switch app.OutlierMethodDropDown.Value
                    case 'None'
                        app.toutlier = [];
                    case 'Percentile'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'prctile',app.OutlierCISwitch.Value);
                    case 'Linear'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly1',app.OutlierCISwitch.Value);
                    case 'Quadratic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly2',app.OutlierCISwitch.Value);
                    case 'Cubic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly3',app.OutlierCISwitch.Value);
                    otherwise
                        error('This outlier method does not exist');
                end
            catch
               app.toutlier = [];
            end
         
            if length(app.toutlier) == old
                change = false;
            else
                change = true;
            end
            
        end
        
        % meat and potatos of the app. 
        % helper function to call optimiseCSAPS
        
        function optQCRSC(app)       
            %avDist = median(app.tqc(2:end) - app.tqc(1:end-1));
            %app.epsilon = avDist^3/16;
                                                
            switch app.CrossValidationDropDown.Value
                case '3-Fold', nfold = 3;
                case '5-Fold', nfold = 5;
                case '7-Fold', nfold = 7;
                case 'Leaveout', nfold = -1;
            end
            
            switch app.ModeDropDown.Value
                case 'Median'
                    app.gammaVal = NaN;
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                case 'Linear'
                    app.gammaVal = 11;
                    avDist = median(app.tqc(2:end) - app.tqc(1:end-1));
                    app.epsilon = avDist^3/16;
                    app.cvMse = 0;
                    app.minVal = 0;
                otherwise
                    [app.gammaVal,app.epsilon,app.cvMse,app.minVal] = ...
                    optimiseCSAPS(app.tqc,app.yqc,app.gammaRange,nfold,app.MCrepsEditField.Value); 
            end
            if length(app.gammaRange) == 1 || ~strcmp(app.ModeDropDown.Value,'Spline')
                app.gammaValueSlider.Enable = 'off';
            else
                app.gammaValueSlider.Enable = 'on';
                app.gammaValueSlider.Limits = [min(app.gammaRange),max(app.gammaRange)];
                app.gammaValueSlider.Value = app.gammaVal;         
            end
            
        end
        
        % helper function to call external calcStats
        % caclulates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            
            found = ismember(app.t,app.toutlier);
            yy = app.y;
            yy(found) = nan;
            
            isLog = app.LogTransformCheckBox.Value;
            isParametric = app.StatsParametric.Value;
            BRM = app.baseConfig.BlankRatioMethod;

            
            [QCrsdBefore,DratioBefore,REFrsdBefore,blankRatioBefore] = calcStats(yy,app.isQC,app.isBlank,app.isReference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);
            [QCrsdAfter,DratioAfter,REFrsdAfter,blankRatioAfter] = calcStats(app.z,app.isQC,app.isBlank,app.isReference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);
            
            if isnan(REFrsdBefore)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.RefRSDBeforeAfterData.Text = sprintf('(%.2f) %.2f',REFrsdBefore,REFrsdAfter);
            end
            
            if isnan(QCrsdBefore)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
                app.logDratioBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.QCRSDBeforeAfterData.Text = sprintf('(%.2f) %.2f',QCrsdBefore,QCrsdAfter);           
                app.logDratioBeforeAfterData.Text = sprintf('(%.2f) %.2f',DratioBefore,DratioAfter);
                app.BlankRatioData.Text = sprintf('(%.2f) %.2f',blankRatioBefore,blankRatioAfter);
            end
            if isnan(blankRatioBefore)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('(%.2f) %.2f',blankRatioBefore,blankRatioAfter);
            end
            
        end
       
        % plotting the results of the optimisation
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function plot_cvMSE(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
            
            cla(app.UIAxesMSE);
            plot(app.UIAxesMSE,app.gammaRange,app.cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',4);
            hold(app.UIAxesMSE,'on');
            plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor',[0.7,0.7,0.7],'MarkerSize',10);
            app.h_cvMSE = plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',8);
            if numel(app.gammaRange) ==1
                xlim(app.UIAxesMSE,[app.gammaVal-1,app.gammaVal+1]);
                ylim(app.UIAxesMSE,[app.minVal,2*app.minVal]);
            elseif numel(app.cvMse) ==1
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[-1,1]);
                if strcmp(app.ModeDropDown.Value,'Spline')
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';'Too few QCs for Spline Correction'},"HorizontalAlignment","center");
                else
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';[app.ModeDropDown.Value,' Correction']},"HorizontalAlignment","center");
                end
            else
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[min(app.cvMse),max(app.cvMse)]); 
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;
            
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;

        end
        
        %Plot the data and optimal spline before correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_before(app)
            
            isLog = app.LogTransformCheckBox.Value;
            found = ismember(app.t,app.toutlier);
            youtlier = app.y(found);

            dot_size = 7;
            lw = 2;
            ms = 6;
   
            temp = app.y(app.isBlank);
            if isempty(temp)
                temp = NaN;
            else
                if isLog
                    temp = power(10,temp);
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOD;
                    temp = log10(temp);
                else
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOD;
                end
            end
            
          
            cla(app.UIAxesBefore);
            app.h_Before5 = line(app.UIAxesBefore,[app.t(1) app.t(end)],[temp temp],'LineStyle',':','Color','m','LineWidth',lw-1);
            datatip(app.h_Before5,'Visible','off');
            app.h_Before5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('blank threshold','');
            app.h_Before5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesBefore,'on');           
            app.h_Before1 = plot(app.UIAxesBefore,app.t(~app.isQC),app.y(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);   
            app.h_Before1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(~app.isQC));
            app.h_Before1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(~app.isQC));            
            plot(app.UIAxesBefore,app.toutlier,youtlier,'ks','MarkerSize',dot_size+4,'MarkerFaceColor','k','LineWidth',lw);
            app.h_Before2 = plot(app.UIAxesBefore,app.t(app.isQC),app.y(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_Before2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isQC));
                app.h_Before2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isQC));
            end
            app.h_Before3 = plot(app.UIAxesBefore,app.t(app.isBlank),app.y(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size); 
            if sum(app.isBlank) ~= 0
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isBlank));
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isBlank));   
            end
            app.h_Before4 = plot(app.UIAxesBefore,app.t(app.isReference),app.y(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);           
            if sum(app.isReference) ~= 0
                app.h_Before4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isReference));
                app.h_Before4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isReference));      
            end
            plot(app.UIAxesBefore,app.t,app.yspline,'--','MarkerSize',ms,'LineWidth',lw-1,'color',[0.7,0.7,0.7]);
            app.h_Before = plot(app.UIAxesBefore,app.t,app.yspline,'r--','MarkerSize',ms,'LineWidth',lw-1);
            app.h_Before.DataTipTemplate.DataTipRows(1) = dataTipTextRow('correction spline','');
            app.h_Before.DataTipTemplate.DataTipRows(2) = [];
            axis(app.UIAxesBefore,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesBefore,'off');
            title(app.UIAxesBefore,[num2str(app.peak),': Original'])
        end
        
        %Plot the data and optimal spline after correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_after(app)

            dot_size = 7;
            ms = 6;
            lw = 2;
            
            cla(app.UIAxesAfter); 
            app.h_After5 = line(app.UIAxesAfter,[app.t(1) app.t(end)],[app.mpv app.mpv],'LineStyle','--','Color','r','LineWidth',lw-1);
            datatip(app.h_After5,'Visible','off');
            app.h_After5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('grand median','');
            app.h_After5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesAfter,'on');
            plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            app.h_After1 = plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);
            app.h_After1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(~app.isQC));
            app.h_After1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(~app.isQC));
            app.h_After2 = plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_After2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isQC));
                app.h_After2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isQC));
            end
            app.h_After3 = plot(app.UIAxesAfter,app.t(app.isBlank),app.z(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size);
            if sum(app.isBlank) ~= 0
                app.h_After3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isBlank));
                app.h_After3.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isBlank));
            end
            app.h_After4 = plot(app.UIAxesAfter,app.t(app.isReference),app.z(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);
            if sum(app.isReference) ~= 0
                app.h_After4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.DataTable.SampleID(app.isReference));
                app.h_After4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.DataTable.SampleType(app.isReference));
            end
            axis(app.UIAxesAfter,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesAfter,'off');
            title(app.UIAxesAfter,[num2str(app.peak),': Corrected'])
        end
        
        % helper function to transfer the data from the baseConfig
        % structure to the GUI
        
        function UpdateConfigGUI(app)
     
            app.LogTransformCheckBox.Value = app.baseConfig.LogTransform;
            app.IgnoreBlanksCheckBox.Value = false;%app.baseConfig.IgnoreBlanks;
            app.RemoveZerosCheckBox.Value = app.baseConfig.RemoveZeros;
            
            app.OutlierMethodDropDown.Value = app.baseConfig.OutlierMethod;
            app.OutlierCISwitch.Value = app.baseConfig.OutlierCI;
            app.OutlierPosthocDropDown.Value = app.baseConfig.OutlierPostHoc;
            
            app.ModeDropDown.Value = app.baseConfig.IntraBatchMode;
            app.grangeEditField.Value = app.baseConfig.QCRSCgammaRange;
            app.CrossValidationDropDown.Value = app.baseConfig.QCRSCcvMethod;
            app.MCrepsEditField.Value = app.baseConfig.QCRSCmcReps;
            app.CorrectionSwitch.Value = app.baseConfig.CorrectionType;
                   
            app.BlankRatioMethodDropDown.Value = app.baseConfig.BlankRatioMethod;
            app.RelativeLODEditField.Value = app.baseConfig.RelativeLOD;

            app.StatsParametric.Value = app.baseConfig.StatsParametric;
            
        end
        
        % update all the various peak data when the peak name is changed
        % and account for new radio button selection
        % any changes in the PeakPanel required a new aqusition of data from
        % source Data Table.
        
        function UpdateData(app)
            
            Data = app.DataTable(:,{'Order','QC','Blank','Reference','Batch',app.peak});
             
            if app.IgnoreBlanksCheckBox.Value
                Data = Data(Data.Blank == 0,:); 
            end
            keep = Data.Batch == app.BatchDropDown.Value;
            Data = Data(keep,:);
            
            app.y = Data.(app.peak);
            app.t = Data.Order;
            app.isQC = logical(Data.QC);
            app.isBlank = logical(Data.Blank);
            app.isReference = logical(Data.Reference);
            
           
            if app.RemoveZerosCheckBox.Value
                app.y(app.y == 0) = NaN;
            end
            
            if app.LogTransformCheckBox.Value
                app.y(app.y == 0) = NaN;
                app.RemoveZerosCheckBox.Value = true;
                app.y = log10(app.y);
                ylabel(app.UIAxesBefore,'log_{10}(Peak Value)');
                ylabel(app.UIAxesAfter,'log_{10}(Peak Value)');
            else
                ylabel(app.UIAxesBefore,'Peak Value');
                ylabel(app.UIAxesAfter,'Peak Value');
            end
           
            
            missing = isnan(app.y);
            app.isQC(missing) = false;
            app.tqc = app.t(app.isQC);
            app.yqc = app.y(app.isQC);
            app.mpv = median(app.yqc);
            
            if length(app.tqc) < 3
                %uialert(app.UIFigure,'Too few QC points to construct a correcton curve. QCRSC ignored','Warning','Icon','warning');
                app.gammaValueSlider.Enable = 'off';
                app.OptimiseButton.Enable = 'off';
                app.OutlierApplyButton.Enable = 'off';
                app.cvMse = [];
                app.InsufficientDataFlag = true;
            else
                app.gammaValueSlider.Enable = 'on';
                app.OptimiseButton.Enable = 'on';
                app.OutlierApplyButton.Enable = 'on';
                app.InsufficientDataFlag = false;
            end
        end
    end
    
    methods (Access = public)
            
        function Restart(app)
            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            %app.UIFigure.Name = app.ProjectName;
            app.PeakDropDown.Items = [app.PeakTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            RESETButtonPushed(app);         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.gammavalueLabel.Text = [char(947), ' value'];
            app.gammarangeLabel.Text = [char(947), ' range:'];
            
            jsonD = ['{' newline ...
                '"LogTransform": true,' newline ...
                '"RemoveZeros": true,' newline ...
                '"OutlierScope": "Local",' newline ...
                '"OutlierMethod": "Quadratic",' newline ...
                '"OutlierCI": 0.95,' newline ...
                '"OutlierPostHoc": "NaN",' newline ...
                '"IntraBatchMode": "Spline",' newline ...
                '"InterBatchMode": "QC",' newline ... 
                '"QCRSCgammaRange": "0:0.5:4",' newline ...
                '"QCRSCcvMethod": "5-Fold",' newline ...
                '"QCRSCmcReps": 10,' newline ...
                '"CorrectionType": "Subtract",' newline ...
                '"BlankRatioMethod": "QC",' newline ...
                '"RelativeLOD": 1.5,' newline ...
                '"StatsParametric": true,' newline ...
                '"ParallelProcess": true ' newline ...
                '}'];
            
            switch nargin
                case 1
                    [app.DataTable,app.PeakTable] = generateMetabExamplesNew();
                    app.ProjectName = 'Demo (25 LC-MS C18+ Peaks, single batch)';
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                    app.CurrentFolder = '';
                case 2
                    [app.DataTable,app.PeakTable] = load_dataXL(varargin{1},DataSheet='Data',PeakSheet='Peak');
                    app.ProjectName = varargin{1};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                    app.CurrentFolder = '';
                case 3
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    app.ProjectName = 'Matlab Mode';
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                    app.CurrentFolder = '';
                case 4
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    app.ProjectName = varargin{3};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                    app.CurrentFolder = '';
                case 5
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = 'Matlab Mode';
                    app.CurrentFolder = '';
                case 6
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = varargin{5}; 
                    app.CurrentFolder = '';
                case 7
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = varargin{5};
                    app.CurrentFolder = varargin{6};
                case 8
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure,ColumnWarningFlag=varargin{7});
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = varargin{5}; 
                    app.CurrentFolder = varargin{6};
                otherwise
                    uialert(app.UIFigure,'Too many input arguments','Error','Icon','error');
            end

            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            
            app.DataName = 'Data';
            app.PeakName = 'Peak';
            
            app.PeakDropDown.Items = [app.PeakTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            
            
            RESETButtonPushed(app);
            
            getPeak(app);
            app.PeakEditField.Value = app.peak;
            UpdateData(app);
            OutlierDetect(app);
            app.gammaRange = str2num(app.grangeEditField.Value);
            OptimiseButtonPushed(app); 
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.jsonTxt = app.jsonDefault;
            app.baseConfig = jsondecode(app.jsonTxt);
            validateConfig(app.baseConfig); 
            app.BatchDropDown.Value = app.BatchDropDown.ItemsData(1);
            app.PeakDropDown.Value = app.PeakDropDown.Items(1);
            %app.baseConfig = createDefaultConfigStruct();
            UpdateConfigGUI(app);            
            GetPeakUpdateData(app);
        end

        % Button pushed function: ViewPeakTableButton
        function ViewPeakTableButtonPushed(app, event)
          app.ViewPeakTableButton.Enable = 'off';                
          app.SpreadsheetApp = TidyPeakTableView(app, app.PeakTable, Name=app.ProjectName,PeakName=app.PeakName);
          
        end

        % Button pushed function: LOADConfigButton
        function LOADConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            
            app.UIFigure.Visible = 'off'; 
            [file, path] = uigetfile('.json');
            app.UIFigure.Visible = 'on'; 
            
            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.configFilename = file;
                    UpdateConfigGUI(app);
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.baseConfig = oldConfig;
                end
            else
                uialert(app.UIFigure,'Failed to import config file','Config Error','Icon','error');
            end
        end

        % Button pushed function: SAVEConfigButton
        function SAVEConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            
            app.baseConfig.LogTransform = app.LogTransformCheckBox.Value; 
            %app.baseConfig.IgnoreBlanks = app.IgnoreBlanksCheckBox.Value;
            app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            
            app.baseConfig.OutlierMethod = app.OutlierMethodDropDown.Value;
            app.baseConfig.OutlierCI = app.OutlierCISwitch.Value;
            app.baseConfig.OutlierPostHoc = app.OutlierPosthocDropDown.Value;
            
            app.baseConfig.QCRSCgammaRange = app.grangeEditField.Value;
            app.baseConfig.QCRSCcvMethod = app.CrossValidationDropDown.Value;
            app.baseConfig.QCRSCmcReps = app.MCrepsEditField.Value;
            app.baseConfig.CorrectionType = app.CorrectionSwitch.Value;
            
            app.baseConfig.StatsParametric = app.StatsParametric.Value;
 
            app.UIFigure.Visible = 'off';
            [file,path]=uiputfile('.json','Save QCRSC config file',app.configFilename);
            app.UIFigure.Visible = 'on';

            if ~isequal(file,0)
                %cfg = jsondecode(app.jsonTxt);
                jsonTxtX = jsonencode(app.baseConfig,'PrettyPrint',true);
                fid=fopen(fullfile(path,file),'w');
                fprintf(fid, jsonTxtX);
                fclose(fid); 
            else
                uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
                app.baseConfig = oldConfig;
            end
            
        end

        % Value changed function: PeakEditField
        function PeakEditFieldValueChanged(app, event)
            oldvalue = app.PeakDropDown.Value;
            value = app.PeakEditField.Value;
            if ~ismember(value,app.PeakDropDown.Items)
                uialert(app.UIFigure,'Peak does not exist','Warning','Icon','warning');
                %warndlg('Peak does not exist','Warning');
                value = oldvalue;
            end
            app.PeakDropDown.Value = value;
            GetPeakUpdateData(app);
        end

        % Button pushed function: RandomPeakButton
        function RandomPeakButtonPushed(app, event)
            app.PeakDropDown.Value = 'Random';
            GetPeakUpdateData(app);
        end

        % Value changed function: PeakDropDown
        function GetPeakUpdateData(app, event)
            %value = app.PeakDropDown.Value;
            getPeak(app);
            app.PeakEditField.Value = app.peak;
            UpdateDataChanged(app);
            
            if ~isempty(app.SpreadsheetApp)         
                if isvalid(app.SpreadsheetApp)
                    highlightrow(app.SpreadsheetApp,app.peak);
                end
            end
        end

        % Value changed function: BatchDropDown
        function BatchDropDownValueChanged(app, event)
            UpdateDataChanged(app);
        end

        % Value changed function: IgnoreBlanksCheckBox, 
        % ...and 2 other components
        function UpdateDataChanged(app, event)
            UpdateData(app);
            OutlierDetect(app);
            OptimiseButtonPushed(app);
        end

        % Callback function: OutlierApplyButton, OutlierCISwitch, 
        % ...and 1 other component
        function OutlierApplyButtonPushed(app, event)
            UpdateData(app);
            changed = OutlierDetect(app);
            if changed
                OptimiseButtonPushed(app);
            end
        end

        % Callback function: CrossValidationDropDown, MCrepsEditField, 
        % ...and 3 other components
        function OptimiseButtonPushed(app, event)
           if app.InsufficientDataFlag  
                app.yspline = app.mpv*ones(length(app.y),1);
                app.z = app.y;
                cla(app.UIAxesMSE);
                xlim(app.UIAxesMSE,[-1,1]);
                ylim(app.UIAxesMSE,[-1,1]);
                text(app.UIAxesMSE,0,0.5,{'Too Few QC samples';'QCRSC ignored'},"HorizontalAlignment","center");

                displayStats(app);
           else
                app.gammaRange = str2num(app.grangeEditField.Value);
                optQCRSC(app);
                plot_cvMSE(app);
                [app.z,app.yspline] = QCRSC(app.t,app.y,app.isQC,app.mpv,app.epsilon,app.gammaVal,...
                    app.toutlier,app.CorrectionSwitch.Value,app.OutlierPosthocDropDown.Value); 
                displayStats(app);
            end
            plot_before(app);
            plot_after(app);
            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');
        end

        % Value changed function: CorrectionSwitch, OutlierPosthocDropDown
        function rerunQCRSC(app, event)
            [app.z,app.yspline] = QCRSC(app.t,app.y,app.isQC,app.mpv,app.epsilon,app.gammaVal,...
                app.toutlier,app.CorrectionSwitch.Value,app.OutlierPosthocDropDown.Value); 
            displayStats(app);
            displayStats(app);
            plot_after(app);
        end

        % Value changing function: gammaValueSlider
        function gammaValueSliderValueChanging(app, event)
            changingValue = event.Value;
            app.h_cvMSE.YData = interp1(app.gammaRange,app.cvMse,changingValue);
            app.h_cvMSE.XData = changingValue;
            
            pp = 1/(1+app.epsilon*10^(changingValue));
            yy = csaps(app.tqc,app.yqc,pp,app.t);
            app.z = (app.y-yy)+app.mpv;
            app.h_Before.YData = yy;
            
            app.h_After1.YData = app.z(~app.isQC);
            app.h_After2.YData = app.z(app.isQC);
            if ~isempty(app.z(app.isBlank)), app.h_After3.YData = app.z(app.isBlank); end
            if ~isempty(app.z(app.isReference)), app.h_After4.YData = app.z(app.isReference); end
            
            displayStats(app)
        
        end

        % Value changed function: StatsParametric
        function StatsParametricValueChanged(app, event)
           displayStats(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SpreadsheetApp)           
            delete(app)
        end

        % Value changed function: BlankRatioMethodDropDown
        function BlankRatioMethodDropDownValueChanged(app, event)
            app.baseConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            displayStats(app);
        end

        % Value changed function: RelativeLODEditField
        function RelativeLODEditFieldValueChanged(app, event)
            app.baseConfig.RelativeLOD = app.RelativeLODEditField.Value;            
            plot_before(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Peak Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Peak Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {'A plot of the cross-validation Mean Sqaure Error (cvMSE) for the range of smoothing values. The minimum cvMSE indicates the best smoothing value.'};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.UIAxesMSE.XTickLabel = '';
            app.gammaValueSlider = uislider(app.Panel_CVplot);
            app.gammaValueSlider.Limits = [-1 4];
            app.gammaValueSlider.ValueChangingFcn = createCallbackFcn(app, @gammaValueSliderValueChanging, true);
            app.gammaValueSlider.Tooltip = {'Move the slider to see how changing the smoothing value changes the correction curve, which in turn changes the corrected data.'};
            app.gammavalueLabel = uilabel(app.Panel_CVplot);
            app.gammavalueLabel.Text = 'g value';
            app.Panel_Optimise = uipanel(app.UIFigure);
            app.Panel_Optimise.Title = 'QCRSC:';
            app.MCrepsEditField = uieditfield(app.Panel_Optimise, 'numeric');
            app.MCrepsEditField.Limits = [1 100];
            app.MCrepsEditField.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.MCrepsEditField.Tooltip = {'The number of monte carlo (random reselection) iterations of the k-fold cross validtion. The resulting cvMSE the mean of the generatedset of cvMSEs.'};
            app.MCrepsEditField.Value = 20;
            app.MCrepsLabel = uilabel(app.Panel_Optimise);
            app.MCrepsLabel.Text = 'MCreps:';
            app.OptimiseButton = uibutton(app.Panel_Optimise, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.gammarangeLabel = uilabel(app.Panel_Optimise);
            app.gammarangeLabel.Text = 'g range:';
            app.grangeEditField = uieditfield(app.Panel_Optimise, 'text');
            app.grangeEditField.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.grangeEditField.Tooltip = {'The range of values to search to determine to optimal correction curve.'; 'A values >=4 results in a curve equivalent to a linear regression. '; 'A values < 0 results in a highly nonlinear curve.'};
            app.grangeEditField.Value = '0:0.5:4';
            app.CrossValidationDropDownLabel = uilabel(app.Panel_Optimise);
            app.CrossValidationDropDownLabel.Text = 'Cross Validation:';
            app.CrossValidationDropDown = uidropdown(app.Panel_Optimise);
            app.CrossValidationDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.CrossValidationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.CrossValidationDropDown.Tooltip = {'The cross-validation method used to avoid overfitting the correction curve to the QC data. ''3-Fold'' is recommended.'};
            app.CrossValidationDropDown.Value = '5-Fold';
            app.CorrectionSwitch = uiswitch(app.Panel_Optimise, 'slider');
            app.CorrectionSwitch.Items = {'Subtract', 'Divide'};
            app.CorrectionSwitch.ValueChangedFcn = createCallbackFcn(app, @rerunQCRSC, true);
            app.CorrectionSwitch.Tooltip = {'Subtract the correction curve from all of the input data or or divide all of the input data by the correction curve.'};
            app.CorrectionSwitch.Value = 'Subtract';
            app.ModeDropDownLabel = uilabel(app.Panel_Optimise);
            app.ModeDropDownLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.Panel_Optimise);
            app.ModeDropDown.Items = {'Spline', 'Linear', 'Median'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.ModeDropDown.Tooltip = {'Three correction modes. "Spline" is the default QCRSC algorthm that requires optimisation of the smoothing parameter. "Linear" is a simple (interpolated) linear regression based on the QC values & as such requires no smoothing optimisation. "Median" equalises the QC median across batches & ignores within batch systematic change.'};
            app.ModeDropDown.Value = 'Spline';
            app.PeakPanel = uipanel(app.UIFigure);
            app.RandomPeakButton = uibutton(app.PeakPanel, 'push');
            app.RandomPeakButton.ButtonPushedFcn = createCallbackFcn(app, @RandomPeakButtonPushed, true);
            app.RandomPeakButton.Text = 'Random Peak';
            app.PeakDropDownLabel = uilabel(app.PeakPanel);
            app.PeakDropDownLabel.Text = 'Peak:';
            app.BatchDropDownLabel = uilabel(app.PeakPanel);
            app.BatchDropDownLabel.Text = 'Batch:';
            app.RemoveZerosCheckBox = uicheckbox(app.PeakPanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.IgnoreBlanksCheckBox = uicheckbox(app.PeakPanel);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            app.IgnoreBlanksCheckBox.Value = true;
            app.LogTransformCheckBox = uicheckbox(app.PeakPanel);
            app.LogTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.LogTransformCheckBox.Text = 'Log Transform';
            app.LogTransformCheckBox.Value = true;
            app.BatchDropDown = uidropdown(app.PeakPanel);
            app.BatchDropDown.Items = {'No Data'};
            app.BatchDropDown.ValueChangedFcn = createCallbackFcn(app, @BatchDropDownValueChanged, true);
            app.BatchDropDown.Value = 'No Data';
            app.PeakDropDown = uidropdown(app.PeakPanel);
            app.PeakDropDown.Items = {'No Data'};
            app.PeakDropDown.ValueChangedFcn = createCallbackFcn(app, @GetPeakUpdateData, true);
            app.PeakDropDown.Value = 'No Data';
            app.PeakEditFieldLabel = uilabel(app.PeakPanel);
            app.PeakEditFieldLabel.Text = 'Peak:';
            app.PeakEditField = uieditfield(app.PeakPanel, 'text');
            app.PeakEditField.ValueChangedFcn = createCallbackFcn(app, @PeakEditFieldValueChanged, true);
            app.SAVEConfigButton = uibutton(app.PeakPanel, 'push');
            app.SAVEConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEConfigButtonPushed, true);
            app.SAVEConfigButton.Text = 'SAVE Config';
            app.LOADConfigButton = uibutton(app.PeakPanel, 'push');
            app.LOADConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LOADConfigButtonPushed, true);
            app.LOADConfigButton.Text = 'LOAD Config';
            app.ViewPeakTableButton = uibutton(app.PeakPanel, 'push');
            app.ViewPeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPeakTableButtonPushed, true);
            app.ViewPeakTableButton.Text = 'View Peak Table';
            app.RESETButton = uibutton(app.PeakPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.OutlierDetectionPanel = uipanel(app.UIFigure);
            app.OutlierDetectionPanel.Title = 'Outlier Detection:';
            app.OutlierMethodDropDown = uidropdown(app.OutlierDetectionPanel);
            app.OutlierMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.OutlierMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierMethodDropDown.Tooltip = {'The ''linear''/quatratic/cubic'' options fit a simple polynomial function to the QC data using robust least squares regression. Points outside the population confidence interval are deemed outliers. The ''percentile'' option implements the standard non-parametric 1.5*IQR outlier detection method on the QC samples.'};
            app.OutlierMethodDropDown.Value = 'Percentile';
            app.MethodDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.OutlierCISwitch = uiswitch(app.OutlierDetectionPanel, 'slider');
            app.OutlierCISwitch.Items = {'95%CI', '99%CI'};
            app.OutlierCISwitch.ItemsData = [0.95 0.99];
            app.OutlierCISwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierCISwitch.Value = 0.99;
            app.OutlierApplyButton = uibutton(app.OutlierDetectionPanel, 'push');
            app.OutlierApplyButton.ButtonPushedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierApplyButton.Text = 'Apply';
            app.PosthocDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.PosthocDropDownLabel.Text = 'Post-hoc:';
            app.OutlierPosthocDropDown = uidropdown(app.OutlierDetectionPanel);
            app.OutlierPosthocDropDown.Items = {'Ignore', 'MPV', 'NaN'};
            app.OutlierPosthocDropDown.ValueChangedFcn = createCallbackFcn(app, @rerunQCRSC, true);
            app.OutlierPosthocDropDown.Tooltip = {'For the corrected data. Either ignore outliers or replace with the QC median peak value (MPV) or remove (replace with "not a number" NaN).'};
            app.OutlierPosthocDropDown.Value = 'NaN';
            app.StatisticsBeforeAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfterPanel.Title = 'Statistics: (Before) After';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.RefRSDBeforeAfterData.Text = '(NaN) NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.refRSDLabel.Tooltip = {'D-Ratio* = std(log10(y))/std(log(y))'; 'or'; 'D-Ratio* = MAD(log10(y))/MAD(log10(y))'; ''; 'std = standard deviation'; 'MAD = median absolute deviation'};
            app.refRSDLabel.Text = '%refRSD :';
            app.logDratioBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.logDratioBeforeAfterData.Text = '(NaN) NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.dRatioLabel.Tooltip = {'D-Ratio* = std(log10(y))/std(log(y))'; 'or'; 'D-Ratio* = MAD(log10(y))/MAD(log10(y))'; ''; 'std = standard deviation'; 'MAD = median absolute deviation'};
            app.dRatioLabel.Text = '%dRatio :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.QCRSDBeforeAfterData.Text = '(NaN) NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.qcRSDLabel.Tooltip = {'QCRSD = mean(yqc)/std(yqc)'; 'or'; 'QCRSD = median(yqc)/MAD(yqc)'; ''; 'std = stndard deviation'; 'MAD = median absolute deviation'};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.StatsParametric = uiswitch(app.StatisticsBeforeAfterPanel, 'slider');
            app.StatsParametric.Items = {'parametric', 'non-parametric'};
            app.StatsParametric.ItemsData = [true false];
            app.StatsParametric.ValueChangedFcn = createCallbackFcn(app, @StatsParametricValueChanged, true);
            app.StatsParametric.Value = true;
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfterPanel);
            app.BlankRatioData.Text = '(NaN) NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.blankRatioLabel.Tooltip = {'D-Ratio* = std(log10(y))/std(log(y))'; 'or'; 'D-Ratio* = MAD(log10(y))/MAD(log10(y))'; ''; 'std = standard deviation'; 'MAD = median absolute deviation'};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.Label = uilabel(app.StatisticsBeforeAfterPanel);
            app.Label.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'ceX.png';
            app.BlankRatioPanel = uipanel(app.UIFigure);
            app.BlankRatioPanel.Title = 'Blank Ratio';
            app.MethodDropDownLabel_2 = uilabel(app.BlankRatioPanel);
            app.MethodDropDownLabel_2.Text = 'Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.BlankRatioPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BlankRatioMethodDropDownValueChanged, true);
            app.BlankRatioMethodDropDown.Tooltip = {'BlankRatio = 100*max(BlankValue)/SampleReferenceValue. ''QC'' sets the SampleReferenceValue as the median QC value; ''Median'' sets the SampleReferenceValue as the median Sample value; ''Percentile'' sets BlankRatio = % of Samples < max(BlankValue)*RelativeLOD (RelativeLOD is a user defined constant - default 1.5). In this instance missing values are considered = Blank value'};
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.ThresholdLabel = uilabel(app.BlankRatioPanel);
            app.ThresholdLabel.Text = 'RelativeLOD:';
            app.RelativeLODEditField = uieditfield(app.BlankRatioPanel, 'numeric');
            app.RelativeLODEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeLODEditFieldValueChanged, true);
            app.RelativeLODEditField.Tooltip = {'The RelativeLOD is a muliplier (relative to the Blank) used to set a value below which a peak is considered background noise. i.e., "estimated LOD = max(Blank) x relativeLOD"'};
            app.RelativeLODEditField.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCconfigExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end