classdef QCRSCfeatureExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IgnoreBlanksCheckBox           matlab.ui.control.CheckBox
        QCCorrectionParametersPanel    matlab.ui.container.Panel
        LogTransformedCorrectionCheckBox  matlab.ui.control.CheckBox
        RemoveZerosCheckBox            matlab.ui.control.CheckBox
        QCRSCMonteCarlorepetitionsEditField  matlab.ui.control.NumericEditField
        QCRSCMonteCarlorepetitionsEditFieldLabel  matlab.ui.control.Label
        QCRSCCrossValidationMethodDropDown  matlab.ui.control.DropDown
        QCRSCCrossValidationMethodDropDownLabel  matlab.ui.control.Label
        QCRSCgammarangeEditField       matlab.ui.control.EditField
        QCRSCgammarangeEditFieldLabel  matlab.ui.control.Label
        BetweenBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        BetweenBatchCorrectionModeDropDownLabel  matlab.ui.control.Label
        WithinBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        WithinBatchCorrectionModeDropDownLabel  matlab.ui.control.Label
        QCOutlierReplacementStrategyDropDown  matlab.ui.control.DropDown
        QCOutlierReplacementStrategyDropDownLabel  matlab.ui.control.Label
        QCOutlierDetectionCIEditField  matlab.ui.control.NumericEditField
        QCOutlierDetectionCIEditFieldLabel  matlab.ui.control.Label
        QCOutlierDetectionMethodDropDown  matlab.ui.control.DropDown
        QCOutlierDetectionMethodDropDownLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        LegendCheckBox                 matlab.ui.control.CheckBox
        LabelplotbyDropDown            matlab.ui.control.DropDown
        LabelplotbyDropDownLabel       matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        FeaturePanel                   matlab.ui.container.Panel
        ReprocessButton                matlab.ui.control.Button
        ViewFeatureDictionaryButton    matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        FeatureEditField               matlab.ui.control.EditField
        FeatureEditFieldLabel          matlab.ui.control.Label
        FeatureDropDown                matlab.ui.control.DropDown
        FeatureLabel                   matlab.ui.control.Label
        RandomFeatureButton            matlab.ui.control.Button
        Panel_CVplot                   matlab.ui.container.Panel
        UIAxesMSE                      matlab.ui.control.UIAxes
        StatisticsBeforeAfter95CIAfterPanel  matlab.ui.container.Panel
        sampleRSDLabel                 matlab.ui.control.Label
        SampleRSDBeforeAfterData       matlab.ui.control.Label
        ViewBatchStatisticsButton      matlab.ui.control.Button
        blankRatioLabel                matlab.ui.control.Label
        BlankRatioData                 matlab.ui.control.Label
        qcRSDLabel                     matlab.ui.control.Label
        QCRSDBeforeAfterData           matlab.ui.control.Label
        dRatioLabel                    matlab.ui.control.Label
        dRatioBeforeAfterData          matlab.ui.control.Label
        refRSDLabel                    matlab.ui.control.Label
        RefRSDBeforeAfterData          matlab.ui.control.Label
        Panel_BeforeAfter              matlab.ui.container.Panel
        UIAxesAfter                    matlab.ui.control.UIAxes
        UIAxesBefore                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        CurrentFolder   %
        DataTable       % Data Table - Public so it can be edited by app1
        FeatureTable       % Feature Table - primarliy to access P.UID
        ExcelName
        ProjectName     % Project Name
        DataName
        FeatureName
        ImportSuccess
        FileType        % 'xls' or 'csv'
        baseConfig      % Data Structure containing all the operable configuration options
%                         app.baseConfig.LogTransform;
%                         app.baseConfig.RemoveZeros;             
%                         app.baseConfig.OutlierMethod;
%                         app.baseConfig.OutlierCI;
%                         app.baseConfig.OutlierPostHoc;           
%                         app.baseConfig.QCRSCgammaRange;
%                         app.baseConfig.QCRSCcvMethod;
%                         app.baseConfig.QCRSCmcReps;
%                         app.baseConfig.QCRSCtype;            
%                         app.baseConfig.ParallelProcess;
        
    end

    
    properties (Access = private)
        
        % Setup and import
        CallerApp
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables
        StatsTableApp   % popup app to view Batch Stats Table    
        StatsTable      % Stats Table for Batch RSD D-Ratio etc stats.
        feature            % Name of feature selected to view (e.g. CP1002) 
        feature_old        % Previous feature      
        jsonTxt         % current raw json config.
        jsonDefault     % default json config for use during reset.
        jsonView        % formatted json file for printing to screen.
        
        configFilename  % config filename
        callerConfig
                                 
        % Data for selected feature
        
        Data            % Temp table for chosen feature and associated cleaned meta data
                
        % Results
        
        OptStruct       % Data Structure with {gamma,epsilon,cvMse,minVal} for each batch
                        %         gammaVal optimal smoothing value
                        %         cvMse    (vector) cross validation Mean Square Error
                        %         minVal   minimum value vector of cvMse
        
        
        InsufficientDataFlag % flag to abort any atempt at correction when low number of QCs
        IgnoreBlanks % Description
        cmap
        cmapName
        noQCflag
    end
    
    methods (Access = private)
        
        % helper function to select the correct feature (or a random feature)
        
        function getFeature(app)
            app.feature_old = app.feature;
            app.feature = app.FeatureDropDown.Value;
            if strcmp(app.feature,'Random')
                idx = randperm(length(app.FeatureTable.UID)-1)+1;
                app.feature = app.FeatureTable.UID{idx(1)};
            end
        end
                
        % helper function to call external calcStats
        % caclulates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            

            found = ismember(app.Data.Order,app.Data.Outlier);
            yy = app.Data.Y;
            yy(found) = nan;
            
            isLog = app.baseConfig.LogTransform;
            BRM = app.baseConfig.BlankRatioMethod;
            
            [QCrsdBefore,~,~,SAMPLErsdBefore,~,~,REFrsdBefore,~,~,dRatioBefore,blankRatioBefore] = calcStats(yy,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
            [QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper,dRatioAfter,blankRatioAfter]  = calcStats(app.Data.Z,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);             
            
            if isnan(QCrsdBefore)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.QCRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',QCrsdBefore,QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper);           
            end

            if isnan(SAMPLErsdBefore)
                app.SampleRSDBeforeAfterData.Text = sprintf('Insufficient Data');         
            else
                app.SampleRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',SAMPLErsdBefore,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper);           
            end

            if isnan(REFrsdBefore)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.RefRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',REFrsdBefore,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper);           
            end

            if isnan(dRatioBefore)           
                app.dRatioBeforeAfterData.Text = sprintf('Insufficient Data');
            else                                     
                app.dRatioBeforeAfterData.Text = sprintf('[%.2f] %.2f',dRatioBefore,dRatioAfter);
            end
            
            if isnan(blankRatioBefore)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('(%.2f) %.2f',blankRatioBefore,blankRatioAfter);
            end          
            
            StatsBefore = BatchCalcStats(yy,app.Data.Batch,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
            StatsAfter = BatchCalcStats(app.Data.Z,app.Data.Batch,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
                        
            StatsBefore = convertvars(StatsBefore,StatsBefore.Properties.VariableNames,'string');
            StatsAfter = convertvars(StatsAfter,StatsAfter.Properties.VariableNames,'string');
            
            app.StatsTable = StatsBefore;
            
            for i = 1:height(app.StatsTable)
                for j = 2:width(app.StatsTable)
                    if ismissing(app.StatsTable{i,j})
                        app.StatsTable{i,j} = "Insufficient Data";
                    else
                        app.StatsTable{i,j} = strjoin(["[",StatsBefore{i,j},"] ",StatsAfter{i,j}],'');                   
                    end
                end
            end
            
            if ~isempty(app.StatsTableApp)         
                if isvalid(app.StatsTableApp)
                     updateTable(app.StatsTableApp,app.StatsTable);                     
                end
            end
        
        end
       
        % plotting the results of the optimisation
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function batchPlotCVmse(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
                     
            cla(app.UIAxesMSE);
            
            batchNumTotal = numel(unique(app.Data.Batch));
            
            if strcmp(app.baseConfig.IntraBatchMode,'Spline')
                for i = 1:batchNumTotal
                    plot(app.UIAxesMSE,app.baseConfig.QCRSCgammaRange,app.OptStruct(i).cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',5);
                    hold(app.UIAxesMSE,'on');
                    plot(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',8);
                    text(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,num2str(i),'HorizontalAlignment',"center",'VerticalAlignment',"middle","Color",'w');
                end
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;           
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;
            
            app.UIAxesMSE.YLimMode = 'auto';
            app.UIAxesMSE.XLimMode = 'auto';
            app.UIAxesMSE.YLimitMethod = 'tight';
            app.UIAxesMSE.XLimitMethod = 'tight';

        end
        
        % update all the various feature data when the feature name is changed
        % and account for new radio button selection
        % any changes in the FeaturePanel required a new aqusition of data from
        % source Data Table.
        
        function updateData(app)
            
            app.Data = app.DataTable(:,{'SampleID','SampleType','Order','QC','Blank','Batch','Reference','Sample'});
            
            y = app.DataTable.(app.feature);

            if app.baseConfig.RemoveZeros
                y(y == 0) = NaN;
            end

            if app.baseConfig.LogTransform
                y(y==0) = NaN;
                app.baseConfig.RemoveZeros = true;
                y = log10(y);
            end

            label = app.DataTable.(app.LabelplotbyDropDown.Value);
            if isnumeric(label)
                label = cellstr(num2str(label));
            end
            label = strrep(label,'_',' ');

            if strcmp('Batch',app.LabelplotbyDropDown.Value)
                label = cellfun(@(x) ['Batch ',x],label,'UniformOutput',false);   
            end

            app.Data.Y = y;
            app.Data.Label = label;
            
            missing = isnan(y);
            app.Data.QC(missing) = false;
            
            if app.IgnoreBlanksCheckBox.Value
                app.Data = app.Data(app.Data.Blank == 0,:); 
            end
            
        end           
        
        function correctFeature(app)
            t = app.Data.Order;
            y = app.Data.Y;
            batch = app.Data.Batch;
            isQC = app.Data.QC;
            isSample = app.Data.Sample;
            isREF = app.Data.Reference;
            isBlank = app.Data.Blank;

            [z,yspline,~,toutlier,app.OptStruct] = OptimiseAndCorrectFeatureX(app.baseConfig,t,y,batch,isQC,isSample,isREF,isBlank);

            app.Data.Z = z;
            app.Data.Yspline = yspline;
            app.Data.Outlier = ismember(t,toutlier);                 
        end
        
        function checkInterBatchValid(app)
            if app.noQCflag 
                 app.baseConfig.IntraBatchMode = 'Sample';
                app.baseConfig.InterBatchMode = 'Sample';
                app.baseConfig.BlankRatioMethod = 'Median';
                updateConfigTextArea(app,'IntraBatchMode','Sample');
                updateConfigTextArea(app,'InterBatchMode','Sample');
                updateConfigTextArea(app,'BlankRatioMethod','Median');
                baseException = MException('QCRSC:WrongInterBatchMode','There are <3 QCs per batch so switching to "Sample" correction. IntraBatchMode, InterBatchMode & BlankRatioMethod changed.');
                throw(baseException)
            end

            if strcmp(app.baseConfig.IntraBatchMode,'Sample')
                if ~strcmp(app.baseConfig.InterBatchMode,'Sample')
                    app.baseConfig.InterBatchMode = 'Sample';
                    app.baseConfig.BlankRatioMethod = 'Median';
                    updateConfigTextArea(app,'InterBatchMode','Sample');
                    updateConfigTextArea(app,'BlankRatioMethod','Median');
                    baseException = MException('QCRSC:WrongInterBatchMode','When using Sample-based intra-batch correction the inter-batch correction must be also be set to "Sample" (and blankRatio set to "Median"). InterBatchMode & BlankRatioMethod changed.');
                    throw(baseException)
                end
            end
            if strcmp(app.baseConfig.InterBatchMode,'Sample')
                if ~strcmp(app.baseConfig.IntraBatchMode,'Sample')
                    app.baseConfig.InterBatchMode = 'QC';
                    updateConfigTextArea(app,'InterBatchMode','QC');
                    baseException = MException('QCRSC:WrongInterBatchMode','When when using QC intra-batch correction the inter-batch correction cannot be set to "Sample". InterBatchMode changed to "QC".');
                    throw(baseException)
                end
            end
            if strcmp(app.baseConfig.InterBatchMode,'Reference')
                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                for i = 1:NumOfBatches
                    batchRef = app.DataTable.Reference(app.DataTable.Batch == ubatch(i));
                    batchRefsum(i) = sum(batchRef);
                end
                if any(batchRefsum == 0)
                    app.baseConfig.InterBatchMode = 'QC';
                    updateConfigTextArea(app,'InterBatchMode','QC');
                    baseException = MException('QCRSC:WrongInterBatchMode','There must be at least one Reference sample per batch for Reference-based inter-batch correction. InterBatchMode changed to "QC".');
                    throw(baseException)
                else
                    app.BatchwiseSwitch.Value = true;
                    app.BatchwiseSwitch.Enable = "off";
                    app.BatchWiseDropDown.Enable = "on";
                end
            end
        end

        function updateConfigTextArea(app,field,value)
                       
            oldConfigText = app.configFileTextArea.Value;
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
           
            try
                app.baseConfig = jsondecode(app.jsonTxt);
                app.baseConfig.(field) = value;
                app.jsonTxt = jsonencode(app.baseConfig,'PrettyPrint',true);
                app.jsonView = strsplit(app.jsonTxt,'\n')';          
                app.configFileTextArea.Value = app.jsonView;
                app.baseConfig = validateConfig(app.baseConfig);
            catch
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = oldConfigText;
                app.jsonTxt = oldJSON;
                ME = MException('QCRSC:baseConfigError','Changing QCRSC Configuration failed');
                throw(ME);
            end          
        end

    end
    
    methods (Access = public)
               
        function Restart(app)
            app.UIFigure.Name = app.ProjectName;
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            RESETButtonPushed(app);         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            
            
            app.CallerApp = callerApp;
            app.UIFigure.Name = '';

            % jsonD = ['{' newline ...
            %     '"LogTransform": true,' newline ...
            %     '"RemoveZeros": true,' newline ...
            %     '"OutlierMethod": "Linear",' newline ...
            %     '"OutlierCI": 0.95,' newline ...
            %     '"OutlierPostHoc": "MPV",' newline ...
            %     '"IntraBatchMode": "Spline",' newline ...
            %     '"InterBatchMode": "QC",' newline ... 
            %     '"QCRSCgammaRange": "1:0.5:4",' newline ...
            %     '"QCRSCcvMethod": "5-Fold",' newline ...
            %     '"QCRSCmcReps": 5,' newline ...
            %     '"CorrectionType": "Subtract",' newline ...
            %     '"BlankRatioMethod": "QC",' newline ...
            %     '"RelativeLOD": 1.5,' newline ...
            %     '"ParallelProcess": true ' newline ...
            %     '}'];
                
            
%             app.jsonTxt = app.jsonDefault;
%             app.jsonView = strsplit(app.jsonTxt,'\n')';
%             app.baseConfig = jsondecode(app.jsonDefault);            
%             app.configFileTextArea.Value = app.jsonView;
            
            if isempty(app.CallerApp.DataTable)
                [app.DataTable,app.FeatureTable] = generateMetabExamplesNew();
                app.ProjectName = 'Demo (25 LC-MS C18+ Features, single batch)';
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                app.jsonDefault = app.CallerApp.jsonTxt;
                app.configFilename = app.CallerApp.configFilename;
                app.CurrentFolder = app.CallerApp.CurrentFolder;
            else
                app.DataTable = app.CallerApp.DataTable;
                app.FeatureTable = app.CallerApp.FeatureTable(app.CallerApp.preProcKeep,:);
                %app.FeatureTable = app.CallerApp.FeatureTable;
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                app.jsonDefault = app.CallerApp.jsonTxt;
                app.configFilename = app.CallerApp.configFilename;
                app.ProjectName = app.CallerApp.ProjectName; 
                app.CurrentFolder = app.CallerApp.CurrentFolder;                
            end
            
            temp = jsondecode(app.jsonDefault);
            app.callerConfig = validateConfig(temp);
            
            app.UIFigure.Name = app.ProjectName;
            app.DataName = 'Data Table';
            app.FeatureName = 'Feature Dictionary';
                      
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
     
            RESETButtonPushed(app);
            
            getFeature(app);
            app.FeatureEditField.Value = app.feature;

            maxNumQCperBatch = checkNumberInBatch(app.DataTable,'QC');
            app.noQCflag = maxNumQCperBatch < 3;
            try
                checkInterBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end

            %updateData(app);
            ReprocessButtonPushed(app);             
        end

        % Button pushed function: ViewFeatureDictionaryButton
        function ViewFeatureDictionaryButtonPushed(app, event)
          app.ViewFeatureDictionaryButton.Enable = 'off';          
          app.SpreadsheetApp = TidyFeatureTableView(app,app.FeatureTable,Name=app.ProjectName,FeatureName=app.FeatureName);
        end

        % Value changed function: FeatureEditField
        function FeatureEditFieldValueChanged(app, event)
            oldvalue = app.FeatureDropDown.Value;
            value = app.FeatureEditField.Value;
            if ~ismember(value,app.FeatureDropDown.Items)
                uialert(app.UIFigure,'Feature does not exist','Warning','Icon','warning');                
                value = oldvalue;
            end
            app.FeatureDropDown.Value = value;
            GetFeatureAndUpdateData(app);
        end

        % Value changed function: IgnoreBlanksCheckBox
        function IgnoreBlanksCheckBoxValueChanged(app, event)
            ReprocessButtonPushed(app);            
        end

        % Callback function: not associated with a component
        function configFileTextAreaValueChanged(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
            app.jsonView = app.configFileTextArea.Value;
            app.jsonTxt = strjoin(app.jsonView,'\n');  
            
            try
                app.baseConfig = jsondecode(app.jsonTxt);
            catch
                uialert(app.UIFigure,{char('Configuration file is invalid.');char('Please check text values are surrounded by quotes.'); char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
                return;
            end
            try
                app.baseConfig = validateConfig(app.baseConfig);
            catch ME
                uialert(app.UIFigure,{ME.message; char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
            end
            try
                checkInterBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.jsonTxt = app.jsonDefault;
            app.jsonView = strsplit(app.jsonTxt,'\n')';
            app.configFileTextArea.Value = app.jsonView;
            app.FeatureDropDown.Value = app.FeatureDropDown.Items(1);
            app.baseConfig = jsondecode(app.jsonTxt);
            app.baseConfig = validateConfig(app.baseConfig);
            app.IgnoreBlanksCheckBox.Value = false;

            list = app.DataTable.Properties.VariableNames;
            listx = {};
            h = height(app.DataTable);
            for i = 1:length(list)
                temp = length(unique(app.DataTable.(list{i})));
                if temp ~=h && temp > 1 && temp < h/2, listx = [listx;list{i}]; end                             
            end
            rm = ismember(listx,{'Batch','QC','Sample','Blank','Reference'});
            listx(rm) = [];
            rm = ismember(listx,app.FeatureTable.UID);
            listx(rm) = [];
            listx = ['Batch';listx];
            app.LabelplotbyDropDown.Items = listx;
            app.LabelplotbyDropDown.Value = 'Batch';
            app.cmapName = 'quoka';
            app.cmap = mapRcolors(app.cmapName); 

            GetFeatureAndUpdateData(app);            
        end

        % Value changed function: FeatureDropDown
        function GetFeatureAndUpdateData(app, event)
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            ReprocessButtonPushed(app);
                      
            if ~isempty(app.SpreadsheetApp)         
                if isvalid(app.SpreadsheetApp)
                    highlightrow(app.SpreadsheetApp,app.feature);
                end
            end
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            updateData(app);
            correctFeature(app);
            batchPlotCVmse(app)
            displayStats(app);
            titleBefore = [num2str(app.feature),': Original'];
            titleAfter = [num2str(app.feature),': Corrected'];
            if strcmp(app.LabelplotbyDropDown.Value,'Batch')
                controlChart(app.UIAxesBefore,app.Data,cmap='batch', ...
                    islog=app.baseConfig.LogTransform,title=titleBefore, ...
                    RelativeLOD=app.baseConfig.RelativeLOD,IntraBatchMode=app.baseConfig.IntraBatchMode);
                controlChart(app.UIAxesAfter,app.Data,cmap='batch', ...
                    before=false,islog=app.baseConfig.LogTransform,title=titleAfter, ...
                    RelativeLOD=app.baseConfig.RelativeLOD,IntraBatchMode=app.baseConfig.IntraBatchMode);
            else
                controlChart(app.UIAxesBefore,app.Data,cmap=app.cmap, ...
                    islog=app.baseConfig.LogTransform,title=titleBefore, ...
                    RelativeLOD=app.baseConfig.RelativeLOD,IntraBatchMode=app.baseConfig.IntraBatchMode);
                controlChart(app.UIAxesAfter,app.Data,cmap=app.cmap, ...
                    before=false,islog=app.baseConfig.LogTransform,title=titleAfter, ...
                    RelativeLOD=app.baseConfig.RelativeLOD,IntraBatchMode=app.baseConfig.IntraBatchMode);
            end
            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');

            if app.LegendCheckBox.Value
                app.UIAxesBefore.Legend.Visible = true;
            else
                app.UIAxesBefore.Legend.Visible = false;
            end
        end

        % Button pushed function: RandomFeatureButton
        function RandomFeatureButtonPushed(app, event)
            app.FeatureDropDown.Value = 'Random';
            GetFeatureAndUpdateData(app);
        end

        % Button pushed function: ViewBatchStatisticsButton
        function ViewBatchStatisticsButtonPushed(app, event)
            app.StatsTableApp = BatchStatsTable(app, app.StatsTable, app.feature);
        end

        % Value changed function: LabelplotbyDropDown
        function LabelplotbyDropDownValueChanged(app, event)
            ReprocessButtonPushed(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isequaln(app.callerConfig,app.baseConfig)
                res = uiconfirm(app.UIFigure,'Do you want to transfer this configuration to the main application?','Confirm Action','Options',{'Yes','No'});
                if strcmp(res,'Yes')            
                    app.CallerApp.jsonTxt = app.jsonTxt;
                    app.CallerApp.baseConfig = app.baseConfig;
                    app.CallerApp.configFileTextArea.Value = app.jsonView;
                end
            end
            delete(app.SpreadsheetApp)
            delete(app.StatsTableApp)            
            delete(app)
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            if app.LegendCheckBox.Value
                app.UIAxesBefore.Legend.Visible = true;
            else
                app.UIAxesBefore.Legend.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.StatisticsBeforeAfter95CIAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfter95CIAfterPanel.Title = 'Statistics: [Before] After (95% CI After)';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.RefRSDBeforeAfterData.Text = '[NaN] NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.refRSDLabel.Tooltip = {''};
            app.refRSDLabel.Text = '%refRSD :';
            app.dRatioBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioBeforeAfterData.Text = '[NaN] NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioLabel.Tooltip = {''};
            app.dRatioLabel.Text = '%dRatio :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.QCRSDBeforeAfterData.Text = '[NaN] NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.qcRSDLabel.Tooltip = {''};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.BlankRatioData.Text = '[NaN] NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.blankRatioLabel.Tooltip = {''};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.ViewBatchStatisticsButton = uibutton(app.StatisticsBeforeAfter95CIAfterPanel, 'push');
            app.ViewBatchStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBatchStatisticsButtonPushed, true);
            app.ViewBatchStatisticsButton.Text = {'View '; 'Batch'; 'Statistics'};
            app.SampleRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.SampleRSDBeforeAfterData.Text = '[NaN] NaN';
            app.sampleRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.sampleRSDLabel.Tooltip = {''};
            app.sampleRSDLabel.Text = '%sampleRSD :';
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {''};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            xlabel(app.UIAxesMSE, '\gamma value')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.FeaturePanel = uipanel(app.UIFigure);
            app.RandomFeatureButton = uibutton(app.FeaturePanel, 'push');
            app.RandomFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RandomFeatureButtonPushed, true);
            app.RandomFeatureButton.Text = 'Random Feature';
            app.FeatureLabel = uilabel(app.FeaturePanel);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureDropDown = uidropdown(app.FeaturePanel);
            app.FeatureDropDown.Items = {'No Data'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @GetFeatureAndUpdateData, true);
            app.FeatureDropDown.Value = 'No Data';
            app.FeatureEditFieldLabel = uilabel(app.FeaturePanel);
            app.FeatureEditFieldLabel.Text = 'Feature:';
            app.FeatureEditField = uieditfield(app.FeaturePanel, 'text');
            app.FeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureEditFieldValueChanged, true);
            app.RESETButton = uibutton(app.FeaturePanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ViewFeatureDictionaryButton = uibutton(app.FeaturePanel, 'push');
            app.ViewFeatureDictionaryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureDictionaryButtonPushed, true);
            app.ViewFeatureDictionaryButton.Text = 'View Feature Dictionary';
            app.ReprocessButton = uibutton(app.FeaturePanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Text = 'Reprocess';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'QCfeatureExplorer.png');
            app.Panel = uipanel(app.UIFigure);
            app.LabelplotbyDropDownLabel = uilabel(app.Panel);
            app.LabelplotbyDropDownLabel.Text = 'Label plot by:';
            app.LabelplotbyDropDown = uidropdown(app.Panel);
            app.LabelplotbyDropDown.Items = {'Batch'};
            app.LabelplotbyDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelplotbyDropDownValueChanged, true);
            app.LabelplotbyDropDown.Value = 'Batch';
            app.LegendCheckBox = uicheckbox(app.Panel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.LegendCheckBox.Value = true;
            app.QCCorrectionParametersPanel = uipanel(app.UIFigure);
            app.QCCorrectionParametersPanel.Title = 'QC Correction Parameters:';
            app.QCOutlierDetectionMethodDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDownLabel.Text = 'QC Outlier Detection Method:';
            app.QCOutlierDetectionMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.QCOutlierDetectionMethodDropDown.Value = 'None';
            app.QCOutlierDetectionCIEditFieldLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionCIEditFieldLabel.Text = 'QC Outlier Detection %CI:';
            app.QCOutlierDetectionCIEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCOutlierDetectionCIEditField.Limits = [0.9 0.999];
            app.QCOutlierDetectionCIEditField.Value = 0.95;
            app.QCOutlierReplacementStrategyDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDownLabel.Text = 'QC Outlier Replacement Strategy:';
            app.QCOutlierReplacementStrategyDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDown.Items = {'Median', 'Ignore', 'NaN'};
            app.QCOutlierReplacementStrategyDropDown.Value = 'Median';
            app.WithinBatchCorrectionModeDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDownLabel.Text = 'Within-Batch Correction Mode:';
            app.WithinBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDown.Items = {'Median', 'Linear', 'Spline', 'Sample'};
            app.WithinBatchCorrectionModeDropDown.Value = 'Spline';
            app.BetweenBatchCorrectionModeDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDownLabel.Text = 'Between-Batch Correction Mode:';
            app.BetweenBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDown.Items = {'QC', 'Reference', 'Sample'};
            app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
            app.QCRSCgammarangeEditFieldLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCgammarangeEditFieldLabel.Text = 'QCRSC gamma range:';
            app.QCRSCgammarangeEditField = uieditfield(app.QCCorrectionParametersPanel, 'text');
            app.QCRSCgammarangeEditField.Value = '0.5:0.5:4';
            app.QCRSCCrossValidationMethodDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDownLabel.Text = 'QCRSC Cross Validation Method:';
            app.QCRSCCrossValidationMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.QCRSCCrossValidationMethodDropDown.Value = '3-Fold';
            app.QCRSCMonteCarlorepetitionsEditFieldLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCMonteCarlorepetitionsEditFieldLabel.Text = 'QCRSC Monte Carlo repetitions:';
            app.QCRSCMonteCarlorepetitionsEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCRSCMonteCarlorepetitionsEditField.Limits = [1 Inf];
            app.QCRSCMonteCarlorepetitionsEditField.Value = 10;
            app.RemoveZerosCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.RemoveZerosCheckBox.Value = true;
            app.LogTransformedCorrectionCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.LogTransformedCorrectionCheckBox.Text = 'Log Transformed Correction';
            app.LogTransformedCorrectionCheckBox.Value = true;
            app.IgnoreBlanksCheckBox = uicheckbox(app.UIFigure);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreBlanksCheckBoxValueChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCfeatureExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end