classdef QCmXP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        PreFilterPanel                  matlab.ui.container.Panel
        blankmissingCheckBox            matlab.ui.control.CheckBox
        BatchwiseCheckBox               matlab.ui.control.CheckBox
        PeaksRemainingLabel             matlab.ui.control.Label
        permissiblemissingQCsEditField  matlab.ui.control.NumericEditField
        permissiblemissingQCsEditFieldLabel  matlab.ui.control.Label
        UIAxesPreprocessing             matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
        LaunchConfigExplorer            matlab.ui.control.Button
        VersionLabel                    matlab.ui.control.Label
        Logo                            matlab.ui.control.Image
        LaunchPeakExplorer              matlab.ui.control.Button
        IncomingDataPanel               matlab.ui.container.Panel
        FilenameLabel                   matlab.ui.control.Label
        DataSheetLabel                  matlab.ui.control.Label
        PeakSheetLabel                  matlab.ui.control.Label
        drinkMeButton                   matlab.ui.control.StateButton
        NumberofBlanksLabel             matlab.ui.control.Label
        NumberofReferenceLabel          matlab.ui.control.Label
        NumberofQCLabel                 matlab.ui.control.Label
        NumberofBatchesLabel            matlab.ui.control.Label
        LOADDATAButton                  matlab.ui.control.Button
        ViewPeakTableButton             matlab.ui.control.Button
        NumberofPeaksLabel              matlab.ui.control.Label
        NumberofSamplesLabel            matlab.ui.control.Label
        ConfigurationPanel              matlab.ui.container.Panel
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        configFileTextArea              matlab.ui.control.TextArea
        QCRSCenginePanel                matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        BypassButton                    matlab.ui.control.Button
        peaksCorrectedGauge             matlab.ui.control.Gauge
        RunningLamp                     matlab.ui.control.Lamp
        CompleteLamp                    matlab.ui.control.Lamp
        CompleteLampLabel               matlab.ui.control.Label
        RunningLampLabel                matlab.ui.control.Label
        peakscorrectedLabel             matlab.ui.control.Label
        SavePanel                       matlab.ui.container.Panel
        SuffixEditField                 matlab.ui.control.EditField
        suffixLabel                     matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CleanExplorePanel               matlab.ui.container.Panel
        BatchWiseDropDown               matlab.ui.control.DropDown
        SwitchBeforeAfter               matlab.ui.control.Switch
        BatchwiseSwitch                 matlab.ui.control.Switch
        PanelKnobs                      matlab.ui.container.Panel
        FilterBankLamp                  matlab.ui.control.Lamp
        FilterBankSwitch                matlab.ui.control.RockerSwitch
        FilterBankLabel                 matlab.ui.control.Label
        SampleRSDKnob                   matlab.ui.control.DiscreteKnob
        SampleRSDKnobLabel              matlab.ui.control.Label
        BlankRatioKnobLabel             matlab.ui.control.Label
        BlankRatioKnob                  matlab.ui.control.DiscreteKnob
        DRatioKnob                      matlab.ui.control.DiscreteKnob
        DRatioKnobLabel                 matlab.ui.control.Label
        QCRSDKnob                       matlab.ui.control.DiscreteKnob
        QCRSDKnobLabel                  matlab.ui.control.Label
        ResetFilterButton               matlab.ui.control.Button
        BatchDropDown                   matlab.ui.control.DropDown
        BatchDropDownLabel              matlab.ui.control.Label
        XremainingLabel                 matlab.ui.control.Label
        XmissingthreshEditField         matlab.ui.control.NumericEditField
        XmissingthreshEditFieldLabel    matlab.ui.control.Label
        MPQLabel                        matlab.ui.control.Label
        MPQ95Label                      matlab.ui.control.Label
        CleanPeaksRemainingLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PCATab                          matlab.ui.container.Tab
        MissingValueImputationButtonGroup  matlab.ui.container.ButtonGroup
        kEditField                      matlab.ui.control.NumericEditField
        kLabel                          matlab.ui.control.Label
        blank20minButton                matlab.ui.control.RadioButton
        KNNrowButton                    matlab.ui.control.RadioButton
        KNNcolumnButton                 matlab.ui.control.RadioButton
        IncludeQCSamplesCheckBox        matlab.ui.control.CheckBox
        IncludeReferenceSamplesCheckBox  matlab.ui.control.CheckBox
        IncludeBlankSamplesCheckBox     matlab.ui.control.CheckBox
        PCStatsSwitch                   matlab.ui.control.Switch
        PopOutButton                    matlab.ui.control.Button
        LabelbyDropDown                 matlab.ui.control.DropDown
        LabelbyDropDownLabel            matlab.ui.control.Label
        VariationExplainedbyeachPCLabel  matlab.ui.control.Label
        pcaPreprocessingPanel           matlab.ui.container.Panel
        batchwiseScaleCheckBox          matlab.ui.control.CheckBox
        ParetoscaleCheckBox             matlab.ui.control.CheckBox
        Log10TransformCheckBox          matlab.ui.control.CheckBox
        AutoscaleCheckBox               matlab.ui.control.CheckBox
        DataPointsCheckBox              matlab.ui.control.CheckBox
        ConfidenceIntervalsCheckBox     matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        UIAxesPVE2                      matlab.ui.control.UIAxes
        UIAxesPVE                       matlab.ui.control.UIAxes
        UIAxesPCA                       matlab.ui.control.UIAxes
        ImperialRSDplotTab              matlab.ui.container.Tab
        mzLabelDropDownLabel_2          matlab.ui.control.Label
        CustomLabelDropDownA1           matlab.ui.control.DropDown
        CustomLabelDropDownA2           matlab.ui.control.DropDown
        mzLabelDropDownLabel            matlab.ui.control.Label
        DeltaSwitch                     matlab.ui.control.Switch
        SortbySwitch                    matlab.ui.control.Switch
        SortbySwitchLabel               matlab.ui.control.Label
        RefCheckBox                     matlab.ui.control.CheckBox
        UIAxesImperialRSD               matlab.ui.control.UIAxes
        RSDScatterplotTab               matlab.ui.container.Tab
        CustomLabelDropDown_2Label_2    matlab.ui.control.Label
        CustomLabelDropDownB1           matlab.ui.control.DropDown
        CustomLabelDropDownB2           matlab.ui.control.DropDown
        CustomLabelDropDown_2Label      matlab.ui.control.Label
        LogScaleCheckBox                matlab.ui.control.CheckBox
        UIAxesRSDComparison             matlab.ui.control.UIAxes
        qcRSDTab                        matlab.ui.container.Tab
        SmoothRSDslider                 matlab.ui.control.Slider
        SmoothSlider_2Label             matlab.ui.control.Label
        SwitchRSDpdf                    matlab.ui.control.Switch
        SwitchRSDPlot                   matlab.ui.control.Switch
        UIAxesRSDpdf                    matlab.ui.control.UIAxes
        dRatioTab                       matlab.ui.container.Tab
        SmoothDRATIOslider              matlab.ui.control.Slider
        SmoothSlider_2Label_2           matlab.ui.control.Label
        SwitchDRATIOpdf                 matlab.ui.control.Switch
        SwitchDratioPlot                matlab.ui.control.Switch
        UIAxesDRATIOpdf                 matlab.ui.control.UIAxes
        blankRatioTab                   matlab.ui.container.Tab
        BlankRatioMethodDropDown        matlab.ui.control.DropDown
        BlankRatioMethodDropDownLabel   matlab.ui.control.Label
        SmoothBLANKslider               matlab.ui.control.Slider
        SmoothSlider_2Label_3           matlab.ui.control.Label
        SwitchBlankpdf                  matlab.ui.control.Switch
        SwitchBlankPlot                 matlab.ui.control.Switch
        UIAxesBLANKpdf                  matlab.ui.control.UIAxes
        QCRSCgTab                       matlab.ui.container.Tab
        UIAxesQCRSCpdf                  matlab.ui.control.UIAxes
        SaveCleanPanel                  matlab.ui.container.Panel
        AlsoSaveImputedDataCheckBox     matlab.ui.control.CheckBox
        suffixLabel_2                   matlab.ui.control.Label
        SaveCleanButton                 matlab.ui.control.Button
        SaveCleanPrefixEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)

        % Setup and import
        CurrentFolder   % 
        SpreadsheetApp  % popup app to view (edit) Data & Peak tables
        PopOutPlotApp   % popout scatter plot for PCA stats
        ImportApp       % popup app to import Peak/Data tables 
        configExplorerApp
	    peakExplorerApp
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset. 
        jsonView        % formatted json file for printing to screen.
        configFilename  % config filename


        DataTable       % Data Table - Public so it can be edited by app1
        PeakTable       % Peak Table - primarliy to access P.UID
        ExcelName       % Full Path of Excel File.
        ProjectName     % Excel Filename or 'CSV'
        DataName        % Excel Sheet or CSV name
        PeakName        % Excel Sheet or CSV name
        FileType        % csv or xls
        ImportSuccess
        baseConfig      % Data Structure containing all the operable configuration options
        preProcKeep     % Peaks to keep after preQCRSC clean 
        DataX           % Corrected Data Table
        PeakX           % Corrected Peak Table + Stats
        GammaVals
        Outliers
        NumOutliers
        PeakXBefore     % Uncorrected Peak Table + Batch Stats
        PeakXAfter      % % Corrected Peak Table + Batch Stats
        

        paused          % is QCRSC auto paused?
        aborted         % is QCRSC auto aborted?
        complete        % is QCRSC complete?
        bypass          % is QCRSC bypassed?
        
        PlotPeakTable   % Table for Plotting (remade every dial change)
        PlotDataTable   % Table for Plotting (remade every dial change)
        cmap            % colormap for plots
        cmapName
        

        scatterX        % X data for popout scatterplot (2 cols)
        scatterY        % y axis data for popout scatterplot (1 col)

        PCAmodel
        imputationType
        anyNAN
        noQCflag

    end
    
    properties (Access = private)
  
    end
    
    
    methods (Access = private)  
        
        function ResetData(app)
                app.ViewPeakTableButton.Enable = 'on';
                app.PreFilterPanel.Enable = 'on';                
                app.LaunchPeakExplorer.Enable = 'on';
                if strcmp(app.FileType,'csv')
                    app.FilenameLabel.Text = '';
                    app.DataSheetLabel.Text = ['DataFile: ',app.DataName,'.csv'];
                    app.DataSheetLabel.Tooltip = app.DataName;
                    app.PeakSheetLabel.Text = ['PeakFile: ',app.PeakName,'.csv'];
                    app.PeakSheetLabel.Tooltip = app.PeakName;
                else
                    app.FilenameLabel.Text = ['Filename: ',app.ProjectName];
                    app.DataSheetLabel.Text = ['DataSheet: ',app.DataName];
                    app.DataSheetLabel.Tooltip = app.DataName;
                    app.PeakSheetLabel.Text = ['PeakSheet: ',app.PeakName];
                    app.PeakSheetLabel.Tooltip = app.PeakName;
                end
                
                app.NumberofPeaksLabel.Text = ['Number of Peaks: ',num2str(height(app.PeakTable))];
                app.NumberofBatchesLabel.Text = ['Number of Batches: ',num2str(numel(unique(app.DataTable.Batch)))];
                app.NumberofQCLabel.Text = ['Number of QC: ',num2str(sum(app.DataTable.QC))];
                app.NumberofReferenceLabel.Text = ['Number of Reference: ',num2str(num2str(sum(app.DataTable.Reference)))];
                app.NumberofBlanksLabel.Text = ['Number of Blank: ',num2str(num2str(sum(app.DataTable.Blank)))];
                app.NumberofSamplesLabel.Text = ['Number of Samples: ',num2str(sum(ismember(app.DataTable.SampleType,'Sample')))];                
                
                app.permissiblemissingQCsEditField.Value = 20;
                app.BatchwiseCheckBox.Value = true;
                app.blankmissingCheckBox.Value = false;

                app.QCRSCenginePanel.Enable = 'on';
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                app.peaksCorrectedGauge.Value = 0; 
                app.paused = false;
                app.aborted = false;
                app.bypass = false;
                app.RunningLamp.Enable = 'off';
                app.CompleteLamp.Enable = 'off';
                
                batch = unique(app.DataTable.Batch);
                Batch = arrayfun(@(x) ['Batch',num2str(x)],batch,'UniformOutput',false);
                app.BatchDropDown.Items = ['All';Batch];
                app.BatchDropDown.ItemsData = [-1;batch];
       

                list = app.DataTable.Properties.VariableNames;
                listx = {};
                h = height(app.DataTable);
                for i = 1:length(list)
                    temp = length(unique(app.DataTable.(list{i})));
                    if temp ~= h && temp > 1, listx = [listx;list{i}]; end
                end
                rm = ismember(listx,{'QC','Sample','Blank','Reference'});
                listx(rm) = [];
                rm = ismember(listx,app.PeakTable.UID);
                listx(rm) = [];

                app.LabelbyDropDown.Items = ['BatchQC';listx];
                app.LabelbyDropDown.Value = 'SampleType';
                app.drinkMeButton.Value = false;

                plist = app.PeakTable.Properties.VariableNames;
                app.CustomLabelDropDownA1.Items = ['None',plist];
                app.CustomLabelDropDownA2.Items = ['None',plist];
                app.CustomLabelDropDownB1.Items = ['None',plist];
                app.CustomLabelDropDownB2.Items = ['None',plist];
                app.CustomLabelDropDownA1.Value = 'None';
                app.CustomLabelDropDownA2.Value = 'None';
                app.CustomLabelDropDownB1.Value = 'None';
                app.CustomLabelDropDownB2.Value = 'None';

                if sum(app.DataTable.Blank) == 0
                    app.blankmissingCheckBox.Value = false;
                    app.blankmissingCheckBox.Enable = 'off';
                else
                    app.blankmissingCheckBox.Enable = 'on';
                end
        end

        function autoImportConfig(app)    
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView; 
            if exist('userConfig.json', 'file') == 2
               res = uiconfirm(app.UIFigure,"Would you like to import the local 'userConfig.json' config file?",'Confirm Action','Options',{'Yes','No'});
               if strcmp(res,'No')
                    return;
               end 
               try
                    app.jsonTxt = fileread('userConfig.json');
                    app.baseConfig = jsondecode(app.jsonTxt);
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = 'userConfig.json';
               catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file. Reverting to default')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.jsonView = oldJSONview;
                    app.baseConfig = oldConfig;
               end
               app.configFileTextArea.Value = app.jsonView; 
            end
            app.ConfigurationPanel.Title = ['Configuration: ',app.configFilename];
            updateConfigFields(app);
        end
        
        function drinkMe(app)
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();
                if sum(app.DataTable.Blank==1) == 0
                    uialert(app.UIFigure,'There are no Blank samples in the data set','Warning','Icon','warning');
                    app.drinkMeButton.Value = false;
                    set(app.UIFigure, 'Pointer','arrow');
                    switchAllPanelsA(app,'on');
                    if app.complete
                        switchAllPanelsB(app,'on');
                    end
                    return
                end

                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                Xall = app.DataTable{:,app.PeakTable.UID};
                for i = 1:NumOfBatches
                    found = app.DataTable.Blank~=1 & app.DataTable.Batch == ubatch(i);
                    foundBlank = app.DataTable.Blank==1 & app.DataTable.Batch == ubatch(i);
                    XU = Xall(found,:);
                    BU = Xall(foundBlank,:);
                    XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOD);
                    Xall(found,:) = XU;
                end
                app.DataTable{:,app.PeakTable.UID} = Xall;
                nanAll = isnan(Xall(app.DataTable.Sample==1,:));
                remove = sum(nanAll) == height(nanAll);
                removeUIDs = app.PeakTable.UID(remove);
                app.PeakTable(remove,:) = [];
                app.DataTable(:,removeUIDs) = [];
                app.NumberofPeaksLabel.Text = ['Number of Peaks: ',num2str(height(app.PeakTable))];
     
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an efect','Warning','Icon','warning');
                end
                drawnow();
        end

        function checkInterBatchValid(app)
            if app.noQCflag 
                 app.baseConfig.IntraBatchMode = 'Sample';
                app.baseConfig.InterBatchMode = 'Sample';
                app.baseConfig.BlankRatioMethod = 'Median';
                updateConfigTextArea(app,'IntraBatchMode','Sample');
                updateConfigTextArea(app,'InterBatchMode','Sample');
                updateConfigTextArea(app,'BlankRatioMethod','Median');
                baseException = MException('QCRSC:WrongInterBatchMode','There are <3 QCs per batch so switching to "Sample" correction. IntraBatchMode, InterBatchMode & BlankRatioMethod changed. Please review & replay!');
                throw(baseException)
            end

            if strcmp(app.baseConfig.IntraBatchMode,'Sample')
                if ~strcmp(app.baseConfig.InterBatchMode,'Sample')
                    %uialert(app.UIFigure,{'When using Sample-based intra-batch correction the inter-batch correction must be also be set to "Sample" (and blankRatio set to "Median").';'InterBatchMode & BlankRatioMethod changed.'},'Warning','Icon','warning');
                    app.baseConfig.InterBatchMode = 'Sample';
                    app.baseConfig.BlankRatioMethod = 'Median';
                    updateConfigTextArea(app,'InterBatchMode','Sample');
                    updateConfigTextArea(app,'BlankRatioMethod','Median');
                    baseException = MException('QCRSC:WrongInterBatchMode','When using Sample-based intra-batch correction the inter-batch correction must be also be set to "Sample" (and blankRatio set to "Median"). InterBatchMode & BlankRatioMethod changed. Please review & replay!');
                    throw(baseException)
                end
            end
            if strcmp(app.baseConfig.InterBatchMode,'Sample')
                if ~strcmp(app.baseConfig.IntraBatchMode,'Sample')
                    %uialert(app.UIFigure,{'When when using QC intra-batch correction the inter-batch correction cannot be set to "Sample".';'InterBatchMode changed to "QC".'},'Warning','Icon','warning');
                    app.baseConfig.InterBatchMode = 'QC';
                    updateConfigTextArea(app,'InterBatchMode','QC');
                    baseException = MException('QCRSC:WrongInterBatchMode','When when using QC intra-batch correction the inter-batch correction cannot be set to "Sample". InterBatchMode changed to "QC". Please review & replay!');
                    throw(baseException)
                end
            end
            if strcmp(app.baseConfig.InterBatchMode,'Reference')
                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                for i = 1:NumOfBatches
                    batchRef = app.DataTable.Reference(app.DataTable.Batch == ubatch(i));
                    batchRefsum(i) = sum(batchRef);
                end
                if any(batchRefsum == 0)
                    %uialert(app.UIFigure,{'There must be at least one Reference sample per batch for Reference-based inter-batch correction.';'InterBatchMode changed to "QC"'},'Warning','Icon','warning');
                    app.baseConfig.InterBatchMode = 'QC';
                    updateConfigTextArea(app,'InterBatchMode','QC');
                    baseException = MException('QCRSC:WrongInterBatchMode','There must be at least one Reference sample per batch for Reference-based inter-batch correction. InterBatchMode changed to "QC". Please review & replay!');
                    throw(baseException)
                else
                    app.BatchwiseSwitch.Value = true;
                    app.BatchwiseSwitch.Enable = "off";
                    app.BatchWiseDropDown.Enable = "on";
                end
            end
        end

        
        function preQCRSCclean(app)
            cla(app.UIAxesPreprocessing,'reset');

            if app.noQCflag
                keep = true(height(app.PeakTable),1);
                app.preProcKeep = keep;
                found = sum(keep);
                app.PeaksRemainingLabel.Text = ['Peaks Remaining: ',num2str(found)];
                return;
            end
           
            DataQC = app.DataTable(app.DataTable.QC==1,:);
            Xqc = DataQC{:,app.PeakTable.UID};          
            if app.baseConfig.RemoveZeros
                Xqc(Xqc==0) = nan;                
            end
            if app.blankmissingCheckBox.Value
                DataBlank = app.DataTable(app.DataTable.Blank==1,:);
                Xblank = DataBlank{:,app.PeakTable.UID};
                if app.baseConfig.RemoveZeros                
                    Xblank(Xblank==0) = nan;
                end
            end
            ubatch = unique(DataQC.Batch);         
            NumOfBatches = numel(ubatch);
            pmissing = zeros(height(app.PeakTable),NumOfBatches);
            numMissing = zeros(height(app.PeakTable),NumOfBatches);
            for i = 1:NumOfBatches
                XU = Xqc(DataQC.Batch == ubatch(i),:);
                if app.blankmissingCheckBox.Value
                    BU = Xblank(DataBlank.Batch == ubatch(i),:);
                    XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOD);
                end
                pmissing(:,i) = (100*sum(isnan(XU))./height(XU))';
                numMissing(:,i) = sum(isnan(XU));
            end

            total_pmissing = 100*sum(numMissing,2)./height(DataQC);
            
            if all(pmissing == 0)
                text(app.UIAxesPreprocessing,0.5,0.5,'No missing QCs',"HorizontalAlignment","center");                
            else
                [n(1,:),edges] = histcounts(pmissing(:,1));
                if length(edges) > 11
                    clear n;
                    [n(1,:),edges] = histcounts(pmissing(:,1),10);
                end
                if NumOfBatches > 1
                    for i = 2:NumOfBatches
                        [n(i,:)] = histcounts(pmissing(:,i),edges);
                    end
                end
                center = edges(1:end-1) + diff(edges) / 2;
                pp = bar(app.UIAxesPreprocessing,center,n);
                app.UIAxesPreprocessing.XTick = edges;
                app.UIAxesPreprocessing.XTickLabel = round(edges);
                app.UIAxesPreprocessing.XLim = [min(edges),max(edges)];
                
                tmp = string(round(10*edges)/10);
                temp = cellfun(@(x,y) strcat('(',x,'>x>',y,')'),tmp(1:end-1),tmp(2:end),'UniformOutput',false);   
                
                for i = 1:NumOfBatches            
                    found = edges(1:end-1) >= app.permissiblemissingQCsEditField.Value;
                    pp(i).FaceColor = 'flat';
                    pp(i).CData(found,:) = repmat([1,1,1],sum(found),1);

                    blabel = ones(length(center),1)*ubatch(i);
                    pp(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('% missing',temp);
                    pp(i).DataTipTemplate.DataTipRows(2).Label = '#Peaks';
                    pp(i).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Batch#',blabel);
                end

            end
            
            if app.BatchwiseCheckBox.Value
                keep = sum(pmissing <= app.permissiblemissingQCsEditField.Value,2) == width(pmissing);
                
            else
                %keep = sum(pmissing <= app.permissiblemissingQCsEditField.Value,2) > 0;
                keep = total_pmissing <= app.permissiblemissingQCsEditField.Value;
            end
            
            found = sum(keep);
            app.PeaksRemainingLabel.Text = ['Peaks Remaining: ',num2str(found)];
            
            app.preProcKeep = keep;

            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)');

        end
              
        function optimiseAndCorrectTable(app)
            app.QCRSCenginePanel.Title = sprintf('QCRSC engine:');            
            app.PeakX = app.PeakTable(app.preProcKeep,:);
            app.DataX = app.DataTable;
            list = setdiff(app.PeakTable.UID,app.PeakX.UID);
            app.DataX(:,list) = [];

            if ~app.bypass
                      
                X = app.DataX{:,app.PeakX.UID};
                t = app.DataX.Order;
                Batch = app.DataX.Batch;
                isQC = logical(app.DataX.QC);
                isSample = logical(app.DataX.Sample);
                isREF = logical(app.DataX.Reference);
                isBlank = logical(app.DataX.Blank);
                
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfPeaks = width(X);
                NumOfObjects = height(X);
                
                if app.baseConfig.RemoveZeros
                    X(X == 0) = NaN;
                end
                
                if app.baseConfig.LogTransform
                    X(X == 0) = NaN;
                    X = log10(X);
                end
                
                Z = nan(size(X));
                gammaVals = nan(NumOfPeaks,NumOfBatches);
                outliers = zeros(NumOfObjects,NumOfPeaks);               
                numOutliers = nan(NumOfPeaks,1);
                
                set(app.UIFigure, 'Pointer','watch')
                drawnow();
    
                if app.baseConfig.ParallelProcess
                    app.PauseButton.Enable = 'off';
                    app.AbortButton.Enable = 'off';
                    if isempty(gcp('nocreate'))
                        d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                        d.Message = "Starting parallel pool (parpool) using the 'local' profile ...";
                        d.Value = 0.5;
                        [~,pp] = evalc('gcp');
                        d.Message = sprintf('Connected to the parallel pool (number of workers: %u).',pp.NumWorkers);                       
                        d.Value = 1;
                        pause(2);
                        close(d);
                    end
                                    
                    
                    config = app.baseConfig;  
                    
                    
                    try
                        tnum = pp.NumWorkers*10;
                        parfor i =1:tnum
                            OptimiseAndCorrectPeakX(config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        tic;
                        parfor i =1:tnum
                            OptimiseAndCorrectPeakX(config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfPeaks/tnum;                    
                    catch
                        expectedTime = -1;
                    end
                    if expectedTime == -1
                        RunningTimeText = 'Estimated Running time is unknown';                                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated Running time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated Running time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated Running time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCRSCenginePanel.Title = ['QCRSC parallel engine: ',RunningTimeText];
                    

                    tic;
                    F(1:NumOfPeaks) = parallel.FevalFuture;
                    for i = 1:NumOfPeaks
                        F(i) = parfeval(@OptimiseAndCorrectPeakX,4,config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);                    
                    end
                    
                    set(app.UIFigure, 'Pointer','arrow');
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    app.AbortButton.Enable = 'on';                
                    NumCompleted = 0;
                    drawnow();
    
                    while NumCompleted < NumOfPeaks
                        NumCompleted = sum({F.State}=="finished");
                        percDone = 100*NumCompleted/NumOfPeaks;
                        app.peaksCorrectedGauge.Value = percDone;
                        app.RunningLamp.Enable = 'off';
                        pause(0.1);
                        app.RunningLamp.Enable = 'on';
                        if app.aborted
                            app.AbortButton.Enable = 'off';
                            set(app.UIFigure, 'Pointer','watch')
                            drawnow();
                            cancel(F);
                            app.peaksCorrectedGauge.Value = 0;
                            delete(gcp('nocreate'));                
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCRSCenginePanel.Title = 'QCRSC engine: ';
                            set(app.UIFigure, 'Pointer','arrow');
                            return
                        end
                        pause(1);
                    end
    
                    for i = 1:NumOfPeaks
                        Z(:,i) = F(i).OutputArguments{1};
                        gammaVals(i,:) = F(i).OutputArguments{3};
                        outliers(:,i) = ismember(t,F(i).OutputArguments{4});
                        numOutliers(i,:) = length(F(i).OutputArguments{4});                        
                    end

                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                     
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCRSCenginePanel.Title = ['QCRSC parallel engine: ',TimeText];

                    d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                    d.Message = "Stopping parallel processor ...";
                    d.Value = 0.5;
                    message = evalc("delete(gcp('nocreate'))");                  
                    d.Message = message;
                    d.Value = 1;
                    pause(2);
                    close(d);
         
                else

                    
                    try
                        tic;
                        for i =1:10
                            OptimiseAndCorrectPeakX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfPeaks/10;
                    catch
                        expectedTime = -1;
                    end
    
                    if expectedTime == -1
                        RunningTimeText = 'Estimated execution time is unknown';                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated execution time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated execution time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated execution time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCRSCenginePanel.Title = ['QCRSC engine: ',RunningTimeText];


                    set(app.UIFigure, 'Pointer','arrow')
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    
                    lampflag = true;
                
                    tic;    
                    for i = 1:NumOfPeaks
                        lampflag = not(lampflag);
                        if lampflag
                            app.RunningLamp.Enable = 'on';
                        else
                            app.RunningLamp.Enable = 'off';
                        end
                        while app.paused
                            app.RunningLamp.Enable = 'off';
                            pause(0.5);
                            app.PauseButton.BackgroundColor = [0.80,0.80,0.80];
                            pause(0.5); 
                            app.PauseButton.BackgroundColor = [1.00,1.00,0.07];                    
                        end
                         if app.aborted
                            app.AbortButton.Enable = 'off';
                            app.peaksCorrectedGauge.Value = 0;
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCRSCenginePanel.Title = 'QCRSC engine: ';
                            return
                         end                       
                        [Z(:,i),~,gammaVals(i,:),oo] = OptimiseAndCorrectPeakX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        outliers(:,i) = ismember(t,oo);
                        numOutliers(i,:) = length(oo);
                        percDone = 100*i/NumOfPeaks;
                        app.peaksCorrectedGauge.Value = percDone;
                                                        
                    end
                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                    
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCRSCenginePanel.Title = ['QCRSC engine: ',TimeText];
                end
            else
                Z = app.DataX{:,app.PeakX.UID};
                if app.baseConfig.LogTransform
                    Z = log10(Z);
                end
                Batch = app.DataX.Batch;
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfPeaks = width(Z);
                NumOfObjects = height(Z);
                gammaVals = nan(NumOfPeaks,NumOfBatches);               
                outliers = zeros(NumOfObjects,NumOfPeaks);
                numOutliers = nan(NumOfPeaks,1);
            end
            app.GammaVals = gammaVals;          
            app.Outliers = outliers;
            app.NumOutliers = numOutliers;
            if app.baseConfig.LogTransform
                Z = power(10,Z);
            end            
            app.DataX{:,app.PeakX.UID} = Z;
            app.CompleteLamp.Enable = 'on';
            app.RunningLamp.Enable = 'off';
            calculateStats(app);     
        end

        function calculateStats(app)

            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();

            Batch = app.DataX.Batch;
            ubatch = unique(Batch);
            NumOfBatches = numel(ubatch);
            NumOfPeaks = height(app.PeakX);

            X = app.DataTable{:,app.PeakX.UID};
            X(logical(app.Outliers)) = NaN;
            DataXX = app.DataTable;
            DataXX{:,app.PeakX.UID} = X;

            gammaVars = cellfun(@(x) strcat('gammaB',x),string(ubatch'),'UniformOutput',false);            
            gammaTable = array2table([app.GammaVals,app.NumOutliers],'variablenames',[gammaVars,'NumOfQCoutliers']);
            gammaTable0 = array2table(nan(size(app.GammaVals,1),size(app.GammaVals,2)+1),'variablenames',[gammaVars,'NumOfQCoutliers']);

            BeforeDRatioVals = nan(NumOfPeaks,NumOfBatches);
            BeforeQCRSDVals = nan(NumOfPeaks,NumOfBatches);
            BeforeblankRatioVals = nan(NumOfPeaks,NumOfBatches);
            AfterDRatioVals = nan(NumOfPeaks,NumOfBatches);
            AfterQCRSDVals = nan(NumOfPeaks,NumOfBatches);
            AfterblankRatioVals = nan(NumOfPeaks,NumOfBatches);

            for i = 1:NumOfBatches
                BatchStatsBefore(i).Tbl = BatchCalcStatsTable(DataXX,app.PeakX,ubatch(i),app.baseConfig);
                BeforeQCRSDVals(:,i) = BatchStatsBefore(i).Tbl.qcRSD;            
                BeforeSampleRSDVals(:,i) = BatchStatsBefore(i).Tbl.sampleRSD;
                BeforeRefRSDVals(:,i) = BatchStatsBefore(i).Tbl.refRSD;
                BeforeDRatioVals(:,i) = BatchStatsBefore(i).Tbl.dRatio;
                BeforeblankRatioVals(:,i) = BatchStatsBefore(i).Tbl.blankRatio;
            end            

            for i = 1:NumOfBatches
                BatchStatsAfter(i).Tbl = BatchCalcStatsTable(app.DataX,app.PeakX,ubatch(i),app.baseConfig);
                AfterQCRSDVals(:,i) = BatchStatsAfter(i).Tbl.qcRSD;
                AfterSampleRSDVals(:,i) = BatchStatsAfter(i).Tbl.sampleRSD;
                AfterRefRSDVals(:,i) = BatchStatsAfter(i).Tbl.refRSD;
                AfterDRatioVals(:,i) = BatchStatsAfter(i).Tbl.dRatio;
                AfterblankRatioVals(:,i) = BatchStatsAfter(i).Tbl.blankRatio;
            end 

            statsBefore = BatchCalcStatsTable(DataXX,app.PeakX,-1,app.baseConfig);
            statsAfter = BatchCalcStatsTable(app.DataX,app.PeakX,-1,app.baseConfig);


            deltaStats.DELTAqcRSD = statsAfter.qcRSD - statsBefore.qcRSD;
            deltaStats.DELTArefRSD = statsAfter.refRSD - statsBefore.refRSD;
            deltaStats.DELTAdRatio = statsAfter.dRatio - statsBefore.dRatio;

            deltaStatsTable = struct2table(deltaStats);

            DeltaQCRSDVals = AfterQCRSDVals - BeforeQCRSDVals;
            DeltaRefRSDVals = AfterRefRSDVals - BeforeRefRSDVals;
            DeltaDRatioVals = AfterDRatioVals - BeforeDRatioVals;

            % delta Tables

            deltaQCRSDVars = cellfun(@(x) strcat('DELTAqcRSDB',x),string(ubatch'),'UniformOutput',false);
            DeltaQCRSDTable = array2table(DeltaQCRSDVals,'variablenames',deltaQCRSDVars);
            DeltaQCRSDTable.DELTAqcRSDMax = max(DeltaQCRSDVals,[],2);
            DeltaQCRSDTable.DELTAqcRSDMin = min(DeltaQCRSDVals,[],2);
            DeltaQCRSDTable.DELTAqcRSDMean = mean(DeltaQCRSDVals,2,"omitmissing");
            DeltaQCRSDTable.DELTAqcRSDMedian = median(DeltaQCRSDVals,2,"omitmissing");
            DeltaQCRSDTable.DELTAqcRSD = deltaStats.DELTAqcRSD;

            deltaRefRSDVars = cellfun(@(x) strcat('DELTArefRSDB',x),string(ubatch'),'UniformOutput',false);
            DeltaRefRSDTable = array2table(DeltaRefRSDVals,'variablenames',deltaRefRSDVars);
            DeltaRefRSDTable.DELTArefRSDMax = max(DeltaRefRSDVals,[],2);
            DeltaRefRSDTable.DELTArefRSDMin = min(DeltaRefRSDVals,[],2);
            DeltaRefRSDTable.DELTArefRSDMean = mean(DeltaRefRSDVals,2,"omitmissing");
            DeltaRefRSDTable.DELTArefRSDMedian = median(DeltaRefRSDVals,2,"omitmissing");
            DeltaRefRSDTable.DELTArefRSD = deltaStats.DELTArefRSD;

            deltaDRatioVars = cellfun(@(x) strcat('DELTAdRatioB',x),string(ubatch'),'UniformOutput',false);
            DeltaDRatioTable = array2table(DeltaDRatioVals,'variablenames',deltaDRatioVars);
            DeltaDRatioTable.DELTAdRatioMax = max(DeltaDRatioVals,[],2);
            DeltaDRatioTable.DELTAdRatioMin = min(DeltaDRatioVals,[],2);
            DeltaDRatioTable.DELTAdRatioMean = mean(DeltaDRatioVals,2,"omitmissing");
            DeltaDRatioTable.DELTAdRatioMedian = median(DeltaDRatioVals,2,"omitmissing");
            DeltaDRatioTable.DELTAdRatio = deltaStats.DELTAdRatio;

            % QCRSD tables

            QCRSDVars = cellfun(@(x) strcat('qcRSDB',x),string(ubatch'),'UniformOutput',false);
            QCRSDTableBefore = array2table(BeforeQCRSDVals,'variablenames',QCRSDVars);
            QCRSDTableBefore.qcRSDMax = max(BeforeQCRSDVals,[],2);
            QCRSDTableBefore.qcRSDMin = min(BeforeQCRSDVals,[],2);
            QCRSDTableBefore.qcRSDMean = mean(BeforeQCRSDVals,2,"omitmissing");
            QCRSDTableBefore.qcRSDMedian = median(BeforeQCRSDVals,2,"omitmissing");
            QCRSDTableBefore.qcRSD = statsBefore.qcRSD;

            QCRSDTable = array2table(AfterQCRSDVals,'variablenames',QCRSDVars);
            QCRSDTable.qcRSDMax = max(AfterQCRSDVals,[],2);
            QCRSDTable.qcRSDMin = min(AfterQCRSDVals,[],2);
            QCRSDTable.qcRSDMean = mean(AfterQCRSDVals,2,"omitmissing");
            QCRSDTable.qcRSDMedian = median(AfterQCRSDVals,2,"omitmissing");
            QCRSDTable.qcRSD = statsAfter.qcRSD;
            QCRSDTable.qcRSDlower95CI = statsAfter.qcRSDlower95CI;
            QCRSDTable.qcRSDupper95CI = statsAfter.qcRSDlower95CI;

            QCRSDTableX = array2table(AfterQCRSDVals,'variablenames',QCRSDVars);
            QCRSDTableX.qcRSD = statsAfter.qcRSD;
            QCRSDTableX.qcRSDlower95CI = statsAfter.qcRSDlower95CI;
            QCRSDTableX.qcRSDupper95CI = statsAfter.qcRSDlower95CI;

            % sampleRSD tables

            sampleRSDVars = cellfun(@(x) strcat('sampleRSDB',x),string(ubatch'),'UniformOutput',false);
            sampleRSDTableBefore = array2table(BeforeSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDTableBefore.sampleRSDMax = max(BeforeSampleRSDVals,[],2);
            sampleRSDTableBefore.sampleRSDMin = min(BeforeSampleRSDVals,[],2);
            sampleRSDTableBefore.sampleRSDMean = mean(BeforeSampleRSDVals,2,"omitmissing");
            sampleRSDTableBefore.sampleRSDMedian = median(BeforeSampleRSDVals,2,"omitmissing");
            sampleRSDTableBefore.sampleRSD = statsBefore.sampleRSD;

            sampleRSDTable = array2table(AfterSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDTable.sampleRSDMax = max(AfterSampleRSDVals,[],2);
            sampleRSDTable.sampleRSDMin = min(AfterSampleRSDVals,[],2);
            sampleRSDTable.sampleRSDMean = mean(AfterSampleRSDVals,2,"omitmissing");
            sampleRSDTable.sampleRSDMedian = median(AfterSampleRSDVals,2,"omitmissing");
            sampleRSDTable.sampleRSD = statsAfter.sampleRSD;
            sampleRSDTable.sampleRSDlower95CI = statsAfter.sampleRSDlower95CI;
            sampleRSDTable.sampleRSDupper95CI = statsAfter.sampleRSDupper95CI;

            sampleRSDTableX = array2table(AfterSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDTableX.sampleRSD = statsAfter.sampleRSD;
            sampleRSDTableX.sampleRSDlower95CI = statsAfter.sampleRSDlower95CI;
            sampleRSDTableX.sampleRSDupper95CI = statsAfter.sampleRSDupper95CI;

            % refRSD tables

            refRSDVars = cellfun(@(x) strcat('refRSDB',x),string(ubatch'),'UniformOutput',false);
            refRSDTableBefore = array2table(BeforeRefRSDVals,'variablenames',refRSDVars);
            refRSDTableBefore.refRSDMax = max(BeforeRefRSDVals,[],2);
            refRSDTableBefore.refRSDMin = min(BeforeRefRSDVals,[],2);
            refRSDTableBefore.refRSDMean = mean(BeforeRefRSDVals,2,"omitmissing");
            refRSDTableBefore.refRSDMedian = median(BeforeRefRSDVals,2,"omitmissing");
            refRSDTableBefore.refRSD = statsBefore.refRSD;

            refRSDTable = array2table(AfterRefRSDVals,'variablenames',refRSDVars);
            refRSDTable.refRSDMax = max(AfterRefRSDVals,[],2);
            refRSDTable.refRSDMin = min(AfterRefRSDVals,[],2);
            refRSDTable.refRSDMean = mean(AfterRefRSDVals,2,"omitmissing");
            refRSDTable.refRSDMedian = median(AfterRefRSDVals,2,"omitmissing");
            refRSDTable.refRSD = statsAfter.refRSD;
            refRSDTable.refRSDlower95CI = statsAfter.refRSDlower95CI;
            refRSDTable.refRSDupper95CI = statsAfter.refRSDupper95CI;

            refRSDTableX = array2table(AfterRefRSDVals,'variablenames',refRSDVars);
            refRSDTableX.refRSD = statsAfter.refRSD;
            refRSDTableX.refRSDlower95CI = statsAfter.refRSDlower95CI;
            refRSDTableX.refRSDupper95CI = statsAfter.refRSDupper95CI;

            % Dratio tables

            DratioVars = cellfun(@(x) strcat('dRatioB',x),string(ubatch'),'UniformOutput',false);
            DratioTableBefore = array2table(BeforeDRatioVals,'variablenames',DratioVars);
            DratioTableBefore.dRatioMax = max(BeforeDRatioVals,[],2);
            DratioTableBefore.dRatioMin = min(BeforeDRatioVals,[],2);
            DratioTableBefore.dRatioMean = mean(BeforeDRatioVals,2,"omitmissing");
            DratioTableBefore.dRatioMedian = median(BeforeDRatioVals,2,"omitmissing");
            DratioTableBefore.dRatio = statsBefore.dRatio;

            DratioTable = array2table(AfterDRatioVals,'variablenames',DratioVars);
            DratioTable.dRatioMax = max(AfterDRatioVals,[],2);
            DratioTable.dRatioMin = min(AfterDRatioVals,[],2);
            DratioTable.dRatioMean = mean(AfterDRatioVals,2,"omitmissing");
            DratioTable.dRatioMedian = median(AfterDRatioVals,2,"omitmissing");
            DratioTable.dRatio = statsAfter.dRatio;

            DratioTableX = array2table(AfterDRatioVals,'variablenames',DratioVars);
            DratioTableX.dRatio = statsAfter.dRatio;
            % blankRatio tables
            % force "complete" blankRatio to be the mean of the batch blankRatio

            blankRatioVars = cellfun(@(x) strcat('blankRatioB',x),string(ubatch'),'UniformOutput',false);
            blankRatioTableBefore = array2table(BeforeblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTableBefore.blankRatioMax = max(BeforeblankRatioVals,[],2);
            blankRatioTableBefore.blankRatioMin = min(BeforeblankRatioVals,[],2);
            blankRatioTableBefore.blankRatioMean = mean(BeforeblankRatioVals,2,"omitmissing");
            blankRatioTableBefore.blankRatioMedian = median(BeforeblankRatioVals,2,"omitmissing");
            %blankRatioTableBefore.blankRatio = statsBefore.blankRatio;
            blankRatioTableBefore.blankRatio = mean(BeforeblankRatioVals,2,"omitmissing");

            blankRatioTable = array2table(AfterblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTable.blankRatioMax =  max(AfterblankRatioVals,[],2);
            blankRatioTable.blankRatioMin =  min(AfterblankRatioVals,[],2);
            blankRatioTable.blankRatioMean =  mean(AfterblankRatioVals,2,"omitmissing");
            blankRatioTable.blankRatioMedian =  median(AfterblankRatioVals,2,"omitmissing");
            %blankRatioTable.blankRatio = statsAfter.blankRatio;
            blankRatioTable.blankRatio = mean(AfterblankRatioVals,2,"omitmissing");

            blankRatioTableX = array2table(AfterblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTableX.blankRatio = mean(AfterblankRatioVals,2,"omitmissing");

            
            app.PeakX(:,ismember(app.PeakX.Properties.VariableNames,gammaTable.Properties.VariableNames)) = [];
            app.PeakX(:,ismember(app.PeakX.Properties.VariableNames,QCRSDTable.Properties.VariableNames)) = [];
            app.PeakX(:,ismember(app.PeakX.Properties.VariableNames,DratioTable.Properties.VariableNames)) = [];
            app.PeakX(:,ismember(app.PeakX.Properties.VariableNames,refRSDTable.Properties.VariableNames)) = [];
            app.PeakX(:,ismember(app.PeakX.Properties.VariableNames,blankRatioTable.Properties.VariableNames)) = [];
                        
         
            app.PeakXBefore = [app.PeakX,gammaTable0,QCRSDTableBefore,sampleRSDTableBefore,refRSDTableBefore,DratioTableBefore,blankRatioTableBefore,DeltaQCRSDTable,DeltaRefRSDTable,DeltaDRatioTable];
            app.PeakXAfter = [app.PeakX,gammaTable,QCRSDTable,sampleRSDTable,refRSDTable,DratioTable,blankRatioTable,DeltaQCRSDTable,DeltaRefRSDTable,DeltaDRatioTable];
            app.PeakX = [app.PeakX,gammaTable,QCRSDTableX,sampleRSDTableX,refRSDTableX,DratioTableX,blankRatioTableX];
            
            set(app.UIFigure, 'Pointer','arrow');
            app.complete = true;
        
            app.RunButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.BypassButton.Enable = 'on';
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();          
        end
             
        function plotImperialRSD(app)
            
            cla(app.UIAxesImperialRSD,'reset');
            
            bin = (1:height(app.PlotPeakTable))';
            if ismac
                ms = 9;
            else
                ms = 7;
            end
            grey = [0.7,0.7,0.7];

            if any(app.PlotDataTable.Reference)
                app.RefCheckBox.Enable = 'on';
            else
                app.RefCheckBox.Enable = 'off';
            end


            if strcmp(app.SortbySwitch.Value,'sample')
                [ss,index] = sortrows(app.PlotPeakTable.sampleRSD);
                qq = app.PlotPeakTable.qcRSD(index);
            else
                [qq,index] = sortrows(app.PlotPeakTable.qcRSD);
                ss = app.PlotPeakTable.sampleRSD(index);
            end
            dd = app.PlotPeakTable.dRatio(index);
            rr = app.PlotPeakTable.refRSD(index);
            bb = app.PlotPeakTable.blankRatio(index);
            id = app.PlotPeakTable.UID(index);
            
            customLabel = app.CustomLabelDropDownA1.Value;
            if strcmp(customLabel,'None')
                customflag = false;
            else
                customflag = true;
                cc = app.PlotPeakTable.(customLabel);
            end

            customLabel2 = app.CustomLabelDropDownA2.Value;
            if strcmp(customLabel2,'None') || customflag == false
                customflag2 = false;
                app.CustomLabelDropDownA2.Value = 'None';
            else
                customflag2 = true;
                cc2 = app.PlotPeakTable.(customLabel2);
            end

            in = app.PlotPeakTable.cleanPeaks(index);
            
            ll = [];
            lt = {};
            
           
            if any(~in)
                
                if strcmp(app.DeltaSwitch.Value,'overlay')

                    h2outA = semilogx(app.UIAxesImperialRSD,ss(~in),bin(~in),'.');
                    h2outA.MarkerSize = ms;
                    h2outA.MarkerFaceColor = grey;
                    h2outA.MarkerEdgeColor = grey;
                    h2outA.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(~in));
                    h2outA.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(~in),'%.2f');
                    if customflag, h2outA.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(~in)); end
                    if customflag2, h2outA.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(~in)); end
                    hold(app.UIAxesImperialRSD,"on");

                    h2outB = semilogx(app.UIAxesImperialRSD,qq(~in),bin(~in),'.');
                    h2outB.MarkerSize = ms;
                    h2outB.MarkerFaceColor = grey;
                    h2outB.MarkerEdgeColor = grey;
                    h2outB.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(~in));
                    h2outB.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(~in),'%.2f');
                    if customflag, h2outB.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(~in)); end
                    if customflag2, h2outB.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(~in)); end
                    if app.RefCheckBox.Value  
                        h2outC = semilogx(app.UIAxesImperialRSD,rr(~in),bin(~in),'.');                    
                        h2outC.MarkerSize = ms;
                        h2outC.MarkerFaceColor = grey;
                        h2outC.MarkerEdgeColor = grey;
                        h2outC.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(~in));
                        h2outC.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(~in));
                        h2outC.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(~in));
                        h2outC.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(~in));
                        h2outC.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(~in));
                        h2outC.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(~in),'%.2f');
                        if customflag, h2outC.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(~in)); end
                        if customflag2, h2outC.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(~in)); end
                    end
                else
                    h2outA = plot(app.UIAxesImperialRSD,ss(~in),bin(~in),'.');
                    h2outA.MarkerSize = ms;
                    h2outA.MarkerFaceColor = grey;
                    h2outA.MarkerEdgeColor = grey;
                    h2outA.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(~in));
                    h2outA.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(~in),'%.2f');
                    h2outA.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(~in),'%.2f');
                    if customflag, h2outA.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(~in)); end
                    if customflag2, h2outA.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(~in)); end
                    hold(app.UIAxesImperialRSD,"on");

                    delta = qq(~in) - rr(~in);
                    h2outB = semilogx(app.UIAxesImperialRSD,delta,bin(~in),'.');
                    h2outB.MarkerSize = ms;
                    h2outB.MarkerFaceColor = grey;
                    h2outB.MarkerEdgeColor = grey;
                    h2outB.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(~in));
                    h2outB.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(~in),'%.2f');
                    h2outB.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(~in),'%.2f');
                    if customflag, h2outB.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(~in)); end
                    if customflag2, h2outB.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(~in)); end
                end
            end 
            
            if any(in)
                
                
                if strcmp(app.DeltaSwitch.Value,'overlay')
                    h2inA = semilogx(app.UIAxesImperialRSD,ss(in),bin(in),'.');
                    h2inA.MarkerSize = ms;
                    h2inA.MarkerFaceColor = 'b';
                    h2inA.MarkerEdgeColor = 'b';
                    h2inA.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(in));
                    h2inA.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',ss(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',rr(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(in),'%.2f');
                    if customflag, h2inA.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(in)); end
                    if customflag2, h2inA.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(in)); end
                    hold(app.UIAxesImperialRSD,"on");

                    h2inB = semilogx(app.UIAxesImperialRSD,qq(in),bin(in),'.');
                    h2inB.MarkerSize = ms;
                    h2inB.MarkerFaceColor = 'r';
                    h2inB.MarkerEdgeColor = 'r';
                    h2inB.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(in));
                    h2inB.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',ss(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',rr(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(in),'%.2f');
                    if customflag, h2inB.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(in)); end
                    if customflag2, h2inB.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(in)); end
    
                    ll = [ll,h2inA,h2inB];
                    lt = [lt,{'Sample','QC'}];
                    
                    if app.RefCheckBox.Value  
                        h2inC = semilogx(app.UIAxesImperialRSD,rr(in),bin(in),'.');                    
                        h2inC.MarkerSize = ms;
                        h2inC.MarkerFaceColor = 'c';
                        h2inC.MarkerEdgeColor = 'c';
                        h2inC.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(in));
                        h2inC.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(in));
                        h2inC.DataTipTemplate.DataTipRows(3) = dataTipTextRow('refRSD:',rr(in));
                        h2inC.DataTipTemplate.DataTipRows(4) = dataTipTextRow('sampleRSD:',ss(in));
                        h2inC.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(in));
                        h2inC.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(in),'%.2f');
                        if customflag, h2inC.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(in)); end
                        if customflag2, h2inC.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(in)); end
    
                        ll = [ll,h2inC];
                        lt = [lt,{'Reference'}];
                    end
                else
                    h2inA = plot(app.UIAxesImperialRSD,ss(in),bin(in),'.');
                    h2inA.MarkerSize = ms;
                    h2inA.MarkerFaceColor = 'b';
                    h2inA.MarkerEdgeColor = 'b';
                    h2inA.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(in));
                    h2inA.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',ss(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',rr(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(in),'%.2f');
                    h2inA.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(in),'%.2f');
                    if customflag, h2inA.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(in)); end
                    if customflag2, h2inA.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(in)); end
                    hold(app.UIAxesImperialRSD,"on");

                    delta = qq(in) - rr(in);
                    h2inB = plot(app.UIAxesImperialRSD,delta,bin(in),'.');
                    h2inB.MarkerSize = ms;
                    h2inB.MarkerFaceColor = 'r';
                    h2inB.MarkerEdgeColor = 'r';
                    h2inB.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',id(in));
                    h2inB.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qq(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',ss(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',rr(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(5) = dataTipTextRow('Dratio:',dd(in),'%.2f');
                    h2inB.DataTipTemplate.DataTipRows(6) = dataTipTextRow('blankRatio:',bb(in),'%.2f');
                    if customflag, h2inB.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel,':'],cc(in)); end
                    if customflag2, h2inB.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],cc2(in)); end
    
                    ll = [ll,h2inA,h2inB];
                    lt = [lt,{'Sample','QC - Reference'}];
                end
                
                if strcmp(app.DeltaSwitch.Value,'overlay')
                    if strcmp(app.SortbySwitch.Value,'sample')
                        k = ~isnan(qq) & in;
                        y = smooth(log10(qq(k)),0.8,'rloess');              
                        y = power(10,y);
                        %semilogx(app.UIAxesImperialRSD,y,bin(k),'r-.','LineWidth',3);
                        fx = semilogx(app.UIAxesImperialRSD,y,bin(k),'k--','LineWidth',2);
                        datatip(fx,'Visible','off');
                        fx.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Loess Mean QC-RSD','');
                        fx.DataTipTemplate.DataTipRows(2) = [];
                    else
                        k = ~isnan(ss) & in;
                        y = smooth(log10(ss(k)),0.8,'rloess');  
                        y = power(10,y);
                        %semilogx(app.UIAxesImperialRSD,y,bin(k),'b-.','LineWidth',3);
                        fx = semilogx(app.UIAxesImperialRSD,y,bin(k),'k--','LineWidth',2);
                        datatip(fx,'Visible','off');
                        fx.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Loess Mean Sample-RSD','');
                        fx.DataTipTemplate.DataTipRows(2) = [];
                    end
                                  
                    if app.RefCheckBox.Value        
                        k = ~(rr==0) & ~isnan(rr) & in;
                        y = smooth(log10(rr(k)),0.8,'rloess');  
                        y = power(10,y);
                        %semilogx(app.UIAxesImperialRSD,y,bin(k),'c-.','LineWidth',3);
                        fx = semilogx(app.UIAxesImperialRSD,y,bin(k),'k-.','LineWidth',2);
                        datatip(fx,'Visible','off');
                        fx.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Loess Mean Reference-RSD','');
                        fx.DataTipTemplate.DataTipRows(2) = [];
                    end
                else
                    if strcmp(app.SortbySwitch.Value,'sample')                        
                        delta = qq - rr;
                        k = ~isnan(delta) & in;
                        y = smooth(delta(k),0.8,'rloess');                                      
                        fx = plot(app.UIAxesImperialRSD,y,bin(k),'k--','LineWidth',2);
                        datatip(fx,'Visible','off');
                        fx.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Loess Mean delta-RSD','');
                        fx.DataTipTemplate.DataTipRows(2) = [];
                    else                      
                        k = ~isnan(ss) & in;
                        y = smooth(ss(k),0.8,'rloess');                         
                        %plot(app.UIAxesImperialRSD,y,bin(k),'b-','LineWidth',3);
                        %plot(app.UIAxesImperialRSD,y,bin(k),'k-','LineWidth',1);  
                        fx = plot(app.UIAxesImperialRSD,y,bin(k),'k--','LineWidth',2); 
                        datatip(fx,'Visible','off');
                        fx.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Loess Mean Reference-RSD','');
                        fx.DataTipTemplate.DataTipRows(2) = [];
                    end                                 
                end

            end
            xlabel(app.UIAxesImperialRSD,'%RSD');
            ylabel(app.UIAxesImperialRSD,'Cumulative Number of Peaks');
            legend(app.UIAxesImperialRSD,ll,lt,'location','southeast');
            
            app.UIAxesImperialRSD.Box = 'on';
            ylim(app.UIAxesImperialRSD,[0,1.05*max(bin)])

            if strcmp(app.DeltaSwitch.Value,'overlay')
                app.UIAxesImperialRSD.XGrid = 'on';            
                app.UIAxesImperialRSD.YGrid = 'on';            
                app.UIAxesImperialRSD.MinorGridLineStyle = '-';
                app.UIAxesImperialRSD.MinorGridAlpha = 0.2;
                xlim(app.UIAxesImperialRSD,[0.9*min([qq;ss]),1.1*max([qq;ss])])                
                app.UIAxesImperialRSD.XTickLabel = humaniseLogTickLabel(app.UIAxesImperialRSD.XTickLabel);
            end
            %app.UIAxesImperialRSD.ButtonDownFcn = createCallbackFcn(app, @UIAxesImperialRSDButtonDown, true);
        end


        function plotCompareRSD(app)
            cla(app.UIAxesRSDComparison,'reset');
            grey = [0.7,0.7,0.7];
            green = [0,0.6,0.6];
            if ismac
                if height(app.PlotPeakTable) < 100                
                    ms = 16;%30;
                elseif height(app.PlotPeakTable) < 2000
                    ms = 12;%20;
                else
                    ms = 8;%15;
                end
            else
                if height(app.PlotPeakTable) < 100                
                    ms = 13;%30;
                elseif height(app.PlotPeakTable) < 2000
                    ms = 9;%20;
                else
                    ms = 5;%15;
                end
            end
            
            sRSDx = app.PlotPeakTable.sampleRSD;
            maxSampleRSD = max(sRSDx);
            if maxSampleRSD > 200
                maxSampleRSD = 200;
            end
            
            qcRSDx = app.PlotPeakTable.qcRSD;
            maxQCRSD = max(qcRSDx);
            if maxQCRSD > 150
                maxQCRSD = 150;
            end
            
            sRSDx(sRSDx > maxSampleRSD) = maxSampleRSD;
            qcRSDx(qcRSDx > maxQCRSD) = maxQCRSD;
            
            qcRSD = app.PlotPeakTable.qcRSD;
            sRSD = app.PlotPeakTable.sampleRSD;
            refRSD = app.PlotPeakTable.refRSD;
            dRatio = app.PlotPeakTable.dRatio;
            blankRatio = app.PlotPeakTable.blankRatio;
            customLabel1 = app.CustomLabelDropDownB1.Value;
            if strcmp(customLabel1,'None')
                customflag1 = false;
            else
                customflag1 = true;
                custom = app.PlotPeakTable.(customLabel1);
            end

            customLabel2 = app.CustomLabelDropDownB2.Value;
            if strcmp(customLabel2,'None') || customflag1 == false
                customflag2 = false;
                app.CustomLabelDropDownB2.Value = 'None';
            else
                customflag2 = true;
                custom2 = app.PlotPeakTable.(customLabel2);
            end

            in = app.PlotPeakTable.cleanPeaks;
            

            if any(~in)
                if app.LogScaleCheckBox.Value
                    f2 = loglog(app.UIAxesRSDComparison,sRSD(~in),qcRSD(~in),'.','Markersize',ms,'MarkerEdgeColor',grey);
                else
                    f2 = scatter(app.UIAxesRSDComparison,sRSDx(~in),qcRSDx(~in),ms,grey,'filled','MarkerFaceAlpha',0.7);
                end                
                f2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',app.PlotPeakTable.UID(~in));
                f2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qcRSD(~in),'%.2f');
                f2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',sRSD(~in),'%.2f');
                f2.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',refRSD(~in),'%.2f');
                f2.DataTipTemplate.DataTipRows(5) = dataTipTextRow('dRatio:',dRatio(~in),'%.2f');
                f2.DataTipTemplate.DataTipRows(6) = dataTipTextRow('BlankRatio:',blankRatio(~in),'%.2f');
                if customflag1, f2.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel1,':'],custom(~in)); end
                if customflag2, f2.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],custom2(~in)); end
                hold(app.UIAxesRSDComparison,"on");
            end
            
            if any(in)
                if app.LogScaleCheckBox.Value
                    f1 = loglog(app.UIAxesRSDComparison,sRSD(in),qcRSD(in),'.','Markersize',ms,'MarkerEdgeColor',green);
                else
                    f1 = scatter(app.UIAxesRSDComparison,sRSDx(in),qcRSDx(in),ms,green,'filled','MarkerFaceAlpha',0.7);
                end
                
                f1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('UID:',app.PlotPeakTable.UID(in));
                f1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('qcRSD:',qcRSD(in),'%.2f');
                f1.DataTipTemplate.DataTipRows(3) = dataTipTextRow('sampleRSD:',sRSD(in),'%.2f');             
                f1.DataTipTemplate.DataTipRows(4) = dataTipTextRow('refRSD:',refRSD(in),'%.2f');
                f1.DataTipTemplate.DataTipRows(5) = dataTipTextRow('dRatio:',dRatio(in),'%.2f');
                f1.DataTipTemplate.DataTipRows(6) = dataTipTextRow('BlankRatio:',blankRatio(in),'%.2f');
                if customflag1, f1.DataTipTemplate.DataTipRows(7) = dataTipTextRow([customLabel1,':'],custom(in)); end
                if customflag2, f1.DataTipTemplate.DataTipRows(8) = dataTipTextRow([customLabel2,':'],custom2(in)); end
                hold(app.UIAxesRSDComparison,"on");
            end
            
            if app.LogScaleCheckBox.Value
                xlim(app.UIAxesRSDComparison,[0.9*min([qcRSD;sRSD]),1.1*max([qcRSD;sRSD])])
                ylim(app.UIAxesRSDComparison,[0.9*min([qcRSD;sRSD]),1.1*max([qcRSD;sRSD])])
                app.UIAxesRSDComparison.XTickLabel = humaniseLogTickLabel(app.UIAxesRSDComparison.XTickLabel);
                app.UIAxesRSDComparison.YTickLabel = humaniseLogTickLabel(app.UIAxesRSDComparison.XTickLabel);           
            else
                xlim(app.UIAxesRSDComparison,[0,max(sRSDx)]);
                ylim(app.UIAxesRSDComparison,[0,max(qcRSDx)]);
                app.UIAxesRSDComparison.XTick = 0:20:200;
                app.UIAxesRSDComparison.YTick = 0:20:150;
            end
            app.UIAxesRSDComparison.XGrid = 'on';            
            app.UIAxesRSDComparison.YGrid = 'on';            
            app.UIAxesRSDComparison.MinorGridLineStyle = '-';
            app.UIAxesRSDComparison.MinorGridAlpha = 0.2;
            

            xlabel(app.UIAxesRSDComparison,'Sample %RSD');
            ylabel(app.UIAxesRSDComparison,'QC %RSD');
            
            app.UIAxesRSDComparison.Box = 'on';
        end

        function PlotPCA(app)
            try
                plotPCAXX(app.UIAxesPCA,app.PlotDataTable,app.PCAmodel, ...
                    xaxis = app.XAxisDropDown.Value, ...
                    yaxis = app.YAxisDropDown.Value, ...
                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                    IncludeBlanks= app.IncludeBlankSamplesCheckBox.Value, ...
                    IncludeRefs= app.IncludeReferenceSamplesCheckBox.Value, ...
                    label = app.LabelbyDropDown.Value, ...
                    plotCIs = app.ConfidenceIntervalsCheckBox.Value, ...
                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                    cmap = app.cmap);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: PCA Plot','Icon','error');
                cla(app.UIAxesPCA);
                cla(app.UIAxesPVE);
                cla(app.UIAxesPVE2);
                return;
            end
        end

        function calcAndPlotPCA(app)

            if ~app.anyNAN
                impType = 'blank20';
            else
                impType = app.imputationType;
            end

            try 
                [app.PCAmodel] = qcrscPCA(app.PlotDataTable,app.PlotPeakTable, ...
                    RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                    ImputationType = impType, ...
                    LogTransform = app.Log10TransformCheckBox.Value, ...
                    Autoscale = app.AutoscaleCheckBox.Value, ...
                    Paretoscale = app.ParetoscaleCheckBox.Value, ...
                    batchScale = app.batchwiseScaleCheckBox.Value, ...
                    k=app.kEditField.Value);
            catch ME
                uialert(app.UIFigure,{ME.message;'Try reducing the % allowable missing values'},'PCA Error','Icon','error');
                cla(app.UIAxesPCA);
                cla(app.UIAxesPVE);
                cla(app.UIAxesPVE2);
                return;
            end

            pcnum = (1:width(app.PCAmodel.Score))';
            app.XAxisDropDown.Items = ['Order';arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false)];
            app.XAxisDropDown.ItemsData = [0;pcnum];
            app.YAxisDropDown.Items = arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false);
            app.YAxisDropDown.ItemsData = pcnum;  
            
            PlotPCA(app);

            numPCsInBar = 100;
            if length(app.PCAmodel.latent) < numPCsInBar
                numPCsInBar = length(app.PCAmodel.latent);
            end
            
            explainedVals = app.PCAmodel.explained(1:numPCsInBar);
            dRatioVals = app.PCAmodel.DRatio(1:numPCsInBar);
            cumVarVals = app.PCAmodel.cumVar(1:numPCsInBar);
            cumMPQVals = app.PCAmodel.cumMPQ(1:numPCsInBar);
            
            if app.PCStatsSwitch.Value
                thresh = min([75,round(max(dRatioVals)*.1)/.1]);
                temp = dRatioVals;
                temp(temp>thresh) = thresh+4;
                bh = barh(app.UIAxesPVE,-1*temp,'red');
                xlabel(app.UIAxesPVE,'dRatio_{PC}','fontsize',10);
                app.UIAxesPVE.XLim = [-1*(thresh+5),0];
                app.UIAxesPVE.XTick = [-1*thresh,0];
                app.UIAxesPVE.XTickLabel = [thresh,0];

                bh2 = barh(app.UIAxesPVE2,explainedVals,'blue');
                xlabel(app.UIAxesPVE2,'Expl.Var_{PC}','fontsize',10)
                app.UIAxesPVE2.XLim = [0,0.105*ceil(max(explainedVals*10))];
                app.UIAxesPVE2.XTick = [0,0.1*ceil(max(explainedVals*10))];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);                  
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');


            else
                bh = barh(app.UIAxesPVE,-1*cumMPQVals,'red');
                xlabel(app.UIAxesPVE,'Cum.MPQ_{PC}','fontsize',10);
                app.UIAxesPVE.XLim = [-0.105*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTick = [-0.1*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTickLabel = [0.1*ceil(max(cumMPQVals*10)),0];

                bh2 = barh(app.UIAxesPVE2,cumVarVals,'blue');
                xlabel(app.UIAxesPVE2,'Cum.Expl.Var_{PC}','fontsize',10)              
                app.UIAxesPVE2.XLim = [0,100];               
                app.UIAxesPVE2.XTick = [0,100];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');                
            end
            
            app.UIAxesPVE.Box = 'on';
            app.UIAxesPVE.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE2.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2.YTick = [1,numPCsInBar];
            app.UIAxesPVE2.YTickLabel = [1,numPCsInBar];
            app.UIAxesPVE2.Box = 'on';
            app.UIAxesPVE2.TickDir = 'none';
            
            app.MPQLabel.Text = sprintf('MPQ: %.1f%%',app.PCAmodel.MPQ);
            app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>: %.1f%%',app.PCAmodel.MPQ95);
            %app.MPQ95Label.Text = 'MPQ_{95}';
        end        
        
        function plotGammaHistogam(app)
            cla(app.UIAxesQCRSCpdf,'reset');
            ubatch = unique(app.PlotDataTable.Batch);         
            NumOfBatches = numel(ubatch);
            if length(app.baseConfig.QCRSCgammaRange) > 2
                edges1 = app.baseConfig.QCRSCgammaRange(1:end-1) - diff(app.baseConfig.QCRSCgammaRange) / 2;
                edges2 = app.baseConfig.QCRSCgammaRange(2:end) + diff(app.baseConfig.QCRSCgammaRange) / 2;
            else
                edges1 = app.baseConfig.QCRSCgammaRange(1:end) - diff(app.baseConfig.QCRSCgammaRange) / 2;
                edges2 = app.baseConfig.QCRSCgammaRange(1:end) + diff(app.baseConfig.QCRSCgammaRange) / 2;
            end
            edges = unique([edges1,edges2]);
            
            in = app.PlotPeakTable.cleanPeaks;

            n0 = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotPeakTable{:,['gammaB',num2str(ubatch(i))]};
                n0(i,:) = histcounts(y,edges);
            end
            b0 = bar(app.UIAxesQCRSCpdf,app.baseConfig.QCRSCgammaRange,n0','FaceColor','w','EdgeColor','k');
            hold(app.UIAxesQCRSCpdf,"on");  

            n = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotPeakTable{in,['gammaB',num2str(ubatch(i))]};
                n(i,:) = histcounts(y,edges);
            end
            b1 = bar(app.UIAxesQCRSCpdf,app.baseConfig.QCRSCgammaRange,n');
            
            for i = 1:NumOfBatches
                bnum = ones(length(app.baseConfig.QCRSCgammaRange),1)*ubatch(i);
                b0(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b0(i).DataTipTemplate.DataTipRows(2).Label = '#peaks';
                b1(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b1(i).DataTipTemplate.DataTipRows(2).Label = '#peaks';
            end
            xlabel(app.UIAxesQCRSCpdf,'QCRSC gammaVal range');
            ylabel(app.UIAxesQCRSCpdf,'Number of Peaks');
            try
                app.UIAxesQCRSCpdf.YLim = [0,1.05*max(n0,[],'all')];
            catch
                app.UIAxesQCRSCpdf.YLim = [0,1];
            end
        end


        function selectcleanPeaks(app)
            if app.FilterBankSwitch.Value == true
                rsdThresh = app.QCRSDKnob.Value;
                sampleThresh = app.SampleRSDKnob.Value;
                dratioThresh = app.DRatioKnob.Value;
                bratioThresh = app.BlankRatioKnob.Value;
            else
                rsdThresh = 2000;
                sampleThresh = 0;
                dratioThresh = 2000;
                bratioThresh = 2000;
            end

            missingThesh = app.XmissingthreshEditField.Value;
            batchNum = app.BatchDropDown.Value;

            if app.SwitchBeforeAfter.Value
                app.PlotPeakTable = app.PeakXAfter;
                app.PlotDataTable = app.DataX;
            else
                app.PlotPeakTable = app.PeakXBefore;
                app.PlotDataTable = app.DataTable;
                list = setdiff(app.PeakTable.UID,app.PlotPeakTable.UID);
                app.PlotDataTable(:,list) = [];
            end
                  
            ubatch = unique(app.PlotDataTable.Batch);
            NumOfBatch = numel(ubatch);
            for i = 1:NumOfBatch
                temp = app.PlotPeakTable.(['blankRatioB',num2str(ubatch(i))]);
                temp(isnan(temp)) = 0.1;
                temp(temp < 0.1) = 0.1;
                app.PlotPeakTable.(['blankRatioB',num2str(ubatch(i))]) = temp;
            end
            app.PlotPeakTable.blankRatio(isnan(app.PlotPeakTable.blankRatio)) = 0.1;
            app.PlotPeakTable.blankRatio(app.PlotPeakTable.blankRatio < 0.1) = 0.1;
            app.PlotPeakTable.blankRatioMax(isnan(app.PlotPeakTable.blankRatioMax)) = 0.1;
            app.PlotPeakTable.blankRatioMax(app.PlotPeakTable.blankRatioMax < 0.1) = 0.1;
            app.PlotPeakTable.blankRatioMean(isnan(app.PlotPeakTable.blankRatioMean)) = 0.1;
            app.PlotPeakTable.blankRatioMean(app.PlotPeakTable.blankRatioMean < 0.1) = 0.1;
            app.PlotPeakTable.blankRatioMedian(isnan(app.PlotPeakTable.blankRatioMedian)) = 0.1;
            app.PlotPeakTable.blankRatioMedian(app.PlotPeakTable.blankRatioMedian < 0.1) = 0.1;
            app.PlotPeakTable.blankRatioMin(isnan(app.PlotPeakTable.blankRatioMin)) = 0.1;
            app.PlotPeakTable.blankRatioMin(app.PlotPeakTable.blankRatioMin < 0.1) = 0.1;
            app.PlotPeakTable.blankRatio(isnan(app.PlotPeakTable.blankRatio)) = 0.1;
            app.PlotPeakTable.blankRatio(app.PlotPeakTable.blankRatio < 0.1) = 0.1;


            if batchNum > 0
                app.PlotPeakTable.qcRSD = app.PlotPeakTable.(['qcRSDB',num2str(batchNum)]);
                app.PlotPeakTable.sampleRSD = app.PlotPeakTable.(['sampleRSDB',num2str(batchNum)]);
                app.PlotPeakTable.refRSD = app.PlotPeakTable.(['refRSDB',num2str(batchNum)]);
                app.PlotPeakTable.dRatio = app.PlotPeakTable.(['dRatioB',num2str(batchNum)]);
                app.PlotPeakTable.blankRatio = app.PlotPeakTable.(['blankRatioB',num2str(batchNum)]);
                app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.(['DELTAqcRSDB',num2str(batchNum)]);
                app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.(['DELTArefRSDB',num2str(batchNum)]);
                app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.(['DELTAdRatioB',num2str(batchNum)]);
                app.PlotDataTable = app.PlotDataTable(app.PlotDataTable.Batch == batchNum,:);
            else
                if app.BatchwiseSwitch.Value
                    switch app.BatchWiseDropDown.Value
                        case 'Max'
                            app.PlotPeakTable.qcRSD = app.PlotPeakTable.qcRSDMax;
                            app.PlotPeakTable.sampleRSD = app.PlotPeakTable.sampleRSDMax;
                            app.PlotPeakTable.dRatio = app.PlotPeakTable.dRatioMax;
                            app.PlotPeakTable.refRSD = app.PlotPeakTable.refRSDMax;
                            app.PlotPeakTable.blankRatio = app.PlotPeakTable.blankRatioMax;
                            app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.DELTAqcRSDMax;
                            app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.DELTArefRSDMax;
                            app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.DELTAdRatioMax;
                        case 'Min'
                            app.PlotPeakTable.qcRSD = app.PlotPeakTable.qcRSDMin;
                            app.PlotPeakTable.sampleRSD = app.PlotPeakTable.sampleRSDMin;
                            app.PlotPeakTable.dRatio = app.PlotPeakTable.dRatioMin;
                            app.PlotPeakTable.refRSD = app.PlotPeakTable.refRSDMin;
                            app.PlotPeakTable.blankRatio = app.PlotPeakTable.blankRatioMin;
                            app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.DELTAqcRSDMin;
                            app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.DELTArefRSDMin;
                            app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.DELTAdRatioMin;
                        case 'Mean'
                            app.PlotPeakTable.qcRSD = app.PlotPeakTable.qcRSDMean;
                            app.PlotPeakTable.sampleRSD = app.PlotPeakTable.sampleRSDMean;
                            app.PlotPeakTable.dRatio = app.PlotPeakTable.dRatioMean;
                            app.PlotPeakTable.refRSD = app.PlotPeakTable.refRSDMean;
                            app.PlotPeakTable.blankRatio = app.PlotPeakTable.blankRatioMean;
                            app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.DELTAqcRSDMean;
                            app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.DELTArefRSDMean;
                            app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.DELTAdRatioMean;
                        case 'Median'
                            app.PlotPeakTable.qcRSD = app.PlotPeakTable.qcRSDMedian;
                            app.PlotPeakTable.sampleRSD = app.PlotPeakTable.sampleRSDMedian;
                            app.PlotPeakTable.dRatio = app.PlotPeakTable.dRatioMedian;
                            app.PlotPeakTable.refRSD = app.PlotPeakTable.refRSDMedian;
                            app.PlotPeakTable.blankRatio = app.PlotPeakTable.blankRatioMedian;
                            app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.DELTAqcRSDMedian;
                            app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.DELTArefRSDMedian;
                            app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.DELTAdRatioMedian;
                        otherwise
                            baseException = MException('QCRSC:BatchWiseDropDown','unknown option');
                            throw(baseException)
                    end
                else
                    app.PlotPeakTable.qcRSD = app.PlotPeakTable.qcRSD;
                    app.PlotPeakTable.sampleRSD = app.PlotPeakTable.sampleRSD;
                    app.PlotPeakTable.dRatio = app.PlotPeakTable.dRatio;
                    app.PlotPeakTable.refRSD = app.PlotPeakTable.refRSD;
                    app.PlotPeakTable.blankRatio = app.PlotPeakTable.blankRatio;
                    app.PlotPeakTable.DELTAqcRSD = app.PlotPeakTable.DELTAqcRSD;
                    app.PlotPeakTable.DELTArefRSD = app.PlotPeakTable.DELTArefRSD;
                    app.PlotPeakTable.DELTAdRatio = app.PlotPeakTable.DELTAdRatio;
                end             
            end      
            
            qcRSDB = app.PlotPeakTable.qcRSD;
            sampleRSDB = app.PlotPeakTable.sampleRSD;
            dRatioB = app.PlotPeakTable.dRatio;
            blankRatioB = app.PlotPeakTable.blankRatio;
            

            X = app.PlotDataTable{app.PlotDataTable.Sample,app.PlotPeakTable.UID};

            app.anyNAN = anynan(X);
            if ~app.anyNAN
                app.XmissingthreshEditField.Enable = "off";
                app.MissingValueImputationButtonGroup.Enable = "off";
                app.XmissingthreshEditFieldLabel.Enable = "off";
            else
                app.XmissingthreshEditField.Enable = "on";
                app.MissingValueImputationButtonGroup.Enable = "on";
                app.XmissingthreshEditFieldLabel.Enable = "on";
            end


            batchSample = app.PlotDataTable.Batch(app.PlotDataTable.Sample);
            if app.BatchwiseSwitch.Value && batchNum == 0
                goodPeaks = ones(height(app.PlotPeakTable),1);
                for i = 1:NumOfBatch
                    Xb = X(batchSample == ubatch(i),:);
                    percMissing = 100 * sum(isnan(Xb))./height(Xb);
                    goodPeaks = goodPeaks & (percMissing < missingThesh)';
                end
            else
                percMissing = 100 * sum(isnan(X))./height(X);
                goodPeaks = (percMissing < missingThesh)';
            end
           
            app.XremainingLabel.Text = sprintf('%u peaks remaining',sum(goodPeaks));

            
            cleanPeaks = qcRSDB < rsdThresh & sampleRSDB > sampleThresh & dRatioB < dratioThresh & blankRatioB < bratioThresh & goodPeaks;
            

            numcleanPeaks = sum(cleanPeaks);
            app.CleanPeaksRemainingLabel.Text = sprintf('# Clean Peaks: %u',numcleanPeaks);
            app.PlotPeakTable.cleanPeaks = cleanPeaks;
            app.PeakX.cleanPeaks = cleanPeaks;

            P1 = app.PlotPeakTable(goodPeaks,:);
            list = setdiff(app.PlotPeakTable.UID,P1.UID);
            app.PlotDataTable(:,list) = [];
            app.PlotPeakTable = P1;
              
        end
              
        function ResetCleanDials(app)

            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            app.FilterBankSwitch.Value = false;          
            setFilterKnobColors(app);
            
            app.BatchDropDown.Value = -1;
            app.BatchwiseSwitch.Value = false;
            app.BatchWiseDropDown.Value = 'Max';
            app.BatchWiseDropDown.Enable = "off";
            app.XmissingthreshEditField.Value = 20;
            app.SwitchBeforeAfter.Value = true;
            
            app.QCRSDKnob.Value = 2000;
            app.SampleRSDKnob.Value = 0;
            app.DRatioKnob.Value = 2000;
            app.BlankRatioKnob.Value = 20000;

            app.SaveCleanPrefixEditField.Value = 'Clean';
            app.SuffixEditField.Value = 'QCRSC';
         
            app.IncludeBlankSamplesCheckBox.Value = false;
            app.IncludeReferenceSamplesCheckBox.Value = false;
            app.IncludeQCSamplesCheckBox.Value = true;
            app.Log10TransformCheckBox.Value = true;
            app.AutoscaleCheckBox.Value = true;         
            app.KNNcolumnButton.Value = true;
            app.imputationType = 'KNNcol';
            

            app.YAxisDropDown.Value = 2;
            app.XAxisDropDown.Value = 1;
            app.DataPointsCheckBox.Value = true;
            app.ConfidenceIntervalsCheckBox.Value = true;
            app.LegendCheckBox.Value = true;
            app.LabelbyDropDown.Value = 'SampleType';
            app.PCStatsSwitch.Value = true;

            app.RefCheckBox.Value = false;
            app.SortbySwitch.Value = 'sample';
            app.DeltaSwitch.Value = 'overlay';
            app.DeltaSwitch.Enable = 'off';
            app.CustomLabelDropDownA1.Value = 'None';
            app.CustomLabelDropDownA2.Value = 'None';

            app.LogScaleCheckBox.Value = false;
            app.CustomLabelDropDownB1.Value = 'None';
            app.CustomLabelDropDownB2.Value = 'None';

            app.SmoothBLANKslider.Value = 20;
            app.SmoothDRATIOslider.Value = 20;
            app.SmoothRSDslider.Value = 20;

            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SwitchRSDpdf.Value = 'histogram';
            app.SwitchBlankpdf.Value = 'histogram';

            app.SwitchBlankPlot.Value = 'all';
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchDratioPlot.Value = 'all';
            
        end

         function CleanAndPlot(app)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                if app.noQCflag
                    baseException = MException('QCRSC:NoQC','There has to be 3 or more QCs per batch for exploration!');
                    throw(baseException);
                end
                selectcleanPeaks(app);        
                plotImperialRSD(app);
                plotCompareRSD(app);
                calcAndPlotPCA(app);        
                RSDPlotValueChanged(app)           
                DratioPlotValueChanged(app)
                BlankPlotValueChanged(app)
                plotGammaHistogam(app);
            catch ME
                ClearPlots(app);
                uialert(app.UIFigure,ME.message,'CleanAndPlot Failure','Icon','error');
                app.CleanExplorePanel.Enable = "off";
                app.SavePanel.Enable = "on";
                switchAllPanelsA(app,'on');
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

         function RePlotAll(app)
            
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try     
                plotImperialRSD(app);
                plotCompareRSD(app);
                calcAndPlotPCA(app);        
                RSDPlotValueChanged(app)           
                DratioPlotValueChanged(app)
                BlankPlotValueChanged(app)
                plotGammaHistogam(app);
            catch ME
                ClearPlots(app);
                uialert(app.UIFigure,ME.message,'RePlotAll Failure','Icon','error');
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

         function RePlotRSD(app)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                plotImperialRSD(app);
                plotCompareRSD(app);
            catch ME
                uialert(app.UIFigure,ME.message,'RePlotRSD Failure','Icon','error');
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        function ClearPlots(app)
            cla(app.UIAxesRSDpdf);
            cla(app.UIAxesDRATIOpdf);
            cla(app.UIAxesBLANKpdf);
            cla(app.UIAxesQCRSCpdf);
            cla(app.UIAxesPCA);
            cla(app.UIAxesPVE);
            cla(app.UIAxesPVE2);
            cla(app.UIAxesImperialRSD);
            cla(app.UIAxesRSDComparison);

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            ylabel(app.UIAxesPCA,'PC2');
            xlabel(app.UIAxesPCA,'PC1');
            app.CleanPeaksRemainingLabel.Text = '# Clean Peaks:';          
            app.MPQLabel.Text = sprintf('MPQ:');
            app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>:');
            %app.MPQ95Label.Text = 'MPQ_{95}';
            
            app.QCRSCenginePanel.Title = sprintf('QCRSC engine:');
            app.PCAmodel = [];
        end

        
        function SaveData(app,Data,Peak,suffix)
            d = uiprogressdlg(app.UIFigure,'Title','Saving Data');
            drawnow
            pause(0.2);
            try
                if strcmp(app.FileType,'xls')                   
                    xlSheetNames = sheetnames(app.ExcelName);
                    sheetname = [app.DataName,suffix];
                    d.Message = ['Saving: ',sheetname];
                    if ismember(sheetname,xlSheetNames)
                        selection = uiconfirm(app.UIFigure,['Sheet ',sheetname,' will be overwritten'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            baseException = MException('QCRSC:DataTableError',['Cannot save to Sheet:',sheetname]);
                            throw(baseException)
                        end
                    end
                    writetable(Data,app.ExcelName,'Sheet',sheetname,'WriteMode','overwritesheet');
                    d.Value = 1/2;
                    pause(0.2);
                    sheetname = [app.PeakName,suffix];
                    d.Message = ['Saving: ',sheetname];
                    if ismember(sheetname,xlSheetNames)
                        selection = uiconfirm(app.UIFigure,['Sheet ',sheetname,' will be overwritten'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            baseException = MException('QCRSC:PeakTableError',['Cannot save to Sheet:',sheetname]);
                            throw(baseException)
                        end
                    end
                    writetable(Peak,app.ExcelName,'Sheet',sheetname,'WriteMode','overwritesheet');
                    
                elseif strcmp(app.FileType,'csv')                    
                    filename = [app.DataName,suffix,'.csv'];
                    d.Message = ['Saving: ',filename];
                    if isfile(filename)
                        selection = uiconfirm(app.UIFigure,[filename,' will be overwritten'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            baseException = MException('QCRSC:DataTableError',['Cannot save to ',filename]);
                            throw(baseException)
                        end
                    end
                    writetable(Data,filename);
                    d.Value = 1/2;
                    pause(0.2);                  
                    filename = [app.PeakName,suffix,'.csv'];
                    d.Message = ['Saving: ',filename];
                    if isfile(filename)
                        selection = uiconfirm(app.UIFigure,[filename,' will be overwritten'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            baseException = MException('QCRSC:PeakTableError',['Cannot save to ',filename]);
                            throw(baseException)
                        end
                    end
                    writetable(Peak,filename);
                else
                    uialert(app.UIFigure,'Unable to save in Matlab mode','Warning','Icon','warning');
                end
             catch baseException
                 uialert(app.UIFigure,baseException.message,'Failed to save data','Icon','error');
             end
            d.Value = 1;
            pause(0.2);
            close(d);
            
        end

        function updateConfigTextArea(app,field,value)
                       
            oldConfigText = app.configFileTextArea.Value;
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
           
            try
                app.baseConfig = jsondecode(app.jsonTxt);
                app.baseConfig.(field) = value;
                app.jsonTxt = jsonencode(app.baseConfig,'PrettyPrint',true);
                app.jsonView = strsplit(app.jsonTxt,'\n')';          
                app.configFileTextArea.Value = app.jsonView;
                app.baseConfig = validateConfig(app.baseConfig);
            catch
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = oldConfigText;
                app.jsonTxt = oldJSON;
                ME = MException('QCRSC:baseConfigError','Changing QCRSC Configuration failed');
                throw(ME);
            end          
                   

        end

        function updateConfigFields(app)
            app.BlankRatioMethodDropDown.Value = app.baseConfig.BlankRatioMethod; 
        end

        function switchAllPanelsA(app,onoff)
            app.IncomingDataPanel.Enable = onoff;
            app.ConfigurationPanel.Enable = onoff;
            app.PreFilterPanel.Enable = onoff;
            app.QCRSCenginePanel.Enable = onoff;
        end

        function switchAllPanelsB(app,onoff)
            app.SavePanel.Enable = onoff;
            app.CleanExplorePanel.Enable = onoff;
        end

        function setFilterKnobColors(app)
            if app.FilterBankSwitch.Value == true
                colr = [1,0,0];
                app.FilterBankLamp.Color = colr;
                app.SampleRSDKnob.FontColor = colr;
                app.QCRSDKnob.FontColor = colr;
                app.BlankRatioKnob.FontColor = colr;
                app.DRatioKnob.FontColor = colr;
                app.SampleRSDKnobLabel.FontColor = colr;
                app.QCRSDKnobLabel.FontColor = colr;
                app.BlankRatioKnobLabel.FontColor = colr;
                app.DRatioKnobLabel.FontColor = colr;
                % app.FilterBankSwitch.FontColor = [1.00,1.00,1.00];
                % app.FilterBankLabel.FontColor = [1.00,1.00,1.00];
                % app.PanelKnobs.BackgroundColor = [0.98,0.22,0.22];
                % app.SampleRSDKnob.FontColor = [1.00,1.00,1.00];
                % app.QCRSDKnob.FontColor = [1.00,1.00,1.00];
                % app.BlankRatioKnob.FontColor = [1.00,1.00,1.00];
                % app.DRatioKnob.FontColor = [1.00,1.00,1.00];
                % app.SampleRSDKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.QCRSDKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.BlankRatioKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.DRatioKnobLabel.FontColor = [1.00,1.00,1.00];
            else
                app.FilterBankLamp.Color = [0.50,0.50,0.50];
                app.FilterBankSwitch.FontColor = [0,0,0];
                app.FilterBankLabel.FontColor = [0,0,0];
                app.PanelKnobs.BackgroundColor = [0.94,0.94,0.94];
                app.SampleRSDKnob.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnob.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnob.FontColor = [0.65,0.65,0.65];
                app.DRatioKnob.FontColor = [0.65,0.65,0.65];
                app.SampleRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnobLabel.FontColor = [0.65,0.65,0.65];
                app.DRatioKnobLabel.FontColor = [0.65,0.65,0.65];
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
         
            app.UIFigure.Name = 'QCMXP';
            app.UIFigure.Icon= 'icon_512x512@2x.png';
            
            app.ViewPeakTableButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.LaunchPeakExplorer.Enable = 'off';
            app.drinkMeButton.Enable = 'off';
            
            app.PreFilterPanel.Enable = 'off';
            app.QCRSCenginePanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            
            app.RunningLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'off';
            app.ImportSuccess = true;
            
            app.complete = false;
                                    
            app.MPQLabel.Text = 'MPQ:';                            
            app.MPQ95Label.Text = 'MPQ<sub>95</sub>:';
            %app.MPQ95Label.Text = 'MPQ_{95}';

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            app.UIAxesPCA.Title.FontSize = 12;
            app.UIAxesPCA.Title.Color = 'r';
            app.QCRSCgTab.Title = ['QCRSC ',char(947)'];
            app.imputationType = 'KNNcol';
            
            app.ProjectName = '';
            app.FilterBankSwitch.Value = false;

            % Colormaps: {'colorblind7','tolBright7','colorblind12','okabe&ito8','quoka', 'lines', 'colorcube', 'prism', 'moog', 'random'};                       
            app.cmapName = 'colorblind12';
            app.cmap = mapRcolors(app.cmapName);
      

            switch nargin
                case 1
                    
                case 2                   
                    [app.DataTable,app.PeakTable] = readDataXLapp(varargin{1},fighandle=app.UIFigure);
                    app.ProjectName = varargin{1};
                    app.PreFilterPanel.Enable = 'on';
                    app.QCRSCenginePanel.Enable = 'on';
                    app.LaunchPeakExplorer.Enable = 'on';
                    app.drinkMeButton.Enable = 'on';
                    app.DataName = 'Data';
                    app.PeakName = 'Peak';
                    app.FileType = 'xls';
                case 3
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    app.ProjectName = 'Matlab Mode';  
                    app.DataName = '';
                    app.PeakName = '';                 
                    app.PreFilterPanel.Enable = 'on';
                    app.QCRSCenginePanel.Enable = 'on';
                    app.LaunchPeakExplorer.Enable = 'on';
                    app.drinkMeButton.Enable = 'on';
                    app.FileType = 'matlab';
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                otherwise
                    uialert(app.UIFigure,'Too many input arguments','Error','Icon','error');
            end

            app.jsonDefault = ['{' newline ...
                '"LogTransform": true,' newline ...
                '"RemoveZeros": true,' newline ...
                '"OutlierMethod": "Quadratic",' newline ...
                '"OutlierCI": 0.95,' newline ...
                '"OutlierPostHoc": "NaN",' newline ...
                '"IntraBatchMode": "Spline",' newline ...
                '"InterBatchMode": "QC",' newline ... 
                '"QCRSCgammaRange": "0.5:0.5:4",' newline ...
                '"QCRSCcvMethod": "5-Fold",' newline ...
                '"QCRSCmcReps": 10,' newline ...
                '"CorrectionType": "Subtract",' newline ...
                '"BlankRatioMethod": "Median",' newline ...
                '"RelativeLOD": 1.2,' newline ...
                '"ParallelProcess": true ' newline ...
                '}'];
                
            app.configFilename = 'Default';
            app.jsonTxt = app.jsonDefault;
            app.jsonView = strsplit(app.jsonTxt,'\n')';
            app.baseConfig = jsondecode(app.jsonDefault);            
            app.configFileTextArea.Value = app.jsonView;
            app.ConfigurationPanel.Title = ['Configuration: ',app.configFilename];
            
            try
                app.baseConfig = validateConfig(app.baseConfig);
                updateConfigFields(app);
            catch
                mydlg = uiconfirm(app.UIFigure,'Default QCRSC config is corrupted. Better call David.','Confirm Close','Icon','error');
                waitfor(mydlg);
                UIFigureCloseRequest(app);
                return;
            end

            if ~isempty(app.DataTable)
                ResetData(app);
                ResetCleanDials(app);
                preQCRSCclean(app);
            end
            app.PCAmodel = [];

        end

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            app.LaunchPeakExplorer.Enable = 'off';
            app.LaunchConfigExplorer.Enable = 'off';
            
            if ~isempty(app.SpreadsheetApp)
                delete(app.SpreadsheetApp);
                app.ViewPeakTableButton.Enable = 'on';
            end

            app.ImportSuccess = false;
            app.ImportApp = ImportDataPeakFile(app);
            waitfor(app.ImportApp);
            figure(app.UIFigure);
            app.IncomingDataPanel.Enable = 'on';
            app.LaunchConfigExplorer.Enable = 'on';
            app.ConfigurationPanel.Enable = 'on';
            if app.ImportSuccess
                delete(app.PopOutPlotApp);
                delete(app.SpreadsheetApp);
                delete(app.configExplorerApp);
                delete(app.peakExplorerApp);
                app.ViewPeakTableButton.Enable = 'on';
                app.LaunchPeakExplorer.Enable = 'on'; 
                app.drinkMeButton.Enable = 'on';
                cd(app.CurrentFolder);
                app.complete = false;
                ffile = fullfile(app.CurrentFolder,app.ProjectName);
                app.UIFigure.Name =  ['Path: ',ffile];
                maxNumQCperBatch = checkNumberInBatch(app.DataTable,'QC');
                app.noQCflag = maxNumQCperBatch < 3;
                ResetData(app);
                ResetCleanDials(app);
                ClearPlots(app);
                preQCRSCclean(app);
                autoImportConfig(app);
                %checkInterBatchValid(app);
            elseif ~isempty(app.DataTable)
                app.LaunchPeakExplorer.Enable = 'on';                     
                app.PreFilterPanel.Enable = 'on';
                app.QCRSCenginePanel.Enable = 'on';              
                app.ConfigurationPanel.Enable = 'on';
                if app.complete
                    switchAllPanelsB(app,'on');
                end            
            end
        end

        % Button pushed function: ViewPeakTableButton
        function ViewPeakTableButtonPushed(app, event)
            app.ViewPeakTableButton.Enable = 'off';
            %app.LOADDATAButton.Enable = 'off';     
            %app.SpreadsheetApp = TidyDataView(app, app.DataTable, app.PeakTable);
            app.SpreadsheetApp = TidyPeakTableView(app, app.PeakTable);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView; 

            if ismac
                scrsz = get(0,'ScreenSize');
                f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
                [file, path] = uigetfile('.json');
                delete(f);
                figure(app.UIFigure);
            else       
                %app.UIFigure.Visible = 'off';
                [file, path] = uigetfile('.json');
                %app.UIFigure.Visible = 'on';
                figure(app.UIFigure);
            end


            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = file;
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.jsonView = oldJSONview;
                    app.baseConfig = oldConfig;
                end                
                app.configFileTextArea.Value = app.jsonView;  
            else
                uialert(app.UIFigure,'Failed to import config file','Config Error','Icon','error');
            end
            app.ConfigurationPanel.Title = ['Configuration: ',app.configFilename];
            updateConfigFields(app);
            %checkInterBatchValid(app);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            if strcmp(app.configFilename,'Default')
                tempFilename = 'userConfig.json';
            else
                tempFilename = app.configFilename;
            end

            if ismac
                scrsz = get(0,'ScreenSize');
                f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
                [file,path]=uiputfile('.json','Save QCRSC config file',tempFilename);
                delete(f);
                figure(app.UIFigure);
            else       
                [file,path]=uiputfile('.json','Save QCRSC config file',tempFilename);
                figure(app.UIFigure);
            end
          
            if ~isequal(file,0)
                try
                    cfg = jsondecode(app.jsonTxt);
                    jsontxt = jsonencode(cfg,'PrettyPrint',true);
                    fid=fopen(fullfile(path,file),'w');
                    fprintf(fid,jsontxt);
                    fclose(fid);
                    app.configFilename = file;
                catch
                    uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
                end
            end
            app.ConfigurationPanel.Title = ['Configuration: ',app.configFilename];
        end

        % Value changed function: configFileTextArea
        function configTextAreaValueChanged(app, event)
            
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
            

            app.jsonView = app.configFileTextArea.Value;
            app.jsonTxt = strjoin(app.jsonView,'\n');     
                 
            try               
                app.baseConfig = jsondecode(app.jsonTxt);
            catch
                uialert(app.UIFigure,{char('Json file Corrupted'); char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
                return;
            end
            try
                app.baseConfig = validateConfig(app.baseConfig);
            catch ME
                uialert(app.UIFigure,{ME.message; char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
            end          
             updateConfigFields(app);
             
             % just in case RelativeLOD changed.
             if ~isempty(app.DataTable) 
                %checkInterBatchValid(app);
                preQCRSCclean(app);
             end
        end

        % Button pushed function: LaunchPeakExplorer
        function LaunchPeakExplorerPushed(app, event)
                 try    
                   app.peakExplorerApp = QCRSCpeakExplorer(app);
                 catch ME
                     uialert(app.UIFigure,ME.message,'Warning: peakExplorerApp Error','Icon','warning');
                 end            
        end

        % Button pushed function: LaunchConfigExplorer
        function LaunchConfigExplorerPushed(app, event)
                try    
                   app.configExplorerApp = QCRSCconfigExplorer(app);
                catch ME
                    uialert(app.UIFigure,ME.message,'Warning: configExplorerApp Error','Icon','warning');
                end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.PopOutPlotApp)
            delete(app.ImportApp)
            delete(app.configExplorerApp)
            delete(app.peakExplorerApp)
            delete(app.SpreadsheetApp)
            delete(app)           
        end

        % Value changed function: BatchwiseCheckBox, 
        % ...and 1 other component
        function permissiblemissingQCsEditFieldValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();            
            preQCRSCclean(app);           
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            drawnow();            
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an efect','Warning','Icon','warning');
            end
        end

        % Button pushed function: RunButton
        function StartButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.complete = false;
            app.peaksCorrectedGauge.Value = 0;
            app.CompleteLamp.Enable = 'off';
            ResetCleanDials(app);
            ClearPlots(app);    
            app.IncomingDataPanel.Enable = 'off';
            app.ConfigurationPanel.Enable = 'off';
            app.PreFilterPanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.BypassButton.Enable = 'off';       
            app.PauseButton.Enable = 'on';
            app.AbortButton.Enable = 'on';
            drawnow();
            try
                checkInterBatchValid(app);
                preQCRSCclean(app);
                optimiseAndCorrectTable(app);
            catch ME
                uialert(app.UIFigure,ME.message,'QCRSC Engine Failure','Icon','error');           
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                switchAllPanelsA(app,'on');
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.RunButton.Enable = 'on';
            app.BypassButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.IncomingDataPanel.Enable = 'on';
            app.ConfigurationPanel.Enable = 'on';
            app.PreFilterPanel.Enable = 'on';
            app.SavePanel.Enable = 'on';
            app.CleanExplorePanel.Enable = 'on';
            if app.aborted
                app.CleanExplorePanel.Enable = 'off';
                app.SavePanel.Enable = 'off';               
                app.aborted = false;
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.bypass = false;       
            CleanAndPlot(app);            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.paused = not(app.paused);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.aborted = true;         
            res = uiconfirm(app.UIFigure,{'Are you sure you want to prematurely end this run?';'QCRSCauto will be reset!'},'Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.aborted = false;
            end                    
        end

        % Button pushed function: BypassButton
        function BypassButtonPushed(app, event)
            app.bypass = true;            
            res = uiconfirm(app.UIFigure,{'Are you sure you want to bypass QCRSC?';'Before/After will show the same data'},'Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.bypass = false;
                return;
            end
            StartButtonPushed(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            suffix = app.SuffixEditField.Value;
            TempPeak = app.PeakX;
            TempPeak.cleanPeaks = [];
            SaveData(app,app.DataX,TempPeak,suffix);
        end

        % Button pushed function: SaveCleanButton
        function SaveCleanButtonPushed(app, event)
            PeakClean = app.PeakX(app.PeakX.cleanPeaks,:);
            DataClean = app.DataX;
            suffix = app.SaveCleanPrefixEditField.Value;
            list = setdiff(app.PeakX.UID,PeakClean.UID);
            DataClean(:,list) = [];    
            PeakClean.cleanPeaks = [];
            SaveData(app,DataClean,PeakClean,suffix);
            if app.AlsoSaveImputedDataCheckBox.Value == true
                PeakClean.cleanPeaks = ones(height(PeakClean),1);
                DataImputed = preprocessDataForExport(DataClean,PeakClean, ...
                        RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                        ImputationType = app.imputationType, ...
                        LogTransform = app.Log10TransformCheckBox.Value, ...
                        Autoscale = app.AutoscaleCheckBox.Value, ...
                        Paretoscale = app.ParetoscaleCheckBox.Value, ...
                        batchScale = app.batchwiseScaleCheckBox.Value, ...
                        k = app.kEditField.Value);
                temp = [suffix,'Imputed'];
                PeakClean.cleanPeaks = [];
                SaveData(app,DataImputed,PeakClean,temp);
            end 
            
            
        end

        % Button pushed function: ResetFilterButton
        function ResetFilterButtonPushed(app, event)
            ResetCleanDials(app);
            CleanAndPlot(app);
        end

        % Value changed function: BlankRatioKnob, DRatioKnob, QCRSDKnob, 
        % ...and 1 other component
        function KnobValueChanged(app, event)
            if app.FilterBankSwitch.Value == true
                CleanAndPlot(app);
            end
        end

        % Value changed function: PCStatsSwitch
        function RedoPCA(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();            
            calcAndPlotPCA(app);           
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: RefCheckBox, SortbySwitch
        function ImperialPlotValueChanged(app, event)
            if app.RefCheckBox.Value
                app.DeltaSwitch.Enable = 'on';
            else
                app.DeltaSwitch.Value = 'overlay';
                app.DeltaSwitch.Enable = 'off';
            end
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            plotImperialRSD(app); 
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: LogScaleCheckBox
        function RSDcompareValueChaged(app, event)
            plotCompareRSD(app);           
        end

        % Value changed function: SmoothRSDslider, SwitchRSDPlot, 
        % ...and 1 other component
        function RSDPlotValueChanged(app, event)
            
            if strcmp(app.SwitchRSDpdf.Value,'histogram')
                app.SmoothRSDslider.Visible = 'off';
                app.SmoothSlider_2Label.Visible = 'off';
            else
                app.SmoothRSDslider.Visible = 'on';
                app.SmoothSlider_2Label.Visible = 'on';
            end
            
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesRSDpdf,app.PlotPeakTable,bvec,label='qcRSD',plotType=app.SwitchRSDpdf.Value,smooth=app.SmoothRSDslider.Value,split=app.SwitchRSDPlot.Value,cmap=app.cmap);
        end

        % Value changed function: SmoothBLANKslider, SwitchBlankPlot, 
        % ...and 1 other component
        function BlankPlotValueChanged(app, event)
            if strcmp(app.SwitchBlankpdf.Value,'histogram')
                app.SmoothBLANKslider.Visible = 'off'; 
                app.SmoothSlider_2Label_3.Visible = 'off';
            else
                app.SmoothBLANKslider.Visible = 'on';
                app.SmoothSlider_2Label_3.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesBLANKpdf,app.PlotPeakTable,bvec,label='blankRatio',plotType=app.SwitchBlankpdf.Value,smooth=app.SmoothBLANKslider.Value,split=app.SwitchBlankPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: SmoothDRATIOslider, SwitchDRATIOpdf, 
        % ...and 1 other component
        function DratioPlotValueChanged(app, event)
            if strcmp(app.SwitchDRATIOpdf.Value,'histogram')
                app.SmoothDRATIOslider.Visible = 'off';  
                app.SmoothSlider_2Label_2.Visible = 'off';
            else
                app.SmoothDRATIOslider.Visible = 'on';
                app.SmoothSlider_2Label_2.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesDRATIOpdf,app.PlotPeakTable,bvec,label='dRatio',plotType=app.SwitchDRATIOpdf.Value,smooth=app.SmoothDRATIOslider.Value,split=app.SwitchDratioPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: DeltaSwitch
        function DeltaSwitchValueChanged(app, event)
            plotImperialRSD(app)   
        end

        % Value changed function: BlankRatioMethodDropDown
        function BlankRatioMethodDropDownValueChanged(app, event)
            updateConfigTextArea(app,'BlankRatioMethod',app.BlankRatioMethodDropDown.Value);
            calculateStats(app);
            CleanAndPlot(app);                              
        end

        % Value changed function: drinkMeButton
        function drinkMeButtonValueChanged(app, event)
            if app.drinkMeButton.Value
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();            
                drinkMe(app);
                preQCRSCclean(app);           
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                drawnow();    
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an efect','Warning','Icon','warning');
                end
            else
                uialert(app.UIFigure,"Sorry, You cannot reverse 'drinking the shrinking potion'! Please reload the data",'Warning','Icon','warning');
                app.drinkMeButton.Value = true;
            end
        end

        % Value changed function: SwitchBeforeAfter
        function SwitchBeforeAfterValueChanged(app, event)
            app.FilterBankSwitch.Value = false;
            setFilterKnobColors(app);
            CleanAndPlot(app);            
        end

        % Value changed function: CustomLabelDropDownA1
        function CustomLabelDropDownA1ValueChanged(app, event)
            value = app.CustomLabelDropDownA1.Value;
            app.CustomLabelDropDownB1.Value = value;
            RePlotRSD(app);
        end

        % Value changed function: CustomLabelDropDownA2
        function CustomLabelDropDownA2ValueChanged(app, event)
            value = app.CustomLabelDropDownA2.Value;
            app.CustomLabelDropDownB2.Value = value;
            RePlotRSD(app);
        end

        % Value changed function: CustomLabelDropDownB1
        function CustomLabelDropDownB1ValueChanged(app, event)
            value = app.CustomLabelDropDownB1.Value;
            app.CustomLabelDropDownA1.Value = value;
            RePlotRSD(app);
        end

        % Value changed function: CustomLabelDropDownB2
        function CustomLabelDropDownB2ValueChanged(app, event)
            value = app.CustomLabelDropDownB2.Value;
            app.CustomLabelDropDownA2.Value = value;
            RePlotRSD(app);
        end

        % Button pushed function: PopOutButton
        function PopOutButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                ScatterView(app.PlotDataTable,app.PlotPeakTable, ...
                    RemoveZeros = app.baseConfig.RemoveZeros, ...
                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                    IncludeRefs = app.IncludeReferenceSamplesCheckBox.Value, ...
                    IncludeBlanks = app.IncludeBlankSamplesCheckBox.Value, ...
                    ImputationType = app.imputationType, ...
                    LogTransform = app.Log10TransformCheckBox.Value, ...
                    Autoscale = app.AutoscaleCheckBox.Value, ...
                    batchScale = app.batchwiseScaleCheckBox.Value, ...
                    k = app.kEditField.Value, ...
                    xaxis = app.XAxisDropDown.Value, ...
                    yaxis = app.YAxisDropDown.Value, ...                    
                    label = app.LabelbyDropDown.Value, ...
                    plotCIs = app.ConfidenceIntervalsCheckBox.Value, ...
                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                    cmapName = app.cmapName);
            catch ME
               uialert(app.UIFigure,ME.message,'Warning: ScatterView App Error','Icon','warning');
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: DataPointsCheckBox
        function DataPointsCheckBoxValueChanged(app, event)
            value = app.DataPointsCheckBox.Value;
            if ~value && ~app.ConfidenceIntervalsCheckBox.Value
                app.DataPointsCheckBox.Value = true;
            else
                PlotPCA(app);
            end
        end

        % Value changed function: ConfidenceIntervalsCheckBox
        function ConfidenceIntervalsCheckBoxValueChanged(app, event)
            value = app.ConfidenceIntervalsCheckBox.Value;
            if ~value && ~app.DataPointsCheckBox.Value
                app.ConfidenceIntervalsCheckBox.Value = true;
            else
                PlotPCA(app);
            end
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            if app.LegendCheckBox.Value
                app.UIAxesPCA.Legend.Visible = true;
            else
                app.UIAxesPCA.Legend.Visible = false;
            end   
        end

        % Value changed function: blankmissingCheckBox
        function blankmissingCheckBoxValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();            
            preQCRSCclean(app);           
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an efect','Warning','Icon','warning');
            end
            drawnow();                    
        end

        % Value changed function: FilterBankSwitch
        function FilterButtonPushed(app, event)
            setFilterKnobColors(app);
            if app.QCRSDKnob.Value ~= 2000 || ...
                app.SampleRSDKnob.Value ~= 0 || ...
                app.DRatioKnob.Value ~= 2000 || ...
                app.BlankRatioKnob.Value ~= 20000
                    CleanAndPlot(app);
            end
            
        end

        % Value changed function: BatchDropDown, BatchwiseSwitch, 
        % ...and 1 other component
        function PreFilterValueChange(app, event)
            if app.BatchwiseSwitch.Value
                app.BatchWiseDropDown.Enable = "on";
            else
                app.BatchWiseDropDown.Enable = "off";
            end
            CleanAndPlot(app);
        end

        % Value changed function: IncludeBlankSamplesCheckBox
        function IncludeBlankSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: IncludeReferenceSamplesCheckBox
        function IncludeReferenceSamplesCheckBoxValueChanged(app, event)
             PlotPCA(app)     
        end

        % Value changed function: IncludeQCSamplesCheckBox
        function IncludeQCSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: LabelbyDropDown
        function LabelbyDropDownValueChanged(app, event)
            switch app.LabelbyDropDown.Value
                case 'BatchQC'
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeQCSamplesCheckBox.Enable = false;
                    app.IncludeReferenceSamplesCheckBox.Enable = false;
                    app.IncludeBlankSamplesCheckBox.Enable = false;
                case 'SampleType'
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Enable = true;
                otherwise
                    app.IncludeQCSamplesCheckBox.Value = false;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Enable = true;
            end
            PlotPCA(app)
        end

        % Selection changed function: MissingValueImputationButtonGroup
        function MissingValueImputationButtonGroupSelectionChanged(app, event)
            selectedButton = app.MissingValueImputationButtonGroup.SelectedObject;
            switch selectedButton.Text
                case app.KNNcolumnButton.Text
                    app.imputationType = 'KNNcol';
                case app.KNNrowButton.Text
                    app.imputationType = 'KNNrow';
                case app.blank20minButton.Text
                    app.imputationType = 'blank20';
            end
            RedoPCA(app);
        end

        % Value changed function: Log10TransformCheckBox
        function Log10TransformCheckBoxValueChanged(app, event)
            RedoPCA(app);
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            if app.ParetoscaleCheckBox.Value
                app.ParetoscaleCheckBox.Value = false;
            end
            if app.ParetoscaleCheckBox.Value || app.AutoscaleCheckBox.Value
                app.batchwiseScaleCheckBox.Enable = "on";
            else
                app.batchwiseScaleCheckBox.Value = false;
                app.batchwiseScaleCheckBox.Enable = "off";
            end
            RedoPCA(app)
        end

        % Value changed function: ParetoscaleCheckBox
        function ParetoscaleCheckBoxValueChanged(app, event)
            if app.AutoscaleCheckBox.Value
                app.AutoscaleCheckBox.Value = false;
            end
            if app.ParetoscaleCheckBox.Value || app.AutoscaleCheckBox.Value               
                app.batchwiseScaleCheckBox.Enable = "on";
            else
                app.batchwiseScaleCheckBox.Value = false;
                app.batchwiseScaleCheckBox.Enable = "off";               
            end
            RedoPCA(app)
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            RedoPCA(app);          
        end

        % Value changed function: BatchWiseDropDown
        function BatchWiseDropDownValueChanged(app, event)
            CleanAndPlot(app);            
        end

        % Value changed function: batchwiseScaleCheckBox
        function batchwiseScaleCheckBoxValueChanged(app, event)
            RedoPCA(app)            
        end

        % Callback function: not associated with a component
        function UIAxesImperialRSDButtonDown(app, event)
            a = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon_512x512@2x.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CleanExplorePanel = uipanel(app.UIFigure);
            app.CleanExplorePanel.Title = 'Clean & Explore:';
            app.SaveCleanPanel = uipanel(app.CleanExplorePanel);
            app.SaveCleanPrefixEditField = uieditfield(app.SaveCleanPanel, 'text');
            app.SaveCleanPrefixEditField.Value = 'Clean';
            app.SaveCleanButton = uibutton(app.SaveCleanPanel, 'push');
            app.SaveCleanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCleanButtonPushed, true);
            app.SaveCleanButton.Text = 'Save';
            app.suffixLabel_2 = uilabel(app.SaveCleanPanel);
            app.suffixLabel_2.Text = 'suffix: ';
            app.AlsoSaveImputedDataCheckBox = uicheckbox(app.SaveCleanPanel);
            app.AlsoSaveImputedDataCheckBox.Text = 'Also Save Imputed Data';
            app.TabGroup = uitabgroup(app.CleanExplorePanel);
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.UIAxesPCA = uiaxes(app.PCATab);
            xlabel(app.UIAxesPCA, 'PC 1')
            ylabel(app.UIAxesPCA, 'PC 2')
            zlabel(app.UIAxesPCA, 'Z')
            app.UIAxesPVE = uiaxes(app.PCATab);
            app.UIAxesPVE.XTick = [];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2 = uiaxes(app.PCATab);
            app.UIAxesPVE2.XTick = [];
            app.UIAxesPVE2.YTick = [];
            app.XAxisDropDownLabel = uilabel(app.PCATab);
            app.XAxisDropDownLabel.Text = 'X Axis:';
            app.XAxisDropDown = uidropdown(app.PCATab);
            app.XAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.XAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 1;
            app.YAxisDropDownLabel = uilabel(app.PCATab);
            app.YAxisDropDownLabel.Text = 'Y Axis:';
            app.YAxisDropDown = uidropdown(app.PCATab);
            app.YAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.YAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 2;
            app.LegendCheckBox = uicheckbox(app.PCATab);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.ConfidenceIntervalsCheckBox = uicheckbox(app.PCATab);
            app.ConfidenceIntervalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfidenceIntervalsCheckBoxValueChanged, true);
            app.ConfidenceIntervalsCheckBox.Text = 'Confidence Intervals';
            app.DataPointsCheckBox = uicheckbox(app.PCATab);
            app.DataPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataPointsCheckBoxValueChanged, true);
            app.DataPointsCheckBox.Text = 'Data Points';
            app.pcaPreprocessingPanel = uipanel(app.PCATab);
            app.pcaPreprocessingPanel.Title = 'Preprocessing:';
            app.AutoscaleCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.AutoscaleCheckBox.Value = true;
            app.Log10TransformCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.Log10TransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @Log10TransformCheckBoxValueChanged, true);
            app.Log10TransformCheckBox.Text = 'Log10 Transform';
            app.Log10TransformCheckBox.Value = true;
            app.ParetoscaleCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.ParetoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParetoscaleCheckBoxValueChanged, true);
            app.ParetoscaleCheckBox.Text = 'Pareto scale';
            app.batchwiseScaleCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.batchwiseScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @batchwiseScaleCheckBoxValueChanged, true);
            app.batchwiseScaleCheckBox.Text = 'batch-wise';
            app.VariationExplainedbyeachPCLabel = uilabel(app.PCATab);
            app.VariationExplainedbyeachPCLabel.Text = 'Variation Explained by each PC';
            app.LabelbyDropDownLabel = uilabel(app.PCATab);
            app.LabelbyDropDownLabel.Text = 'Label by:';
            app.LabelbyDropDown = uidropdown(app.PCATab);
            app.LabelbyDropDown.Items = {'SampleType'};
            app.LabelbyDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelbyDropDownValueChanged, true);
            app.LabelbyDropDown.Value = 'SampleType';
            app.PopOutButton = uibutton(app.PCATab, 'push');
            app.PopOutButton.ButtonPushedFcn = createCallbackFcn(app, @PopOutButtonPushed, true);
            app.PopOutButton.Icon = 'fullscreen.png';
            app.PopOutButton.Text = '';
            app.PCStatsSwitch = uiswitch(app.PCATab, 'slider');
            app.PCStatsSwitch.Items = {'Component', 'Cumulative'};
            app.PCStatsSwitch.ItemsData = [true false];
            app.PCStatsSwitch.ValueChangedFcn = createCallbackFcn(app, @RedoPCA, true);
            app.PCStatsSwitch.Value = true;
            app.IncludeBlankSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeBlankSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeBlankSamplesCheckBoxValueChanged, true);
            app.IncludeBlankSamplesCheckBox.Text = 'Project in Blank samples';
            app.IncludeReferenceSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeReferenceSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeReferenceSamplesCheckBoxValueChanged, true);
            app.IncludeReferenceSamplesCheckBox.Text = 'Project in Reference samples';
            app.IncludeQCSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeQCSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeQCSamplesCheckBoxValueChanged, true);
            app.IncludeQCSamplesCheckBox.Text = 'Project in QC samples';
            app.IncludeQCSamplesCheckBox.Value = true;
            app.MissingValueImputationButtonGroup = uibuttongroup(app.PCATab);
            app.MissingValueImputationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MissingValueImputationButtonGroupSelectionChanged, true);
            app.MissingValueImputationButtonGroup.Title = 'Missing Value Imputation:';
            app.KNNcolumnButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNcolumnButton.Text = 'KNN column';
            app.KNNcolumnButton.Value = true;
            app.KNNrowButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNrowButton.Text = 'KNN row';
            app.blank20minButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.blank20minButton.Text = 'blank/20%min';
            app.kLabel = uilabel(app.MissingValueImputationButtonGroup);
            app.kLabel.Text = 'k =';
            app.kEditField = uieditfield(app.MissingValueImputationButtonGroup, 'numeric');
            app.kEditField.Limits = [1 20];
            app.kEditField.RoundFractionalValues = 'on';
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 3;
            app.ImperialRSDplotTab = uitab(app.TabGroup);
            app.ImperialRSDplotTab.Title = 'Imperial RSD plot';
            app.UIAxesImperialRSD = uiaxes(app.ImperialRSDplotTab);
            xlabel(app.UIAxesImperialRSD, 'X')
            ylabel(app.UIAxesImperialRSD, 'Y')
            zlabel(app.UIAxesImperialRSD, 'Z')
            app.RefCheckBox = uicheckbox(app.ImperialRSDplotTab);
            app.RefCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImperialPlotValueChanged, true);
            app.RefCheckBox.Text = 'Plot Reference RSD';
            app.SortbySwitchLabel = uilabel(app.ImperialRSDplotTab);
            app.SortbySwitchLabel.Text = 'Sort by';
            app.SortbySwitch = uiswitch(app.ImperialRSDplotTab, 'slider');
            app.SortbySwitch.Items = {'sampleRSD', 'qcRSD'};
            app.SortbySwitch.ItemsData = {'sample', 'qc'};
            app.SortbySwitch.ValueChangedFcn = createCallbackFcn(app, @ImperialPlotValueChanged, true);
            app.SortbySwitch.Value = 'sample';
            app.DeltaSwitch = uiswitch(app.ImperialRSDplotTab, 'slider');
            app.DeltaSwitch.Items = {'Overlay', 'deltaRSD'};
            app.DeltaSwitch.ItemsData = {'overlay', 'delta'};
            app.DeltaSwitch.ValueChangedFcn = createCallbackFcn(app, @DeltaSwitchValueChanged, true);
            app.DeltaSwitch.Value = 'overlay';
            app.mzLabelDropDownLabel = uilabel(app.ImperialRSDplotTab);
            app.mzLabelDropDownLabel.Text = 'Custom Label';
            app.CustomLabelDropDownA2 = uidropdown(app.ImperialRSDplotTab);
            app.CustomLabelDropDownA2.Items = {'None'};
            app.CustomLabelDropDownA2.ValueChangedFcn = createCallbackFcn(app, @CustomLabelDropDownA2ValueChanged, true);
            app.CustomLabelDropDownA2.Value = 'None';
            app.CustomLabelDropDownA1 = uidropdown(app.ImperialRSDplotTab);
            app.CustomLabelDropDownA1.Items = {'None'};
            app.CustomLabelDropDownA1.ValueChangedFcn = createCallbackFcn(app, @CustomLabelDropDownA1ValueChanged, true);
            app.CustomLabelDropDownA1.Value = 'None';
            app.mzLabelDropDownLabel_2 = uilabel(app.ImperialRSDplotTab);
            app.mzLabelDropDownLabel_2.Text = 'Custom Label';
            app.RSDScatterplotTab = uitab(app.TabGroup);
            app.RSDScatterplotTab.Title = 'RSD Scatter plot';
            app.UIAxesRSDComparison = uiaxes(app.RSDScatterplotTab);
            xlabel(app.UIAxesRSDComparison, 'X')
            ylabel(app.UIAxesRSDComparison, 'Y')
            zlabel(app.UIAxesRSDComparison, 'Z')
            app.LogScaleCheckBox = uicheckbox(app.RSDScatterplotTab);
            app.LogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RSDcompareValueChaged, true);
            app.LogScaleCheckBox.Text = 'Log Scale';
            app.CustomLabelDropDown_2Label = uilabel(app.RSDScatterplotTab);
            app.CustomLabelDropDown_2Label.Text = 'Custom Label';
            app.CustomLabelDropDownB2 = uidropdown(app.RSDScatterplotTab);
            app.CustomLabelDropDownB2.Items = {'None'};
            app.CustomLabelDropDownB2.ValueChangedFcn = createCallbackFcn(app, @CustomLabelDropDownB2ValueChanged, true);
            app.CustomLabelDropDownB2.Value = 'None';
            app.CustomLabelDropDownB1 = uidropdown(app.RSDScatterplotTab);
            app.CustomLabelDropDownB1.Items = {'None'};
            app.CustomLabelDropDownB1.ValueChangedFcn = createCallbackFcn(app, @CustomLabelDropDownB1ValueChanged, true);
            app.CustomLabelDropDownB1.Value = 'None';
            app.CustomLabelDropDown_2Label_2 = uilabel(app.RSDScatterplotTab);
            app.CustomLabelDropDown_2Label_2.Text = 'Custom Label';
            app.qcRSDTab = uitab(app.TabGroup);
            app.qcRSDTab.Title = 'qcRSD';
            app.UIAxesRSDpdf = uiaxes(app.qcRSDTab);
            xlabel(app.UIAxesRSDpdf, 'X')
            ylabel(app.UIAxesRSDpdf, 'Y')
            zlabel(app.UIAxesRSDpdf, 'Z')
            app.SwitchRSDPlot = uiswitch(app.qcRSDTab, 'slider');
            app.SwitchRSDPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchRSDPlot.ItemsData = {'all', 'batch'};
            app.SwitchRSDPlot.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchRSDpdf = uiswitch(app.qcRSDTab, 'slider');
            app.SwitchRSDpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchRSDpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchRSDpdf.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDpdf.Value = 'histogram';
            app.SmoothSlider_2Label = uilabel(app.qcRSDTab);
            app.SmoothSlider_2Label.Visible = 'off';
            app.SmoothSlider_2Label.Text = 'Smooth:';
            app.SmoothRSDslider = uislider(app.qcRSDTab);
            app.SmoothRSDslider.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SmoothRSDslider.Visible = 'off';
            app.SmoothRSDslider.Value = 20;
            app.dRatioTab = uitab(app.TabGroup);
            app.dRatioTab.Title = 'dRatio';
            app.UIAxesDRATIOpdf = uiaxes(app.dRatioTab);
            xlabel(app.UIAxesDRATIOpdf, 'X')
            ylabel(app.UIAxesDRATIOpdf, 'Y')
            zlabel(app.UIAxesDRATIOpdf, 'Z')
            app.SwitchDratioPlot = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDratioPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchDratioPlot.ItemsData = {'all', 'batch'};
            app.SwitchDratioPlot.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDratioPlot.Value = 'all';
            app.SwitchDRATIOpdf = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDRATIOpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchDRATIOpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchDRATIOpdf.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SmoothSlider_2Label_2 = uilabel(app.dRatioTab);
            app.SmoothSlider_2Label_2.Visible = 'off';
            app.SmoothSlider_2Label_2.Text = 'Smooth:';
            app.SmoothDRATIOslider = uislider(app.dRatioTab);
            app.SmoothDRATIOslider.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SmoothDRATIOslider.Visible = 'off';
            app.SmoothDRATIOslider.Value = 20;
            app.blankRatioTab = uitab(app.TabGroup);
            app.blankRatioTab.Title = 'blankRatio';
            app.UIAxesBLANKpdf = uiaxes(app.blankRatioTab);
            xlabel(app.UIAxesBLANKpdf, 'X')
            ylabel(app.UIAxesBLANKpdf, 'Y')
            zlabel(app.UIAxesBLANKpdf, 'Z')
            app.SwitchBlankPlot = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchBlankPlot.ItemsData = {'all', 'batch'};
            app.SwitchBlankPlot.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankPlot.Value = 'all';
            app.SwitchBlankpdf = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchBlankpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchBlankpdf.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankpdf.Value = 'histogram';
            app.SmoothSlider_2Label_3 = uilabel(app.blankRatioTab);
            app.SmoothSlider_2Label_3.Visible = 'off';
            app.SmoothSlider_2Label_3.Text = 'Smooth:';
            app.SmoothBLANKslider = uislider(app.blankRatioTab);
            app.SmoothBLANKslider.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SmoothBLANKslider.Visible = 'off';
            app.SmoothBLANKslider.Value = 20;
            app.BlankRatioMethodDropDownLabel = uilabel(app.blankRatioTab);
            app.BlankRatioMethodDropDownLabel.Text = 'Blank Ratio Method';
            app.BlankRatioMethodDropDown = uidropdown(app.blankRatioTab);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BlankRatioMethodDropDownValueChanged, true);
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.QCRSCgTab = uitab(app.TabGroup);
            app.QCRSCgTab.Title = 'QCRSC g';
            app.UIAxesQCRSCpdf = uiaxes(app.QCRSCgTab);
            xlabel(app.UIAxesQCRSCpdf, 'X')
            ylabel(app.UIAxesQCRSCpdf, 'Y')
            zlabel(app.UIAxesQCRSCpdf, 'Z')
            app.CleanPeaksRemainingLabel = uilabel(app.CleanExplorePanel);
            app.CleanPeaksRemainingLabel.Text = '#Clean Peaks:';
            app.MPQ95Label = uilabel(app.CleanExplorePanel);
            app.MPQ95Label.Interpreter = 'html';
            app.MPQ95Label.Text = 'XXX';
            app.MPQLabel = uilabel(app.CleanExplorePanel);
            app.MPQLabel.Text = 'XXX';
            app.XmissingthreshEditFieldLabel = uilabel(app.CleanExplorePanel);
            app.XmissingthreshEditFieldLabel.Text = '%X missing thresh:';
            app.XmissingthreshEditField = uieditfield(app.CleanExplorePanel, 'numeric');
            app.XmissingthreshEditField.Limits = [1 100];
            app.XmissingthreshEditField.ValueChangedFcn = createCallbackFcn(app, @PreFilterValueChange, true);
            app.XmissingthreshEditField.Value = 50;
            app.XremainingLabel = uilabel(app.CleanExplorePanel);
            app.XremainingLabel.Text = 'Xremaining:';
            app.BatchDropDownLabel = uilabel(app.CleanExplorePanel);
            app.BatchDropDownLabel.Text = 'Batch';
            app.BatchDropDown = uidropdown(app.CleanExplorePanel);
            app.BatchDropDown.ValueChangedFcn = createCallbackFcn(app, @PreFilterValueChange, true);
            app.ResetFilterButton = uibutton(app.CleanExplorePanel, 'push');
            app.ResetFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterButtonPushed, true);
            app.ResetFilterButton.Icon = 'resetx.png';
            app.ResetFilterButton.Tooltip = {''};
            app.ResetFilterButton.Text = '';
            app.PanelKnobs = uipanel(app.CleanExplorePanel);
            app.QCRSDKnobLabel = uilabel(app.PanelKnobs);
            app.QCRSDKnobLabel.Text = 'QC-RSD';
            app.QCRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.QCRSDKnob.Items = {'5', '10', '15', '20', '25', '30', '35', '40', '50', '75', 'Off'};
            app.QCRSDKnob.ItemsData = [5 10 15 20 25 30 35 40 50 75 2000];
            app.QCRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.QCRSDKnob.Value = 2000;
            app.DRatioKnobLabel = uilabel(app.PanelKnobs);
            app.DRatioKnobLabel.Text = 'D-Ratio';
            app.DRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.DRatioKnob.Items = {'5', '10', '15', '20', '25', '30', '35', '40', '50', '75', 'Off'};
            app.DRatioKnob.ItemsData = [5 10 15 20 25 30 35 40 50 75 2000];
            app.DRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DRatioKnob.Value = 2000;
            app.BlankRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.BlankRatioKnob.Items = {'0.1', '1', '2', '5', '10', '15', '20', '30', '50', '75', 'Off'};
            app.BlankRatioKnob.ItemsData = [0.11 1 2 5 10 15 20 30 50 75 20000];
            app.BlankRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.BlankRatioKnob.Value = 0.11;
            app.BlankRatioKnobLabel = uilabel(app.PanelKnobs);
            app.BlankRatioKnobLabel.Text = 'Blank Ratio';
            app.SampleRSDKnobLabel = uilabel(app.PanelKnobs);
            app.SampleRSDKnobLabel.Text = 'Sample-RSD';
            app.SampleRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.SampleRSDKnob.Items = {'50', '40', '35', '30', '25', '20', '15', '10', '5', '2 ', 'Off'};
            app.SampleRSDKnob.ItemsData = [50 40 35 30 25 20 15 10 5 2 0];
            app.SampleRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.SampleRSDKnob.Value = 50;
            app.FilterBankLabel = uilabel(app.PanelKnobs);
            app.FilterBankLabel.Text = 'Filter Bank';
            app.FilterBankSwitch = uiswitch(app.PanelKnobs, 'rocker');
            app.FilterBankSwitch.ItemsData = [false true];
            app.FilterBankSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterBankSwitch.Value = false;
            app.FilterBankLamp = uilamp(app.PanelKnobs);
            app.BatchwiseSwitch = uiswitch(app.CleanExplorePanel, 'slider');
            app.BatchwiseSwitch.Items = {'Batch-wise', 'Complete'};
            app.BatchwiseSwitch.ItemsData = [true false];
            app.BatchwiseSwitch.ValueChangedFcn = createCallbackFcn(app, @PreFilterValueChange, true);
            app.BatchwiseSwitch.Value = false;
            app.SwitchBeforeAfter = uiswitch(app.CleanExplorePanel, 'slider');
            app.SwitchBeforeAfter.Items = {'Before', 'After'};
            app.SwitchBeforeAfter.ItemsData = [false true];
            app.SwitchBeforeAfter.ValueChangedFcn = createCallbackFcn(app, @SwitchBeforeAfterValueChanged, true);
            app.SwitchBeforeAfter.Value = true;
            app.BatchWiseDropDown = uidropdown(app.CleanExplorePanel);
            app.BatchWiseDropDown.Items = {'Max', 'Mean', 'Median', 'Min'};
            app.BatchWiseDropDown.ValueChangedFcn = createCallbackFcn(app, @BatchWiseDropDownValueChanged, true);
            app.BatchWiseDropDown.Enable = 'off';
            app.BatchWiseDropDown.Value = 'Max';
            app.QCRSCenginePanel = uipanel(app.UIFigure);
            app.QCRSCenginePanel.Title = 'QCRSC engine: ';
            app.SavePanel = uipanel(app.QCRSCenginePanel);
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.suffixLabel = uilabel(app.SavePanel);
            app.suffixLabel.Text = 'suffix:';
            app.SuffixEditField = uieditfield(app.SavePanel, 'text');
            app.SuffixEditField.Value = 'QCRSC';
            app.peakscorrectedLabel = uilabel(app.QCRSCenginePanel);
            app.peakscorrectedLabel.Text = '% peaks corrected';
            app.RunningLampLabel = uilabel(app.QCRSCenginePanel);
            app.RunningLampLabel.Text = 'Running';
            app.CompleteLampLabel = uilabel(app.QCRSCenginePanel);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.QCRSCenginePanel);
            app.RunningLamp = uilamp(app.QCRSCenginePanel);
            app.peaksCorrectedGauge = uigauge(app.QCRSCenginePanel, 'circular');
            app.BypassButton = uibutton(app.QCRSCenginePanel, 'push');
            app.BypassButton.ButtonPushedFcn = createCallbackFcn(app, @BypassButtonPushed, true);
            app.BypassButton.Icon = 'bypass2.png';
            app.BypassButton.Tooltip = {''};
            app.BypassButton.Text = '';
            app.AbortButton = uibutton(app.QCRSCenginePanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Icon = 'eject2.png';
            app.AbortButton.Tooltip = {''};
            app.AbortButton.Text = '';
            app.PauseButton = uibutton(app.QCRSCenginePanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause2.png';
            app.PauseButton.Tooltip = {''};
            app.PauseButton.Text = '';
            app.RunButton = uibutton(app.QCRSCenginePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.RunButton.Icon = 'play2.png';
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = '';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration:';
            app.configFileTextArea = uitextarea(app.ConfigurationPanel);
            app.configFileTextArea.ValueChangedFcn = createCallbackFcn(app, @configTextAreaValueChanged, true);
            app.LoadConfigButton = uibutton(app.ConfigurationPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.SaveConfigButton = uibutton(app.ConfigurationPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.IncomingDataPanel = uipanel(app.UIFigure);
            app.IncomingDataPanel.Title = 'Incoming Data:';
            app.NumberofSamplesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofPeaksLabel = uilabel(app.IncomingDataPanel);
            app.NumberofPeaksLabel.Text = 'Number of Peaks:';
            app.ViewPeakTableButton = uibutton(app.IncomingDataPanel, 'push');
            app.ViewPeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPeakTableButtonPushed, true);
            app.ViewPeakTableButton.Text = 'View Peak Table';
            app.LOADDATAButton = uibutton(app.IncomingDataPanel, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Text = 'Load Data';
            app.NumberofBatchesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBatchesLabel.Text = 'Number of Batches:';
            app.NumberofQCLabel = uilabel(app.IncomingDataPanel);
            app.NumberofQCLabel.Text = 'Number of QC:';
            app.NumberofReferenceLabel = uilabel(app.IncomingDataPanel);
            app.NumberofReferenceLabel.Text = 'Number of Reference:';
            app.NumberofBlanksLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBlanksLabel.Text = 'Number of Blanks:';
            app.drinkMeButton = uibutton(app.IncomingDataPanel, 'state');
            app.drinkMeButton.ValueChangedFcn = createCallbackFcn(app, @drinkMeButtonValueChanged, true);
            app.drinkMeButton.Icon = 'drinkme.png';
            app.drinkMeButton.Text = '';
            app.PeakSheetLabel = uilabel(app.IncomingDataPanel);
            app.PeakSheetLabel.Text = 'PeakSheet:';
            app.DataSheetLabel = uilabel(app.IncomingDataPanel);
            app.DataSheetLabel.Text = 'DataSheet:';
            app.FilenameLabel = uilabel(app.IncomingDataPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.LaunchPeakExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchPeakExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchPeakExplorerPushed, true);
            app.LaunchPeakExplorer.Icon = 'PEpopX.png';
            app.LaunchPeakExplorer.Text = '';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'QCMXPz.png';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'version: 1.2 (beta)';
            app.LaunchConfigExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchConfigExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchConfigExplorerPushed, true);
            app.LaunchConfigExplorer.Icon = 'CEpopX.png';
            app.LaunchConfigExplorer.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'textx10.png';
            app.PreFilterPanel = uipanel(app.UIFigure);
            app.PreFilterPanel.Title = 'PreFilter:';
            app.UIAxesPreprocessing = uiaxes(app.PreFilterPanel);
            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)')
            zlabel(app.UIAxesPreprocessing, 'Z')
            app.permissiblemissingQCsEditFieldLabel = uilabel(app.PreFilterPanel);
            app.permissiblemissingQCsEditFieldLabel.Text = '% permissible missing QCs:';
            app.permissiblemissingQCsEditField = uieditfield(app.PreFilterPanel, 'numeric');
            app.permissiblemissingQCsEditField.Limits = [0 100];
            app.permissiblemissingQCsEditField.ValueChangedFcn = createCallbackFcn(app, @permissiblemissingQCsEditFieldValueChanged, true);
            app.permissiblemissingQCsEditField.Value = 20;
            app.PeaksRemainingLabel = uilabel(app.PreFilterPanel);
            app.PeaksRemainingLabel.Text = 'Peaks Remaining: ';
            app.BatchwiseCheckBox = uicheckbox(app.PreFilterPanel);
            app.BatchwiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @permissiblemissingQCsEditFieldValueChanged, true);
            app.BatchwiseCheckBox.Text = 'Batch-wise';
            app.BatchwiseCheckBox.Value = true;
            app.blankmissingCheckBox = uicheckbox(app.PreFilterPanel);
            app.blankmissingCheckBox.ValueChangedFcn = createCallbackFcn(app, @blankmissingCheckBoxValueChanged, true);
            app.blankmissingCheckBox.Text = '<blank = missing';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'http://www.qcmxp.org';
            app.Hyperlink.Text = 'QCMXP.org';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCmXP(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end